classdef uavrt_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VehicleConnectionTab            matlab.ui.container.Tab
        FlightsettingsPanel             matlab.ui.container.Panel
        PulsedurationsEditField_2Label  matlab.ui.control.Label
        PUL_D_INPUT                     matlab.ui.control.NumericEditField
        PulserepetitionratesEditField_2Label  matlab.ui.control.Label
        PUL_R_INPUT                     matlab.ui.control.NumericEditField
        RadioSamplingRateHzEditField_2Label  matlab.ui.control.Label
        FRS_INPUT                       matlab.ui.control.NumericEditField
        UAVTelemsamplerateHzLabel       matlab.ui.control.Label
        FVEH_INPUT                      matlab.ui.control.NumericEditField
        TagFrequencyMHzEditField_2Label  matlab.ui.control.Label
        FT_INPUT                        matlab.ui.control.NumericEditField
        RFGaindBEditField_2Label        matlab.ui.control.Label
        RFG_INPUT                       matlab.ui.control.NumericEditField
        IFGaindBEditField_2Label        matlab.ui.control.Label
        IFG_INPUT                       matlab.ui.control.NumericEditField
        FlightnotesTextArea_2Label      matlab.ui.control.Label
        NOTES_INPUT                     matlab.ui.control.TextArea
        BBGaindBLabel                   matlab.ui.control.Label
        BBG_INPUT                       matlab.ui.control.NumericEditField
        SendsettingsButton              matlab.ui.control.Button
        GetsettingsButton               matlab.ui.control.Button
        UsepastconfigButton             matlab.ui.control.Button
        SystemControlPanel              matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.Button
        StartRadioButton                matlab.ui.control.Button
        StopRadioButton                 matlab.ui.control.Button
        TextArea_VEHTRM                 matlab.ui.control.TextArea
        DisconnectButton                matlab.ui.control.Button
        ConnectionSettingsButton        matlab.ui.control.Button
        FTPLampLabel                    matlab.ui.control.Label
        FTPLamp                         matlab.ui.control.Lamp
        UDPLampLabel                    matlab.ui.control.Label
        UDPLamp                         matlab.ui.control.Lamp
        VehicleTerminalOutputLabel      matlab.ui.control.Label
        ConnectionStatusLabel           matlab.ui.control.Label
        CleanupconnectionButton         matlab.ui.control.Button
        ClearterminalButton             matlab.ui.control.Button
        RefreshIPButton                 matlab.ui.control.Button
        SnapwindowButton                matlab.ui.control.Button
        LastHearbeatEditFieldLabel      matlab.ui.control.Label
        LastHearbeatEditField           matlab.ui.control.NumericEditField
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        FileoffloadPanel                matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        DownloadDataButton              matlab.ui.control.Button
        UAV_FTP_Pop_Dir                 matlab.ui.control.Button
        LocalfolderEditField            matlab.ui.control.EditField
        SaveLocationLabel               matlab.ui.control.Label
        ProcessingPrepTab               matlab.ui.container.Tab
        DataprocessinginputPanel        matlab.ui.container.Panel
        LoadDataButton                  matlab.ui.control.Button
        FlightFileTextAreaLabel         matlab.ui.control.Label
        FlightFileTextArea              matlab.ui.control.TextArea
        RadioFileTextAreaLabel          matlab.ui.control.Label
        RadioFileTextArea               matlab.ui.control.TextArea
        OutputsavestringEditFieldLabel  matlab.ui.control.Label
        OutputsavestringEditField       matlab.ui.control.EditField
        OutputTextAreaLabel             matlab.ui.control.Label
        OutputTextArea                  matlab.ui.control.TextArea
        SelectOutputFolderButton        matlab.ui.control.Button
        ConfigurationFileTextAreaLabel  matlab.ui.control.Label
        ConfigurationFileTextArea       matlab.ui.control.TextArea
        ProcessingcontrolsPanel         matlab.ui.container.Panel
        RadioProcessingOptionsButtonGroup  matlab.ui.container.ButtonGroup
        AutodetectpulsefrequencyButton  matlab.ui.control.RadioButton
        ManuallyenterpulsefrequencyButton  matlab.ui.control.RadioButton
        FilterbandwidthHzEditFieldLabel  matlab.ui.control.Label
        FilterbandwidthHzEditField      matlab.ui.control.NumericEditField
        PulsefrequencyHzEditField       matlab.ui.control.NumericEditField
        PlotspectumofradiodataCheckBox  matlab.ui.control.CheckBox
        PlotspectrogramthismighttakeacoupleofminutesCheckBox  matlab.ui.control.CheckBox
        UpperlimitfromcenterfrequencyHzEditFieldLabel  matlab.ui.control.Label
        UpperlimitfromcenterfrequencyHzEditField  matlab.ui.control.NumericEditField
        TimeselectionButtonGroup        matlab.ui.container.ButtonGroup
        AlldataButton                   matlab.ui.control.ToggleButton
        AtaltitudeButton                matlab.ui.control.ToggleButton
        ManualtimesButton               matlab.ui.control.ToggleButton
        StartsEditFieldLabel            matlab.ui.control.Label
        StartsEditField                 matlab.ui.control.NumericEditField
        EndsEditFieldLabel              matlab.ui.control.Label
        EndsEditField                   matlab.ui.control.NumericEditField
        PulsedetectionthresholdSliderLabel  matlab.ui.control.Label
        PulsedetectionthresholdSlider   matlab.ui.control.Slider
        WaypointdetectionButtonGroup    matlab.ui.container.ButtonGroup
        DwellSelectButtonGroup          matlab.ui.container.ButtonGroup
        AutoDwellButton                 matlab.ui.control.ToggleButton
        ManualDwellButton               matlab.ui.control.ToggleButton
        MinsLabel                       matlab.ui.control.Label
        MinDwellEditField               matlab.ui.control.NumericEditField
        MaxsEditFieldLabel              matlab.ui.control.Label
        MaxDwellEditField               matlab.ui.control.NumericEditField
        MaxDwellLabel                   matlab.ui.control.Label
        MinDwellLabel                   matlab.ui.control.Label
        AltSelectButtonGroup            matlab.ui.container.ButtonGroup
        AutoAltButton                   matlab.ui.control.ToggleButton
        ManualAltButton                 matlab.ui.control.ToggleButton
        MinEditFieldLabel               matlab.ui.control.Label
        MinAltEditField                 matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxAltEditField                 matlab.ui.control.NumericEditField
        AltUnitsDropDown                matlab.ui.control.DropDown
        MaxAltLabel                     matlab.ui.control.Label
        MinAltLabel                     matlab.ui.control.Label
        FlightsettingsPanel_2           matlab.ui.container.Panel
        PulsedurationsEditFieldLabel    matlab.ui.control.Label
        PUL_D_PROCESS                   matlab.ui.control.NumericEditField
        PulserepetitionratesEditFieldLabel  matlab.ui.control.Label
        PUL_R_PROCESS                   matlab.ui.control.NumericEditField
        RadioSamplingRateHzEditFieldLabel  matlab.ui.control.Label
        FRS_PROCESS                     matlab.ui.control.NumericEditField
        VehiclestatesamplerateHzLabel   matlab.ui.control.Label
        FVEH_PROCESS                    matlab.ui.control.NumericEditField
        TagFrequencyMHzEditFieldLabel   matlab.ui.control.Label
        FT_PROCESS                      matlab.ui.control.NumericEditField
        FlightnotesTextAreaLabel        matlab.ui.control.Label
        NOTES_PROCESS                   matlab.ui.control.TextArea
        TestinginputsPanel              matlab.ui.container.Panel
        AltmLabel                       matlab.ui.control.Label
        TAG_TEST_ALT                    matlab.ui.control.NumericEditField
        LondegELabel                    matlab.ui.control.Label
        TAG_TEST_LON                    matlab.ui.control.NumericEditField
        LatdegNLabel                    matlab.ui.control.Label
        TAG_TEST_LAT                    matlab.ui.control.NumericEditField
        TagantennaorientationisButtonGroup  matlab.ui.container.ButtonGroup
        AntUnknownButton                matlab.ui.control.RadioButton
        AntHorizontalButton             matlab.ui.control.RadioButton
        AntVerticalButton               matlab.ui.control.RadioButton
        EnableButton                    matlab.ui.control.StateButton
        TAG_TEST_ANT_STR                matlab.ui.control.EditField
        ResultsTab                      matlab.ui.container.Tab
        ProcessingPanel                 matlab.ui.container.Panel
        RadioDataButton                 matlab.ui.control.Button
        VehicleDataButton               matlab.ui.control.Button
        ExtractPulsesButton             matlab.ui.control.Button
        BearingsButton                  matlab.ui.control.Button
        PositionButton                  matlab.ui.control.Button
        Lampvehicledataready            matlab.ui.control.Lamp
        Lamppreprocessingcomplete       matlab.ui.control.Lamp
        Lamppulsefindcomplete           matlab.ui.control.Lamp
        Lampbearingscomplete            matlab.ui.control.Lamp
        Lamppositioncomplete            matlab.ui.control.Lamp
        ProcessBearingCheckBox          matlab.ui.control.CheckBox
        ProcessPositionCheckBox         matlab.ui.control.CheckBox
        ALLButton                       matlab.ui.control.Button
        LocalizationControlPanel        matlab.ui.container.Panel
        SignalScaleButtonGroup          matlab.ui.container.ButtonGroup
        AmpButton                       matlab.ui.control.ToggleButton
        PowButton                       matlab.ui.control.ToggleButton
        PCAScaleButtonGroup             matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.ToggleButton
        LogButton                       matlab.ui.control.ToggleButton
        MethodPanel                     matlab.ui.container.Panel
        CMButton                        matlab.ui.control.StateButton
        MLEButton                       matlab.ui.control.StateButton
        RMRButton                       matlab.ui.control.StateButton
        MESTButton                      matlab.ui.control.StateButton
        MedianButton                    matlab.ui.control.StateButton
        AllButton                       matlab.ui.control.Button
        UIWayptSelTable                 matlab.ui.control.Table
        ClearresultsButton              matlab.ui.control.Button
        TagcheckButton                  matlab.ui.control.Button
        PulseonlypositionButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        MapTab                          matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        ResetResultsMapButton           matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        PositionEstimateMedianofcurrentlyplottedLabel  matlab.ui.control.Label
        ZoomDropDownLabel               matlab.ui.control.Label
        ZoomDropDown                    matlab.ui.control.DropDown
        MapradiuskmLabel                matlab.ui.control.Label
        MapRad                          matlab.ui.control.NumericEditField
        MapDropDownLabel                matlab.ui.control.Label
        MapDropDown                     matlab.ui.control.DropDown
        LatEditFieldLabel               matlab.ui.control.Label
        LatEditField                    matlab.ui.control.NumericEditField
        LonEditFieldLabel               matlab.ui.control.Label
        LonEditField                    matlab.ui.control.NumericEditField
        XNEditFieldLabel                matlab.ui.control.Label
        XNEditField                     matlab.ui.control.NumericEditField
        YEEditFieldLabel                matlab.ui.control.Label
        YEEditField                     matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        ThetafromTrueNEditFieldLabel    matlab.ui.control.Label
        ThetafromTrueNEditField         matlab.ui.control.NumericEditField
        ViewDropDownLabel               matlab.ui.control.Label
        ViewDropDown                    matlab.ui.control.DropDown
        PopfigureButton                 matlab.ui.control.Button
        TesttagplotButton               matlab.ui.control.StateButton
        UIAxes2                         matlab.ui.control.UIAxes
        BearingsTab                     matlab.ui.container.Tab
        AudioTab                        matlab.ui.container.Tab
        StartAudioButton                matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PlayPauseButton                 matlab.ui.control.Button
        WaypointTab                     matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ExportCSVButton_2               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        PulsesTab                       matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        ExportCSVButton                 matlab.ui.control.Button
        SpectrumTab                     matlab.ui.container.Tab
        PlayRawAudioButton              matlab.ui.control.Button
        PlayFilteredAudioButton         matlab.ui.control.Button
        UIAxes_PSD                      matlab.ui.control.UIAxes
        UIAxes_WAVEFRM                  matlab.ui.control.UIAxes
        MapcachingTab                   matlab.ui.container.Tab
        DownloadButton                  matlab.ui.control.Button
        OpencachingmapButton            matlab.ui.control.Button
        SelectmappreviewzoomlevelDropDownLabel  matlab.ui.control.Label
        SelectmappreviewzoomlevelDropDown  matlab.ui.control.DropDown
        ChangecachingdirectoryButton    matlab.ui.control.Button
        MapcachedirEditField            matlab.ui.control.EditField
        Toposelectcheckbox              matlab.ui.control.CheckBox
        ImageryToposelectcheckbox       matlab.ui.control.CheckBox
        Imageryonlyselectcheckbox       matlab.ui.control.CheckBox
        Shadedselectcheckbox            matlab.ui.control.CheckBox
        SelectmappreviewlevelDropDownLabel_2  matlab.ui.control.Label
        zoom_dl_sel_6                   matlab.ui.control.CheckBox
        zoom_dl_sel_8                   matlab.ui.control.CheckBox
        zoom_dl_sel_7                   matlab.ui.control.CheckBox
        zoom_dl_sel_5                   matlab.ui.control.CheckBox
        zoom_dl_sel_4                   matlab.ui.control.CheckBox
        zoom_dl_sel_3                   matlab.ui.control.CheckBox
        zoom_dl_sel_2                   matlab.ui.control.CheckBox
        zoom_dl_sel_1                   matlab.ui.control.CheckBox
        zoom_dl_sel_9                   matlab.ui.control.CheckBox
        zoom_dl_sel_10                  matlab.ui.control.CheckBox
        zoom_dl_sel_11                  matlab.ui.control.CheckBox
        zoom_dl_sel_12                  matlab.ui.control.CheckBox
        zoom_dl_sel_13                  matlab.ui.control.CheckBox
        zoom_dl_sel_14                  matlab.ui.control.CheckBox
        zoom_dl_sel_15                  matlab.ui.control.CheckBox
        zoom_dl_sel_16                  matlab.ui.control.CheckBox
        SelectmappreviewlevelDropDownLabel_3  matlab.ui.control.Label
        SelectmappreviewlevelDropDownLabel_4  matlab.ui.control.Label
        SelectmappreviewlevelDropDownLabel_5  matlab.ui.control.Label
        SelectmappreviewlevelDropDownLabel_6  matlab.ui.control.Label
        Hydroselectcheckbox             matlab.ui.control.CheckBox
        TNMBlankselectcheckbox          matlab.ui.control.CheckBox
        zoom_dl_sel_0                   matlab.ui.control.CheckBox
        TabGroup3                       matlab.ui.container.TabGroup
        MapCacheTab                     matlab.ui.container.Tab
        MapPreviewTab                   matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        DebuggingTab                    matlab.ui.container.Tab
        BreakpointButton                matlab.ui.control.Button
        RawCommsLabel                   matlab.ui.control.Label
        TextArea_RAWCOMM                matlab.ui.control.TextArea
        ctfrootEditFieldLabel           matlab.ui.control.Label
        ctfrootEditField                matlab.ui.control.EditField
        matlabrootEditFieldLabel        matlab.ui.control.Label
        matlabrootEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        default_veh_state_rate = 2; %2 Hz is what is currently used on the vehicle
        
        default_Fs = 48000;
        default_dur = 0.02;
        default_rep = 1.3;
        default_filt_band = 160;
        default_search_lim_upper = 2000;
        
        
        default_lat  =   35.177535;     %Default latitude 
        default_lon  = -111.657072;     %Default longitude
        
        default_uavrt_ip = '10.42.0.1';
        
        tru_bear_color = [1 0.3 0.3];
        est_bear_color =  [0 0.4470 0.7410];
        
        %Prep variable needed amongst callbacks
        waypt           %Waypoint listing
        home            %Home location for analysis and ma plotting
        SDR             %Radio data
        pulse           %Listing of pulses
        UAV             %Vehicle states throughout flight
        UAVPULS         %Vehicle states at each pulse detection
        LOC             %Localization Estimate
        TEST            %Test tag parameters (like testing tag location). 
        PULSELOC        %Pulse only based localization Estimate
        
        slash_char      %Slash character for dir. generation for PC o Mac
        cp_cmd          %compy command string for generation for PC o Mac
        
        player          %audio object for playback of sound.
        playbackcursors %handles of all the cursors lines used for playback plotting
        
        h_mapcache_ax   %axis handle of popup figure used to select region to download
        
        mapcachedir     %Directory on the computer containing the cached maps.
        iconsdir        %Directory on the computer containing the cached maps.
        configfilesdir  %Directory on the computer containing the current config file.
        
        mapselectvals   %Cell array with the exact names of the available maps for download and display. These have to match the names in the usgs WMS urls
        mapselectnames  %Cell array with the display names of the available maps for download and display. These can be different than mapselectvals to make the user interface more readable.
        zoomvals        %A vector of the available zoom values.
        
        doatypes        %Listing of the doa types available
        doalogic        %A logical vector contain true/false for which methods to use.
        
        ftpobj          %The FTP object associated with the vehcile's ftp server. Used to get files from the vehicle
        udpobj          %The udp object associated with issuing commands to the vehicle. 
        connsettings     %Contains IP addesses and ports for comm between PC and UAVRT


        %HERE IS A LIST OF TAGS THAT ARE USED TO HELP WITH SELECTION OF
        %ITEMS IN THE GRAPHS
        %LOC-'TYPE': for example LOC-MLE  or LOC-TRUE
        %BEAR-'WAYPOINT NUMBER':  for exampl BEAR-3
        %PULSE-'PULSE NUMBER':  for exampl PULSE-3
        %
        
    end
    
    methods (Access = private)
        
        function [] = syncaxes(app,axlist,axchanger)
           %This function is used to synchronize the limits of the Spectrum
           %plots
           %axlist is a list of the other axes that need updating
           %axchanger is a single axis that was just updated and the others
           %need to match
           persistent CallbackRunning %This variable is used to block execution of this function until it completes to avoid the Xlims within it executing the callback 
           if isempty(CallbackRunning) || ~CallbackRunning
               CallbackRunning = true;
               %One of these will be empty, since the axlist doesn't
               %contain one of them. 
               psd_ind = find(axlist==app.UIAxes_PSD);
               wav_ind = find(axlist==app.UIAxes_WAVEFRM);
               %spe_ind = find(axlist==app.UIAxes_SPECTRO);
%                if axchanger ==  app.UIAxes_SPECTRO
%                    %Update the x and y lims of other two to match
%                    axlist(psd_ind).XLim = axchanger.XLim;
%                    axlist(psd_ind).YLim = [-inf inf]; %Auto
%                    axlist(wav_ind).XLim = [-inf inf]; %Auto
%                    axlist(wav_ind).YLim = axchanger.YLim;
               if axchanger ==  app.UIAxes_PSD
                   %SPD updated. Update the x lim of spectrogram
                   axlist(spe_ind).XLim = axchanger.XLim;
                   axchanger.YLim = [-inf inf]; %Auto
               elseif axchanger ==  app.UIAxes_WAVEFRM
                   %Waveforem updated. Update the y lims of spectrogram
                   axlist(spe_ind).YLim = axchanger.YLim;
                   axchanger.XLim = [-inf inf]; %Auto
               end
               CallbackRunning = false;
           end
           
           %disp('Breakpoint!');
        end
        
        
        function [inclusivezlim] = mapholdaltlims(app,ax)
            objwithz = findobj(app.UIAxes2.Children,'-property','ZData');
            currmin_z = 0;
            currmax_z = 0;
            for i = 1:length(objwithz)
                currmin_z = min([min(min(objwithz(i).ZData)), currmin_z]);%Need double mins because data could be 2d surface
                currmax_z = max([max(max(objwithz(i).ZData)), currmax_z]);
            end
            inclusivezlim = [currmin_z currmax_z];
            ax.ZLim = inclusivezlim;
            %disp('Updated the limits')
        end
        
        
        function [] = initialize_app_flight_props(app)
            %This function resets all the flight properties. This is set up
            %as a function so that it can be called during initialization
            %and by a button used to reset the processing.
            
            %Some of these should likely be set up as classes in future
            %releases
            
            %Initialize all the waypoint properties
            app.waypt.num   = [];
            app.waypt.x     = [];
            app.waypt.y     = [];
            app.waypt.alt   = [];
            app.waypt.lat   = [];
            app.waypt.lon   = [];
            app.waypt.tin   = [];
            app.waypt.tout  = [];
            app.waypt.dt    = [];
            app.waypt.doa   = [];
            app.waypt.maxpulsepow = [];
            app.waypt.maxpulseamp = [];
            app.waypt.avgpulsepow = [];
            app.waypt.avgpulseamp = [];
            app.waypt.haspulses = [];   %Logical on if pulses were found at this waypoints
            app.waypt.DOA_degN_CW = []; %DOA from True North with + angle in CW dir (compass)
            app.waypt.tau   = [];
            
            %Initialize all the home properties
            app.home.lat    = [];
            app.home.lon    = [];
            
            %Initialize the radio data propeties
            app.SDR.raw.data     = [];       %unfiltered IQ data at original Fs
            app.SDR.dec.data     = [];       %unfiltered IQ data at decimated Fs
            app.SDR.dec.Fs       = [];         %Fs of decimated IQ data
            app.SDR.filt.IQ      = [];       %fitlered IQ data
            app.SDR.filt.mag     = [];       %abs of IQ
            app.SDR.filt.downmax = [];  %fitlered IQ data with maximum from each pulse duration.
            app.SDR.reduced = [];
            
            %Initialize the pulse data propeties
            app.pulse.num   = [];       %Number of total pulse
            app.pulse.time  = [];       %When was pulse received
            app.pulse.amp   = [];       %List of pulse amplitudes
            app.pulse.pow   = [];       %List of pulse powers
            app.pulse.wayptnum = [];    %At which waypoint was the pulse reveived
            app.pulse.x     = [];
            app.pulse.y     = [];
            app.pulse.yaw   = [];
            app.pulse.alt   = [];
            app.pulse.lat   = [];
            app.pulse.R     = [];       %Range to received pulse location from home
            app.pulse.bear  = [];       %Bearing to received pulse location from home
            
            %Initialize the UAV properties
            app.UAV.time    = [];
            app.UAV.roll    = [];
            app.UAV.pitch   = [];
            app.UAV.yaw     = [];       %Total yaw from start (not wrapped to 360).
            app.UAV.alt     = [];
            app.UAV.lat     = [];
            app.UAV.lon     = [];
            app.UAV.x       = [];
            app.UAV.y       = [];
            app.UAV.atwaypt = [];       %Logical when at a waypt
            app.UAV.atalt   = [];       %Logical when at altitude
            
            %UAVPULS properties - UAV states at instants that pusles were
            %received.
            app.UAVPULS.x   = [];
            app.UAVPULS.y   = [];
            app.UAVPULS.yaw = [];
            app.UAVPULS.alt = [];
            app.UAVPULS.waypt_num = []; %At which waypoint was this pulse received?
            app.UAVPULS.lat = [];
            app.UAVPULS.lon = [];
            %app.UAVPULS.latstr = [];%These are a cell array of the lats for printing. Needed for us in tables
            %app.UAVPULS.lonstr = [];%These are a cell array of the lons for printing. Needed for us in tables
            app.UAVPULS.r   = []; %Home to UAV position (radial dist) at pulse reception time.
            app.UAVPULS.th  = []; %Home to UAV position (theta angle from N) at pulse reception time.
            
            %Position Estimates based on bearings
            app.LOC.CM.x    = [];
            app.LOC.CM.y    = [];
            app.LOC.CM.lat  = [];
            app.LOC.CM.lon  = [];
            app.LOC.CM.r    = [];
            app.LOC.CM.th   = [];
            
            app.LOC.MLE.x    = [];
            app.LOC.MLE.y    = [];
            app.LOC.MLE.lat  = [];
            app.LOC.MLE.lon  = [];
            app.LOC.MLE.r    = [];
            app.LOC.MLE.th   = [];
            
            app.LOC.RMR.x    = [];
            app.LOC.RMR.y    = [];
            app.LOC.RMR.lat  = [];
            app.LOC.RMR.lon  = [];
            app.LOC.RMR.r    = [];
            app.LOC.RMR.th   = [];
            
            app.LOC.MEST.x    = [];
            app.LOC.MEST.y    = [];
            app.LOC.MEST.lat  = [];
            app.LOC.MEST.lon  = [];
            app.LOC.MEST.r    = [];
            app.LOC.MEST.th   = [];
            
            app.LOC.MED.x    = [];
            app.LOC.MED.y    = [];
            app.LOC.MED.lat  = [];
            app.LOC.MED.lon  = [];
            app.LOC.MED.r    = [];
            app.LOC.MED.th   = [];
            
            %app.TEST.time    = [];
            %app.TEST.x       = [];
            %app.TEST.y       = [];
            app.TEST.lat     = [0];
            app.TEST.lon     = [0];
            app.TEST.alt     = [0];
            app.TEST.ant     = 'Unknown';
            %app.TEST.r       = [];
            %app.TEST.th      = [];
            
            %Position Estimates based on pulses only
            app.PULSELOC.CM.x    = [];
            app.PULSELOC.CM.y    = [];
            app.PULSELOC.CM.lat  = [];
            app.PULSELOC.CM.lon  = [];
            app.PULSELOC.CM.r    = [];
            app.PULSELOC.CM.th   = [];
            
            
        end
        
        function foundlogic = findtagpart(app,input_h_list,searchstr)
            %This function receives a list of graphics handles and then
            %looks at all of the children and finds those whose tags
            %contain the search string. This is useful for turning on
            %and off portions of a plot by using the 'Visible' property
            %in the calling function.
            %INPUTS:
            %h_list     A list of graphics handles. Can be nxm
            %searchstr  A string you want to search for in all the Tags
            %
            %OUPTUTS:
            %foundlogic  an logic array the size of the input_h_list with
            %            true values if the string was found the in the tag
            %
            
            foundlogic = false(size(input_h_list));%Initialize
            for i=1:size(input_h_list,1)
                for j=1:size(input_h_list,2)
                    curr_h = input_h_list(i,j);
                    if isprop(curr_h,'Tag')%First check if 'Tag' is a property
                        %Look for the search field and if it is there (not
                        %empty) then set the found logic for that element equal
                        %to 1.
                        foundlogic(i,j) = ~isempty(strfind(curr_h.Tag,searchstr));
                    end
                end
            end
        end
        
        function [] = plotswitch(app,input_h_list,searchstr,plotcntrl)
            %This function receives a list of graphics handles and then
            %turns on or off those items that are found to contain the
            %search string in their Tag property. Turning the items on vs
            %off is controlled by the onoffcntrl input 
            %INPUTS:
            %h_list     A list of graphics handles. Can be nxm
            %searchstr  A string you want to search for in all the Tags
            %plotcntrl  'on' or 'off' or 'delete' to remove
            %
            %OUPTUTS:
            %none
            %
            
            %First search for who in the list have the searchstr
            foundlogic = findtagpart(app,input_h_list,searchstr);
            setlogic = find(foundlogic);
            for i = 1:length(setlogic)
                k = setlogic(i);
                curr_h = input_h_list(k);
                if strcmp(plotcntrl,'delete')
                    delete(curr_h)
                else
                    
                    if isprop(curr_h,'Visible')%First check if 'Visible' is a property
                        curr_h.Visible = plotcntrl;
                    end
                end
            end
        end
    
        
        function [] = openfile(app,file_path)
            %This function opens a file passed to it in the default
            %applciation on either a PC or Mac. Filepath should be a string
            %input. No outputs.
            if ismac
                syscmd = ['open "', file_path, '" &'];   %Need the quote for files with spaces
                system(syscmd);
            elseif ispc
                winopen(file_path)
            end
        end
        
        
        function sorted_struct = sortstruct(app,structurein)
            if ~isempty(structurein)%Only do this if the structure isn't empty.
                structurein_T = struct2table(structurein);
                structurein_sortedT = sortrows(structurein_T,{'isdir','name'},{'descend','ascend'});%Sort first by isdir then by names
                sorted_struct = table2struct(structurein_sortedT);
            else
                sorted_struct = structurein;
            end
            
        end
        
        function IPSTRING = getmyip(app)
            if ismac
                %Adapted from command on:   http://osxdaily.com/2010/11/21/find-ip-address-mac/
                [~, sysout] = system('ifconfig |grep inet');
                inet_inds = strfind(sysout,'inet ');
                space_inds = strfind(sysout,' ');
                for i = 1:length(inet_inds)
                    nextspace_ind = space_inds(find(space_inds>inet_inds(i)+5,1,'first'));
                    IP{i} = sysout(inet_inds(i)+5:nextspace_ind-1);
                    if ~strcmp(IP{i}(1:3),'127')
                        ext_IP = IP{i};
                    end
                end
            elseif ispc
                [~, sysout] = system('ipconfig');
                inet_inds = strfind(sysout,'IPv4 Address. . . . . . . . . . . : ');
                if numel(inet_inds)>1
                    inet_inds = max(inet_inds); %Had problems when ethernet and wireless were connected. Wireless was listed second, so use that IP if more than one IPv4 is listed
                end
                space_inds = strfind(sysout,' ');
                nextspace_ind = space_inds(find(space_inds>inet_inds+36,1,'first'));
                ext_IP = sysout(inet_inds+36:nextspace_ind-2);
            end
            IPSTRING = ext_IP;
        end
        
        function [] = changeudplamp(app,ftpobj)
            if strcmp(ftpobj.Status,'open')
                disp('open!')
            else
                disp('closed!')
            end
            
        end
        
        function cellout = latlon2cell(app,vect_in,formatspec)
            %This function outputs a cell array of the inputed vector
            %vector in the format requested (see num2str for formatting
            %options) so that the correct precision can be used in table
            %generation. 
            
            %First make sure the vector is a column
            if isrow(vect_in)
                vect_in = vect_in';
            end
            %Create the char array.
            chararray = num2str(vect_in,formatspec);
            %Print out each row as cell element. 
            cellout = cell(size(vect_in));
            for i  = 1:numel(vect_in)
                cellout{i} = chararray(i,:);
            end
        end
        
       
        function [] = udpconnected(app)
            %This function is used to enable interface options when the UDP
            %connection is active. 
            app.TextArea_VEHTRM.Value = [{['*** UDP Connection Opened: ',datestr(now)]};app.TextArea_VEHTRM.Value];%Timestamp connection start
            app.ConnectButton.Enable    = 'off';
            app.DisconnectButton.Enable = 'on';
            app.StartRadioButton.Enable = 'on';
            app.StopRadioButton.Enable  = 'on';
            app.UDPLamp.Color           = [0, 1, 0];%Update connection display
            
            app.connsettings.udp.HB_timer = timer;
            app.connsettings.udp.HB_timer.TimerFcn = @checklastHB;
            app.connsettings.udp.HB_timer.Period = 1;
            app.connsettings.udp.HB_timer.ExecutionMode = 'fixedDelay';
            
            start(app.connsettings.udp.HB_timer)
            
            function [] = checklastHB(mTimer,~)
                %disp('Firing timer')
                if isnan(app.connsettings.udp.last_HB)
                    app.LastHearbeatEditField.Value = Inf; %DOn't know it yet becasue we haven't gotten a HB yet.
                else
                    app.LastHearbeatEditField.Value = now*86400-app.connsettings.udp.last_HB;   %This will contain the time of the last heartbeat
                end
                if app.LastHearbeatEditField.Value>5
                    app.LastHearbeatEditField.FontColor = [1 0 0];    
                    app.connsettings.udp.status = 'unknown';
                    app.StatusEditField.FontColor = [255,99,71]/255; %Tomato https://www.rapidtables.com/web/color/orange-color.html
                    app.StatusEditField.FontColor = [255,99,71]/255; %Tomato https://www.rapidtables.com/web/color/orange-color.html
                else
                    app.StatusEditField.FontColor       = [0,250,154]/255; %Medium spring green  https://www.rapidtables.com/web/color/green-color.html
                    app.LastHearbeatEditField.FontColor = [0,250,154]/255; %Medium spring green  https://www.rapidtables.com/web/color/green-color.html
                end
                app.StatusEditField.Value = app.connsettings.udp.status; %Last reported status message
                if strcmp(app.StatusEditField.Value,'unknown')
                    app.StartRadioButton.Enable = 'off';
                    app.StopRadioButton.Enable = 'off';
                else
                    app.StartRadioButton.Enable = 'on';
                    app.StopRadioButton.Enable = 'on';
                end
            end
            
        end
        
        function [] = udpdisconnected(app)
            %This function is used to enable interface options when the UDP
            %connection is disconnected. 
            app.TextArea_VEHTRM.Value = [{['*** UDP Connection Closed: ',datestr(now)]};app.TextArea_VEHTRM.Value];%Timestamp connection start
            app.ConnectButton.Enable    = 'on';
            app.DisconnectButton.Enable = 'off';
            app.StartRadioButton.Enable = 'off';
            app.StopRadioButton.Enable  = 'off';
            app.UDPLamp.Color           = [1, 0, 0];%Update connection display
            
            stop(app.connsettings.udp.HB_timer)
            delete(app.connsettings.udp.HB_timer)
            
             app.LastHearbeatEditField.FontColor = [1 1 1];
             app.StatusEditField.FontColor = [1 1 1];
             app.StatusEditField.Value = 'unknown';
             app.LastHearbeatEditField.Value = Inf;
            
        end


        function [] = plotknowntagloc(app)
            %This is a test, so plot the test beacon location
                homelatlon = [app.home.lat, app.home.lon];
                EnableButtonValueChanged(app); %Call the button press to reset the variables that might have been cleared when data was previously cleared by the clear data button press. 
                tag_true_latlon = [app.TEST.lat,app.TEST.lon];
                [tag_true_E,tag_true_N] = latlondiff2meters(homelatlon,tag_true_latlon);
                
                mapmarkersize = 12;
                
                if strcmp(app.TEST.ant,'Vertical')
                    plot3(app.UIAxes2,tag_true_N,tag_true_E,app.TEST.alt,'x','Markersize',mapmarkersize,'Linewidth',2,'Color',app.tru_bear_color,'Tag','LOC-TRUE');
                elseif strcmp(app.TEST.ant,'Unknown')
                    plot3(app.UIAxes2,tag_true_N,tag_true_E,app.TEST.alt,'o','Markersize',mapmarkersize,'Linewidth',2,'Color',app.tru_bear_color,'Tag','LOC-TRUE');
                else
                    beacon_vec_length = 100;
                    beacon_bearing = app.TEST.ant;%Don't need to convert from string, because it would already be a number if not caught by the other two cases
                    plot3(app.UIAxes2,tag_true_N,tag_true_E,app.TEST.alt,'o','Markersize',mapmarkersize,'Linewidth',2,'Color',app.tru_bear_color,'Tag','LOC-TRUE');
                    plot3(app.UIAxes2,tag_true_N+[-beacon_vec_length*cosd(beacon_bearing) beacon_vec_length*cosd(beacon_bearing)],tag_true_E+[-beacon_vec_length*sind(beacon_bearing) beacon_vec_length*sind(beacon_bearing)],[app.TEST.alt, app.TEST.alt],'Color',app.tru_bear_color,'Tag','LOC-TRUE'); 
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ismac
                app.cp_cmd = 'cp';
                app.slash_char = '/';
            elseif ispc
                app.cp_cmd = 'copy';
                app.slash_char = '\';
            end
            
            app.UIFigure.Name = 'UAV-RT Connect';
            app.FVEH_PROCESS.Value = app.default_veh_state_rate;


            %Setup defaults on radio inputs
            app.FRS_PROCESS.Value = app.default_Fs;
            app.PUL_D_PROCESS.Value = app.default_dur;
            app.PUL_R_PROCESS.Value = app.default_rep;
            app.FilterbandwidthHzEditField.Value = app.default_filt_band;
            
            %app.UAVRTComputerIPAddressEditField.Value = app.default_uavrt_ip;
            app.UpperlimitfromcenterfrequencyHzEditField.Value = app.default_search_lim_upper;
            
            %Make this field uneditable to start, since at startup, auto detection is selected
            app.PulsefrequencyHzEditField.Editable = 0;
            app.PulsefrequencyHzEditField.Enable = 0;
            %Vestigial code 
            %Disable dwell fields
            %waypt_dwell_time = 'auto';
%             app.MaxDwellEditField.Editable   = 'off';
%             app.MaxDwellEditField.Enable     = 'off';
%             app.MinDwellEditField.Editable   = 'off';
%             app.MinDwellEditField.Enable     = 'off';
            
            app.Lampvehicledataready.Enable     = 'off';
            app.Lamppreprocessingcomplete.Enable= 'off';
            app.Lamppulsefindcomplete.Enable    = 'off';
            app.Lampbearingscomplete.Enable     = 'off';
            app.Lamppositioncomplete.Enable     = 'off';
            
            app.ExtractPulsesButton.Enable      = 'off';
            app.BearingsButton.Enable           = 'off';
            app.StartAudioButton.Enable         = 'off';


            %INITIALIZE ALL THE FLIGHT PROPERTIES
            initialize_app_flight_props(app);
            
            
            app.player      = [];%set as empty so we can look to for it later
           % app.playbackcursors{1} = plot(app.UIAxes_WAVEFRM,[0;0],app.UIAxes_WAVEFRM.XLim);
           % hold(app.UIAxes_WAVEFRM,'on');
            
            
            %DOA Methods and Button setup
            app.CMButton.UserData   = 'CM';    %Attached the string to the button data
            app.MLEButton.UserData  = 'MLE';
            app.RMRButton.UserData  = 'RMR';
            app.MESTButton.UserData = 'MEST';
            app.doatypes = {app.CMButton.UserData,... %Create a list of all methods/
                app.MLEButton.UserData,...
                app.MESTButton.UserData,...
                app.MESTButton.UserData};
            app.doalogic = false(size(app.doatypes));  %Make it the same in case we add  more methods laters, we won't have to update this.
            app.doalogic(strcmp(app.doatypes,'MLE')) = true; %Make MLE the default selection
            app.CMButton.Value   = 0;    %And set the button values to match
            app.MLEButton.Value  = 1;
            app.RMRButton.Value  = 0;
            app.MESTButton.Value = 0;
            
            %Initialize mapping variables
            app.h_mapcache_ax = [];%Empty axis of eventual map caching axis.
            
            app.zoom_dl_sel_1.Value  = 0;
            app.zoom_dl_sel_2.Value  = 0;
            app.zoom_dl_sel_3.Value  = 0;
            app.zoom_dl_sel_4.Value  = 0;
            app.zoom_dl_sel_5.Value  = 0;
            app.zoom_dl_sel_6.Value  = 0;
            app.zoom_dl_sel_7.Value  = 0;
            app.zoom_dl_sel_9.Value  = 0;
            app.zoom_dl_sel_10.Value = 0;
            app.zoom_dl_sel_11.Value = 0;
            app.zoom_dl_sel_12.Value = 0;
            app.zoom_dl_sel_13.Value = 0;
            app.zoom_dl_sel_14.Value = 0;
            app.zoom_dl_sel_15.Value = 0;
            app.zoom_dl_sel_16.Value = 1;
            
            %% Setup configurtation of results map for proper zooming.
            %This code is needed to force the limits of the z axis to
            %display all of the data. If you don't do this, when you zoom
            %in, the z data will get clipped. During off clipping in the
            %axes properties does some weird stuff that and doesn't plot
            %well. Setting a listenter on the zlim property wasn't
            %allowable initially, but I found this hack here that allowed
            %me to add the listenter on the z axis. I also turn off the
            %warning regarding the structOnObject so the warning isn't
            %fired when I used this hack.
            %https://stackoverflow.com/questions/53825151/uiaxes-ylim-property-cannot-be-listened-to
            warning('off','MATLAB:structOnObject');
            %addlistener(struct(app.UIAxes2).Axes, 'ZLim', 'PostSet', @(src,evnt)mapholdaltlims(app,app.UIAxes2));
            app.UIAxes2.Layer = 'top' ;
            %Limit results map so that only x and y are zoomable.
            app.UIAxes2.Interactions = [zoomInteraction('Dimensions','xy')];
            
            %Setup icons and map cache directory. If deployed as an application,
            %store the maps within the application file directory. If using
            %natively within matlab, use a relative file path. 
            %https://blogs.mathworks.com/loren/2008/08/11/path-management-in-deployed-applications/
            if isdeployed
                %app.mapcachedir = [ctfroot,app.slash_char,'UAVRT_Maps'];
                %                 app.mapcachedir    = fullfile(ctfroot,'Program_files','UAVRT_Maps');
                %                 app.iconsdir       = fullfile(ctfroot,'UAVRT','Icons'); %This requires that '-a Icons' be added to the 'Additional parameters passed to mcc' in the application compiler settings. Otherwise the folder won't get installed in the right place.
                %                 app.configfilesdir = fullfile(ctfroot,'Program_files');
                %                 app.ctfrootEditField.Value = ctfroot;
                %                 app.matlabrootEditField.Value = matlabroot;
                if ismac
                    app.mapcachedir    = fullfile(ctfroot,'Program_files','UAVRT_Maps');
                    app.iconsdir       = fullfile(ctfroot,'UAVRT','Icons'); %This requires that '-a Icons' be added to the 'Additional parameters passed to mcc' in the application compiler settings. Otherwise the folder won't get installed in the right place.
                    app.configfilesdir = fullfile(ctfroot,'Program_files');
                    app.ctfrootEditField.Value = ctfroot;
                    app.matlabrootEditField.Value = matlabroot;
                    %IF DEPLOYED ON A PC, IT MUST BE INSTALLED TO PROGRAM FILES
                elseif ispc
                    app.mapcachedir    = fullfile('C:\','Program Files','UAVRT','Program_files','UAVRT_Maps');
                    app.iconsdir       = fullfile('C:\','Program Files','UAVRT','application','Icons'); %This requires that '-a Icons' be added to the 'Additional parameters passed to mcc' in the application compiler settings. Otherwise the folder won't get installed in the right place.
                    app.configfilesdir = fullfile('C:\','Program Files','UAVRT','Program_files');
                    app.ctfrootEditField.Value = ctfroot;
                    app.matlabrootEditField.Value = matlabroot;
                else
                    uialert(app.UIFigure,'UAVRT is only support on Mac and PC systems.','File structure error');
                end
            else
                if ismac
                    app.mapcachedir = fullfile('Program_files','UAVRT_Maps');
                    app.iconsdir    = ['Icons'];%Use relative path to icons directory if running within Matlab
                    app.configfilesdir = fullfile('Program_files','Config_Files');
                elseif ispc
                    app.mapcachedir = fullfile('Program_files','UAVRT_Maps');
                    app.iconsdir = ['Icons'];%Use relative path to icons directory if running within Matlab
                    app.configfilesdir = fullfile('Program_files','Config_Files');
                else
                    uialert(app.UIFigure,'UAVRT is only support on Mac and PC systems.','File structure error');
                end
            end
            
            app.MapcachedirEditField.Value = app.mapcachedir; %Set the display to the default dir
            
            %Specify the maps available for download from USGS and configure the download and results options to match
            app.mapselectvals   = {'USGSTopo',...
                'USGSImageryTopo',...
                'USGSImageryOnly',...
                'USGSShadedReliefOnly',...
                'USGSHydroCached',...
                'Blank'};
            app.mapselectnames   = {'Topo',...
                'Imagery & Topo',...
                'Imagery Only',...
                'Shaded Relief' ...
                'Hydro',...
                'Blank'};
            
            app.MapDropDown.Items         = app.mapselectnames; %Set the display names
            app.MapDropDown.ItemsData     = app.mapselectvals; %Set the actual names needed for url generation
            app.Toposelectcheckbox.Text         = app.mapselectnames{1};
            app.ImageryToposelectcheckbox.Text  = app.mapselectnames{2};
            app.Imageryonlyselectcheckbox.Text  = app.mapselectnames{3};
            app.Shadedselectcheckbox.Text       = app.mapselectnames{4};
            app.Hydroselectcheckbox.Text        = app.mapselectnames{5};
            app.TNMBlankselectcheckbox.Text     = app.mapselectnames{6};
            
            app.zoomvals = [0,1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16];
            
            %%USE THIS CODE HERE AND IN OTHER CALLBACKS TO UPDATE THE
            %%RESULTS WHEN THE USER SELECTED WAYPOINTS
            %app.UIWayptSelTable.CellEditCallback = @PLOTTHEBEARING VECTORS;
            %Check to see what the user has selected.
            %user_waypt_select_logic = table2array(app.UIWayptSelTable.Data(:,2));
            %NEED TO SPECIFY THE COLUMN WIDTH?
            
            app.PositionButton.Enable = false;
            
            
            %Specify the default SDR configuration settings
            app.FVEH_INPUT.Value = app.default_veh_state_rate;
            app.RFG_INPUT.Value = 17;
            app.IFG_INPUT.Value = 8;
            app.BBG_INPUT.Value = 10;
            app.FT_INPUT.Value  = 148.000;
            app.FRS_INPUT.Value = 48000;
            app.PUL_D_INPUT.Value = 0.02;
            app.PUL_R_INPUT.Value = 1.30;
            app.NOTES_INPUT.Value = '';
            
            %Initialize connection objects as empty
            app.ftpobj = [];
            app.udpobj = [];
            app.DisconnectButton.Enable   = 'off';
            app.StartRadioButton.Enable   = 'off';
            app.StopRadioButton.Enable    = 'off';
            app.GetsettingsButton.Enable  = 'off';
            app.SendsettingsButton.Enable = 'off';
            app.UAV_FTP_Pop_Dir.Enable    = 'off';
            app.DownloadDataButton.Enable = 'off';
            app.FTPLamp.Color = [1, 0, 0];
            app.UDPLamp.Color = [1, 0, 0];
            app.connsettings.pc.ip = getmyip(app);
            app.connsettings.pc.port  = 9091; %Port we will send from 
            %app.connsettings.pc.port_in   = 9092; %Port we will receive from 
            app.connsettings.uav.ip       = '10.42.0.1';%'134.114.64.117';
            app.connsettings.uav.port     = 9090; %Port we will receive from 
            %app.connsettings.uav.port_in  = 9091; %Port we send data to
            app.connsettings.ftp.addr     = app.connsettings.uav.ip; %UAV FTP server address
            app.connsettings.ftp.user     = 'dasl'; %UAV FTP server address
            app.connsettings.ftp.pswd     = 'dasl249'; %UAV FTP server address
            app.connsettings.ftp.origdir  = []; %UAV FTP initial connection directory. 
            app.connsettings.ftp.flightdir  = []; %UAV FTP connection directory for flight data 
            app.connsettings.ftp.configflnm  = 'curr_config.uavrt'; %UAV FTP connection directory for flight data 
            app.connsettings.udp.flg = '';      %A string we will set when sending a udp message and then clear when the confirmation is made by the response of the remote host
            app.connsettings.udp.last_HB = NaN;   %This will contain the time of the last heartbeat
            app.connsettings.udp.status = 'UNKNOWN';   %This will contain the status of what is reported by the UAV companion computer
            app.connsettings.udp.HB_timer = []; %A timer object for checking the heartbeat
            
            %Set up the table headings
            app.UITable.ColumnName = {'Waypt#','Time @','x(m-N)','y(m-E)','ALT (m)','Lat','Lon'};
            app.UITable.ColumnName = {'Waypt#','Time @','x(m-N)','y(m-E)','ALT (m)','Lat','Lon'};
            
            %Ask for the working directory. Hack for when deployed file
            %structure is broken
            %SetworkingdirectoryButtonPushed(app)

            %Scale the window appropriately
            %allobjs = findall(app.UIFigure);
            %First get the size of the screen,
             screensize = get(0,'ScreenSize');           
            %Get a list of object that we want to resize. Got these mainly
            %thru trial and error
             panel_objs = findobj(app.UIFigure,'Type','uipanel');
             tabgroup_objs = findobj(app.UIFigure,'Type','uitabgroup');
             table_objs = findobj(app.UIFigure,'Type','uitable');
             axes_objs = findobj(app.UIFigure,'Type','axes');
            % textarea_objs = findobj(app.UIFigure,'Type','uitextarea');
            % tree_objs = findobj(app.UIFigure,'Type','uitree');
             
             allobjs = [app.UIFigure;tabgroup_objs;panel_objs;table_objs;axes_objs];
             pause(0.5); %Without this pause the resizing doesn't work right. I think it might have to do with the figure window not being ready yet. 
             
             %Create factors for scaling. each will be used for the corner
             %position and the size. 
             horiz_factor = 1;%screensize(3)/app.UIFigure.Position(3);
             vert_factor = 0.95*screensize(4)/app.UIFigure.Position(4);
             %[left bottom width height]
             factors = [horiz_factor,vert_factor,horiz_factor,vert_factor];
             %Now rescale all the necessary objects
            for i = 1:length(allobjs) 
                try
                    currpos = allobjs(i).Position;
                    newpos = factors.*(currpos-1)+1;
                    allobjs(i).Position = newpos;
                catch ME
                    %This worked when trying to change objhects properties,
                    %yet some were not settable. This no longer typically
                    %catches anything, but it is getting left here just in
                    %case. 
                    disp('Can`t set something.')
                    disp(ME)
                end
            end
            %disp('Breakpoint!')
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            startpath = app.LocalfolderEditField.Value;%Start looking at the place where the vechile data was downloaded. 
            skip = false; %Default to not skipping the last part of the function. 
            
            if isempty(startpath)%If we don't have a recently downloaded folder, allow users to look for one.
                select_indiv = false;
            elseif ~isfolder(startpath)%If this was a file not a folder, get the folder path as the starting location. 
                [startpath,~,~] = fileparts(startpath); 
                select_indiv = true;
            else  %isfolder(startpath) must be true - We have a recently downloaded folder, start there for the directory search
                select_indiv = false;
            end
            
            if select_indiv == false
            app.UIFigure.Visible = 'off';
                alldatadir = uigetdir(startpath,'Select data folder');
            app.UIFigure.Visible = 'on';
                fltfls = dir(fullfile(alldatadir,'*.flt'));
                sdrfls = dir(fullfile(alldatadir,'*.dat'));
                configfls = dir(fullfile(alldatadir,'*.uavrt'));
                if (length(fltfls)~=1) || (length(sdrfls)~=1) || (length(configfls)~=1) 
                    %First check to see if there are only one of the needed
                    %file types. If there are more than one or none, the user need
                    %to select which to use invidually
                   %uialert(app.UIFigure,'More than one (or no) flight, radio, and/or config files were found in the selected folder. You"ll now need to select these files individually','Alert','Icon','error') 
                   selection = uiconfirm(app.UIFigure,'More than one (or no) flight, radio, and/or config files were found in the selected folder. You''ll now need to select these files individually','Folder selection issue','Icon','warning');
                   if strcmp(selection,'OK')
                    select_indiv = true;
                   else
                       %If user cancels, skip the rest of the fucntion. 
                       select_indiv = false;
                       skip = true; 
                   end
                elseif (length(fltfls)==1) && (length(sdrfls)==1) && (length(configfls)==1) 
                    %Otherwise, autoselected the file paths. Set the path
                    %and names here
                    flt_data_flnm = fltfls.name;
                    flt_data_path = fltfls.folder;
                    sdr_data_flnm = sdrfls.name;
                    sdr_data_path = sdrfls.folder;
                    con_data_flnm = configfls.name;
                    con_data_path = configfls.folder;
                end
            end  
            if select_indiv == true
                try
                    app.UIFigure.Visible = 'off';
                    [flt_data_flnm,flt_data_path] = uigetfile([startpath,app.slash_char,'*.flt'],'SELECT FLIGHT DATA RECORD');
                    [sdr_data_flnm,sdr_data_path] = uigetfile([flt_data_path,'*.dat'],'SELECT RADIO DATA RECORD');
                    [con_data_flnm,con_data_path] = uigetfile([flt_data_path,'*.uavrt'],'SELECT CONFIGURATION FILE');
                    app.UIFigure.Visible = 'on';
                    
                catch ME
                    uialert(app.UIFigure,'All file selections needed.','Alert','Icon','warning')
                    clear ME
                    skip = true;
                end
            end
            
            if ~skip
                %After finding the flight data, use its path as the
                %default location for processed results.
                [~,default_output_savestring,~] = fileparts(flt_data_flnm);%Use the filename as the default output savestring.
                default_output_path = fullfile(flt_data_path,'OUTPUT'); %Default results locations is a subfolder within the data folder
                app.OutputsavestringEditField.Value = default_output_savestring;
                %if isempty(app.OutputTextArea.Value{1})%If we haven't filled this in yet, default to the same folder
                        app.OutputTextArea.Value  = default_output_path;
                %end
                    
                fltloc = fullfile(flt_data_path,flt_data_flnm);
                sdrloc = fullfile(sdr_data_path,sdr_data_flnm);
                conloc = fullfile(con_data_path,con_data_flnm);
                
                %Set text fields with selected filenames
                app.FlightFileTextArea.Value        = fltloc;
                app.RadioFileTextArea.Value         = sdrloc;
                app.ConfigurationFileTextArea.Value = conloc;


                %Read the config file we just downloaded
                OUTSTRUCT = configread(conloc);
                %Update the data fields for processing
                app.FVEH_PROCESS.Value  = str2double(OUTSTRUCT.SDRSYS.FVEH);
                app.FT_PROCESS.Value    = str2double(OUTSTRUCT.TAG(1).FT);
                app.FRS_PROCESS.Value   = str2double(OUTSTRUCT.TAG(1).FRS);
                app.PUL_D_PROCESS.Value = str2double(OUTSTRUCT.TAG(1).PUL_D);
                app.PUL_R_PROCESS.Value = str2double(OUTSTRUCT.TAG(1).PUL_R);
                app.NOTES_PROCESS.Value = OUTSTRUCT.NOTES;
            end


        end

        % Button pushed function: VehicleDataButton
        function VehicleDataButtonPushed(app, event)
            
            h_wait_veh = uiprogressdlg(app.UIFigure,'Message','Processing vehicle flight data...','Title','Flight data progress');
            
            app.Lampvehicledataready.Enable = 'off';
            if isempty(app.FlightFileTextArea.Value{1})
                uialert(app.UIFigure,'Select flight data file first.','No flight data specified');
            else
            %First figure out which type of waypoint detection to do: manual or auto
            %selectedDwellButton = app.WaypointdetectionButtonGroup.SelectedObject;
            if app.AutoDwellButton.Value%strcmp(selectedDwellButton.Text,app.AutoButton.Text)
                waypt_dwell_time = 'auto';
            else
                waypt_dwell_time= [app.MinDwellEditField.Value app.MaxDwellEditField.Value];
            end
            %Now set up the auto or manually entered waypoint altitude
            %definitions.
            if app.AutoAltButton.Value
                waypt_alts = NaN;%The program uses the maximum altitude if the alt range is not specified or listed as NaN.
            else
                if app.AltUnitsDropDown.Value==2 %User seleted feet, but all other data is in meters
                    unitfactor = 3.28084; %ft/meter
                else %Units are meters is data is 1 in dropdown
                    unitfactor = 1;
                end
                    waypt_alts= unitfactor*[app.MinAltEditField.Value app.MaxAltEditField.Value];
            end
            
            
            [veh_states,waypt_out,latlonhome] = flightprep(app.FlightFileTextArea.Value{1},'all','plot','axis_handle',[app.UIAxes,app.UIAxes2],'waypt_time',waypt_dwell_time,'waypt_alt',waypt_alts,'fs',app.FVEH_PROCESS.Value);
            waypt_time = waypt_out(:,end-1:end);%Grab the last two columns to create a more readable code with waypt_times [start end] for each waypoint (in the rows)
            app.waypt.num   = size(waypt_out,1);
            app.waypt.x     = waypt_out(:,1);
            app.waypt.y     = waypt_out(:,2);
            app.waypt.alt   = waypt_out(:,3);
            app.waypt.tin   = waypt_out(:,4);
            app.waypt.tout  = waypt_out(:,5);
            app.waypt.dt    = diff(waypt_time,[],2);
            app.home.lat    = latlonhome(1);
            app.home.lon    = latlonhome(2);
            
            app.UAV.time    =  veh_states(:,1);
            app.UAV.roll    =  veh_states(:,2);
            app.UAV.pitch   =  veh_states(:,3);
            app.UAV.yaw     =  veh_states(:,4); %Total yaw from start (not wrapped to 360).
            app.UAV.alt     =  veh_states(:,5);
            app.UAV.lat     =  veh_states(:,6);
            app.UAV.lon     =  veh_states(:,7);
            app.UAV.x       =  veh_states(:,8);
            app.UAV.y       =  veh_states(:,9);
            app.UAV.atwaypt =  veh_states(:,10);%Logical when at a waypt
            app.UAV.atalt   =  veh_states(:,11);%Logical when at altitude
            
            if app.AtaltitudeButton.Value
                %Use the first and last point where the vehicle was
                %within 90% of the max altitiude. See the comments in
                %flightprep.m to see how this get set.
                app.StartsEditField.Value = app.UAV.time(find(app.UAV.atalt,1,'first'));
                app.EndsEditField.Value   = app.UAV.time(find(app.UAV.atalt,1,'last'));
            end
            
            h_wait_veh.Message = 'Plotting results...';
            h_wait_veh.Value = 0.5;
            
            %Waypoints in lat/lon
            %Switch the x-y because the lat/lon converter uses xEyN
            waypt_latlon = xy2latlon(latlonhome,waypt_out(:,2:-1:1));
            app.waypt.lat = waypt_latlon(:,1);
            app.waypt.lon = waypt_latlon(:,2);
            
            view(app.UIAxes2,[90 90]);
            varnames = {'Waypoint #','Time (s)','x (m)','y (m)','Alt. (m)','Lat (deg)','Lon. (deg)'};
            waypt_table = table((1:app.waypt.num)',app.waypt.dt,app.waypt.x,app.waypt.y,app.waypt.alt,app.waypt.lat,app.waypt.lon,...
                                'VariableNames',varnames);
            %app.UITable.Data = [(1:app.waypt.num)',app.waypt.dt,app.waypt.x,app.waypt.y,app.waypt.alt,app.waypt.lat,app.waypt.lon];
            app.UITable.Data = waypt_table;
            
            
            app.Lampvehicledataready.Enable = 'on';
            if strcmp(app.Lamppreprocessingcomplete.Enable, 'on') %Only allow pulse find if radio data is also ready
                app.ExtractPulsesButton.Enable = 'on';
            end
            
            %Set up waypoint selection table
            wpselect = true(app.waypt.num,1);
            wpnum = (1:app.waypt.num)';
            app.UIWayptSelTable.Data = table(wpnum,wpselect);
            app.UIWayptSelTable.ColumnEditable = [false, true];
            %app.UIWayptSelTable.ColumnWidth
            
%             %Plot the position of the beacon if testing
%             if app.EnableButton.Value
%                plotknowntagloc(app);
%             end
            PlotButtonPushed(app);

            end
            
        end

        % Callback function
        function ResetFiguresButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
        end

        % Callback function
        function ConnecttosharedfolderButtonPushed(app, event)
            if ismac
            command = ['open smb://',app.UAVRTComputerIPAddressEditField.Value];
            elseif ispc
            command = ['net use U: \\',app.UAVRTComputerIPAddressEditField.Value];
            end
            
            [status, cmdout] = system(command);
            
        end

        % Callback function
        function SelectRemoteDataButtonPushed(app, event)
            try
                [flt_data_flnm_remote,flt_data_path_remote] = uigetfile('*.txt','SELECT FLIGHT DATA RECORD');
                [sdr_data_flnm_remote,sdr_data_path_remote] = uigetfile([flt_data_path_remote,'*.dat'],'SELECT RADIO DATA RECORD');
                fltloc_remote = [flt_data_path_remote,flt_data_flnm_remote];
                sdrloc_remote = [sdr_data_path_remote,sdr_data_flnm_remote];
                app.RemoteFlightFileTextArea.Value = fltloc_remote;
                app.RemoteRadioFileTextArea.Value = sdrloc_remote;
            catch ME
                uialert(app.UIFigure,'Select both flight and radio data.','Alert','Icon','error')
                clear ME
            end
            
        end

        % Callback function
        function BackuptoButtonPushed(app, event)
            try
                app.UIFigure.Visible = 'off';
                local_backup_dir = uigetdir([],'SELECT LOCATION FOR LOCAL DATA BACKUP');
                app.UIFigure.Visible = 'on';
                app.LocalfolderEditField.Value = local_backup_dir;%Set field with the selected location
                %Copy the files:
                
                %Get the original filename
                loc_of_flt_flnm_str = max(strfind(app.RemoteFlightFileTextArea.Value{1},app.slash_char));
                flt_flnm = app.RemoteFlightFileTextArea.Value{1}(loc_of_flt_flnm_str:end);
                %Use it to setup the command to copy with the same name
                %copy with source and desitination. Include quotes in case there are spaces
                command_flt_cp = [app.cp_cmd,' "',app.RemoteFlightFileTextArea.Value{1},'" "',[local_backup_dir,flt_flnm],'"'];
                
                uialert(app.UIFigure,'Copying flight data...','Backup status','Icon','info');
                [status, cmdout] = system(command_flt_cp);%Copy system command
                uialert(app.UIFigure,'Copying flight data...complete!','Backup status','Icon','success');
                if status~=0
                    error('Failed to copy flight data');
                else
                    %Auto populate the loading data field
                    app.FlightFileTextArea.Value = [local_backup_dir,flt_flnm];
                    app.OutputsavestringEditField.Value = flt_flnm(2:end-4);
                    app.OutputTextArea.Value = [local_backup_dir,'OUPUT'];
                end
                
                loc_of_sdr_flnm_str = max(strfind(app.RemoteRadioFileTextArea.Value{1},app.slash_char));
                sdr_flnm = app.RemoteRadioFileTextArea.Value{1}(loc_of_sdr_flnm_str:end);
                %Use it to setup the command to copy with the same name
                %copy with source and desitination. Include quotes in case there are spaces
                command_sdr_cp = [app.cp_cmd,' "',app.RemoteRadioFileTextArea.Value{1},'" "',[local_backup_dir,sdr_flnm],'"'];
                uialert(app.UIFigure,'Copying radio data (this might take a while)...','Backup status','Icon','info');
                [status, cmdout] = system(command_sdr_cp);%Copy system command
                uialert(app.UIFigure,'Copying radio data (this might take a while)...complete!','Backup status','Icon','success');
                if status~=0
                    error('Failed to copy radio data');
                else
                    %Auto populate the loading data field
                    app.RadioFileTextArea.Value = [local_backup_dir,sdr_flnm];
                end
            catch ME
                uialert(app.UIFigure,['Problems with transfer: ',ME.message],'Alert','Icon','error')
                clear ME
            end
        end

        % Button pushed function: RadioDataButton
        function RadioDataButtonPushed(app, event)
            
        app.Lamppreprocessingcomplete.Enable = 'off';%Turn off the lamp
            
            %Get parameters from the text boxes
            Fs           = app.FRS_PROCESS.Value;
            pulse_dur    = app.PUL_D_PROCESS.Value;
            search_range = app.UpperlimitfromcenterfrequencyHzEditField.Value;
            filtband     = app.FilterbandwidthHzEditField.Value;
            
            
            %Determine which output plots are wanted
            if app.PlotspectumofradiodataCheckBox.Value==1&&app.PlotspectrogramthismighttakeacoupleofminutesCheckBox.Value==1
                plt_control = 'both';
%                handle_list = [app.UIAxes_PSD];
            elseif app.PlotspectumofradiodataCheckBox.Value==1&&app.PlotspectrogramthismighttakeacoupleofminutesCheckBox.Value==0
                plt_control = 'fft';
%                handle_list = [app.UIAxes_PSD];
            elseif app.PlotspectumofradiodataCheckBox.Value==0&&app.PlotspectrogramthismighttakeacoupleofminutesCheckBox.Value==1
                plt_control = 'spectro';
%                handle_list = [app.UIAxes_SPECTRO];
            else
                plt_control = 'none';
%                handle_list = [];
            end
            
            
            try
                h_wait = uiprogressdlg(app.UIFigure,'Message','Finding pulse frequency','Title','Radio Processing Progress','Indeterminate','on');
                radio_out = radioprep(app.RadioFileTextArea.Value{1},Fs,pulse_dur,...
                                      [app.StartsEditField.Value, app.EndsEditField.Value],... %time bounds to consider
                                      'fmax',search_range,...
                                      'filterband',filtband);%'plot',plt_control,'spectro',1); 'axis_handles',handle_list,
                [~,msgID] = lastwarn; %The radioprep function will throw an 
                                        %error if it has to fix the time bounds. 
                                        %We catch that here, set the time bounds
                                        %displayed to the user, and let the
                                        %user know their selection was
                                        %modified. 
                if strcmp(msgID,'UAVRT:timeOutofbounds')
                    uialert(app.UIFigure,['The time range requested was outside ' ...
                                          'the available radio data. This might ' ...
                                          'have happened because the radio was ' ...
                                          'shut down before the flight was ' ...
                                          'terminated, or you may have entered ' ...
                                          'a time incorrectly. The requested ' ...
                                          'time bounds was ' ...
                                          '[',num2str([app.StartsEditField.Value, app.EndsEditField.Value]),']' ...
                                          ', but has been updated to ' ...
                                          '[',num2str(radio_out{3}),']'],...
                                          'Alert','Icon','warning');
                    %Update the display
                    app.StartsEditField.Value = radio_out{3}(1);
                    app.EndsEditField.Value = radio_out{3}(2);
                end
            catch ME
                uialert(app.UIFigure,['Problems with preparing radio data: ',ME.message],'Alert','Icon','error')
                clear ME
            end
            
            try 
                %close(h_wait)
                
                %Unpack results
                f_raw = radio_out{1}; %This is the frequency where pulses were detected.
                f     = abs(f_raw); %TFrequency to build filter around
                app.SDR.raw.data = radio_out{2};
                    app.SDR.raw.data(1:round(Fs)+1) = 0;%The first second of data is usually bad as the SDR starts up. 
                    %Decimate results for reduced size.
                    Fs_red = 3*app.UpperlimitfromcenterfrequencyHzEditField.Value; %Calculate the approximate reduced sampling rate so that 3 pts per cycle are maintained at the upper limit of the frequency search bandwidth
                    decimationfactor = ceil(Fs/Fs_red);%Calculate the deceimation factor. Use ceil because decimate requires an integer
                    app.SDR.dec.Fs = Fs/decimationfactor;
                    app.SDR.dec.data = decimate(app.SDR.raw.data,decimationfactor);
                freqs_1Hz = radio_out{4}; %The list of 1 Hz for the amplitude results below
                P1_1Hz    = radio_out{5}; %These are the spectrum results smoothed over 1 Hz
                clear radio_out  %No longer need this. 
                
                %Now put that in the field if the user wants to use auto detected value
                selectedButton = app.RadioProcessingOptionsButtonGroup.SelectedObject;
                if strcmp(selectedButton.Text,app.AutodetectpulsefrequencyButton.Text)%Auto pulse frequency detection
                    app.PulsefrequencyHzEditField.Value = f;
                    app.PulsefrequencyHzEditField.Editable = 'off';
                    app.PulsefrequencyHzEditField.Enable = 'off';
                end
                
               h_wait.Message = 'Plotting waveform';
                
                %% Plot time domain waveform
                t_plot = (0:1:(length(app.SDR.dec.data)-1))*1/app.SDR.dec.Fs;
                indselect = (t_plot>=app.StartsEditField.Value) & (t_plot<=app.EndsEditField.Value);%Select a portion within the time bounds of the edit fields
                plot(app.UIAxes_WAVEFRM,t_plot(indselect),real(app.SDR.dec.data(indselect)),'Color',[0.8 0.8 1])
                app.UIAxes_WAVEFRM.YLim = 1.1*[min(real(app.SDR.dec.data)),max(real(app.SDR.dec.data))];
                app.UIAxes_WAVEFRM.XLim = [-inf inf]; %Set to auto
                hold(app.UIAxes_WAVEFRM,'on');
                drawnow
                
                h_wait.Message = 'Writing wavefile';
                %Write the audio file
               [status, msg, msgID] = mkdir(app.OutputTextArea.Value{1});
                audiowrite([app.OutputTextArea.Value{1},app.slash_char,app.OutputsavestringEditField.Value,'-AUDIO_RAW.wav'],real(app.SDR.raw.data)./max(abs(real(app.SDR.raw.data))),Fs,'Title',[app.OutputsavestringEditField.Value,'-raw']);
 
                if strcmp(plt_control,'fft') || strcmp(plt_control,'both')
                    h_wait.Message = 'Plotting spectrum';
                    %% Plot the frequency domain
                    sub_range_select = (freqs_1Hz<search_range & freqs_1Hz>-search_range);
                    freqs_1Hz_sub_range = freqs_1Hz(sub_range_select);
                    [max_pow_val,ind_max_pow] = max(P1_1Hz(sub_range_select));%Find the maximum in the frequency range less than 1kHz- the tag should be within 1 kHz if the radio was tuned to its center frequency since with have 3 digits past the MHz decimal place - ie. 150.256 MHz - maxx it could be off is +/- 500 Hz 
                    plot(app.UIAxes_PSD,freqs_1Hz,P1_1Hz)
                    title(app.UIAxes_PSD,['Double-Sided Amplitude Spectrum of X(t) Zoomed to \pm',num2str(-search_range),'Hz'])
                    xlabel(app.UIAxes_PSD,'f (Hz)')
                    ylabel(app.UIAxes_PSD,'|P1(f)|')
                    set(app.UIAxes_PSD,'xlim',[-search_range search_range])
                    set(app.UIAxes_PSD,'ylim',[0 1.2*max_pow_val])
                    hold(app.UIAxes_PSD,'on')
                    amp_range = get(app.UIAxes_PSD,'ylim');
                    fill(app.UIAxes_PSD,[f_raw-filtband/2,f_raw-filtband/2,f_raw+filtband/2,f_raw+filtband/2],[amp_range flip(amp_range)],[1 0.3 0.3],'Facealpha',0.1,'Edgecolor','none')
                    plot(app.UIAxes_PSD,freqs_1Hz_sub_range(ind_max_pow),max_pow_val,'r.','Markersize',10)
                    legend(app.UIAxes_PSD,'Amplitude spectrum','Selected frequency')
                    drawnow
                end
                
                if strcmp(plt_control,'spectro') || strcmp(plt_control,'both')
                    h_wait.Message = 'Computing spectrogram';
                    %h_wait = uiprogressdlg(app.UIFigure,'Message','Computing spectrogram','Title','Radio Processing Progress','Indeterminate','on');
                    spectro(app)
          
                end
                
                close(h_wait)   
                
                %Setup some limits
                %view(app.UIAxes_SPECTRO,0,90)
%                 app.UIAxes_SPECTRO.XLim = app.UIAxes_PSD.XLim;
%                 app.UIAxes_SPECTRO.YLim = app.UIAxes_WAVEFRM.YLim;
                
                %Link the axes of the spectrogram, waveform and PSD plots
%                 warning('off','MATLAB:structOnObject');
%                 addlistener(struct(app.UIAxes_PSD).Axes,     {'XLim','YLim'}, 'PostSet', @(src,evnt)syncaxes(app,[app.UIAxes_SPECTRO,app.UIAxes_WAVEFRM],app.UIAxes_PSD));
%                 addlistener(struct(app.UIAxes_SPECTRO).Axes, {'XLim','YLim'}, 'PostSet', @(src,evnt)syncaxes(app,[app.UIAxes_PSD,  app.UIAxes_WAVEFRM],app.UIAxes_SPECTRO));
%                 addlistener(struct(app.UIAxes_WAVEFRM).Axes, {'XLim','YLim'}, 'PostSet', @(src,evnt)syncaxes(app,[app.UIAxes_PSD,  app.UIAxes_SPECTRO],app.UIAxes_WAVEFRM));
%                 %app.UIAxes2.Layer = 'top' ;
%                 %Limit results map so that only x and y are zoomable.
%                 app.UIAxes_SPECTRO.Interactions = [zoomInteraction('Dimensions','xy')];
%                 app.UIAxes_WAVEFRM.Interactions = [zoomInteraction('Dimensions','y')];
                grid(app.UIAxes_PSD,'on');
                grid(app.UIAxes_WAVEFRM,'on');
                %grid(app.UIAxes_SPECTRO,'on');
                
                %Turn on the completion lamp and allow useres to use the find pulse button
                app.Lamppreprocessingcomplete.Enable = 'on';
                if strcmp(app.Lampvehicledataready.Enable, 'on') %Only allow pulse find if vehicle data is also ready
                    app.ExtractPulsesButton.Enable = 'on';
                    app.TagcheckButton.Enable = 'on';
                end
                
            catch ME
                uialert(app.UIFigure,['Problems post processing radio data: ',ME.message],'Alert','Icon','error')
                clear ME
            end
            
            function spectro(app,event) %This function plots the spectrogram of the section of the dataset between t0 and t1
                
                spectro_fig_h = figure;
                spectro_axes_h = axes(spectro_fig_h);
                
                Fs_local   = app.SDR.dec.Fs;  %Or you could use Fs if you want to do the spectro on the non-decimated dataset
                data_local = app.SDR.dec.data;%Or you could use .raw.data for full samplerate
                
                pulse_n  = pulse_dur*Fs_local;             %the expected number of samples per pulse
                t_bounds = [app.StartsEditField.Value, app.EndsEditField.Value];
     
                %Use Fs and start and end times to down select data to FFT. 
                ind_start   = floor(min(t_bounds)*Fs_local+1);%+1 because first element is at t = 0;
                if t_bounds(2)~=Inf
                    ind_end = floor(max(t_bounds)*Fs_local);
                else  %If Inf, then use the entire record
                    ind_end = length(data_local); 
                end
                
                inds_select =  ind_start:1:ind_end;
                if mod(length(inds_select),2) %Then it is odd
                    inds_select = inds_select(1:end-1); %we want an even number of elements for future operations.
                end
                %USING THE RAW DATA
                %
                % the_data = data_local(inds_select);%Downselect for time rang under consideration
                [s_spec,f_spec,t_spec] =  spectrogram(data_local(inds_select),pulse_n*2,pulse_n,-search_range:50:search_range,Fs_local);
                %[s_spec,f_spec,t_spec] = spectrogram(data_local(inds_select),2*pulse_n*2,2*pulse_n,-search_range:50:search_range,Fs_local);
                t_spec = t_spec+t_bounds(1);
                %surf(app.UIAxes_SPECTRO,f_spec,t_spec,transpose(10*log10(abs(s_spec))),'Edgecolor','none');
                %view(app.UIAxes_SPECTRO,0,90)
                %DON't PLOT THE FIRST SECOND, AS THE SDR STARTUP CREATES
                %LARGE ERRONEOUS DATA
                %imagesc(app.UIAxes_SPECTRO,f_spec,t_spec(t_spec>1),transpose(10*log10(abs(s_spec(:,(t_spec>1))))),'AlphaData',0.7)
                spectro_surf_h = surf(spectro_axes_h,f_spec,t_spec(t_spec>1),transpose(10*log10(abs(s_spec(:,(t_spec>1))))),'AlphaData',0.7,'EdgeColor','interp');
                spectro_axes_h.View = [-115 45];
                spectro_axes_h.XLabel.String = 'Frequency (Hz)';
                spectro_axes_h.YLabel.String = 'Time (s)';
                spectro_axes_h.ZLabel.String = 'Power/Frequency (dB/Hz)';
             
                %app.UIAxes_SPECTRO.YDir = 'normal';
                %app.UIAxes_SPECTRO.YLim = [min(t_spec) max(t_spec)];
                %app.UIAxes_SPECTRO.XLim = [min(f_spec) max(f_spec)];
                %xlabel(app.UIAxes_SPECTRO,'Time(s)'); 
                %ylabel(app.UIAxes_SPECTRO,'Frequency (Hz)')
                drawnow
            end
            
            
        end

        % Selection changed function: 
        % RadioProcessingOptionsButtonGroup
        function RadioProcessingOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.RadioProcessingOptionsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,app.AutodetectpulsefrequencyButton.Text)%Auto pulse frequency detection
                app.PulsefrequencyHzEditField.Editable = 'off';
                app.PulsefrequencyHzEditField.Enable = 'off';
            else
                app.PulsefrequencyHzEditField.Editable = 'on';
                app.PulsefrequencyHzEditField.Enable = 'on';
            end
        end

        % Button pushed function: ExtractPulsesButton
        function ExtractPulsesButtonPushed(app, event)
            
            h_wait_pulse = uiprogressdlg(app.UIFigure,'Message','Filtering radio data...','Title','Pulse extraction progress');
            
            app.Lamppulsefindcomplete.Enable = 'off';
            %Pull some varaibles from the user interface for processing.
            Fs          = app.FRS_PROCESS.Value;
            pulse_dur   = app.PUL_D_PROCESS.Value;
            pulse_rep   = app.PUL_R_PROCESS.Value;
            f           = app.PulsefrequencyHzEditField.Value;
            search_range= app.UpperlimitfromcenterfrequencyHzEditField.Value;
            filtband    = app.FilterbandwidthHzEditField.Value;
            
            
            %Process the data
            %data_out = cleandata(Fs,pulse_dur,f,filtband,app.SDR.raw.data);%With original sample rate
            data_out = cleandata(app.SDR.dec.Fs,pulse_dur,f,filtband,app.SDR.dec.data);% With decimated data


            %t_g      = data_out{1};
            app.SDR.downmax = abs(data_out{2});%used to be data_abs. Maximums of the SDR data on the pulse duration dt time scale
            app.SDR.filt.IQ = data_out{3};
            
            %Plot the filtered radio results
            %t_plot = (0:1:(length(app.SDR.filt.IQ)-1))*1/Fs;%For original sample rate
            t_plot = (0:1:(length(app.SDR.filt.IQ)-1))*1/app.SDR.dec.Fs; %Using decimated data
            indselect = (t_plot>=app.StartsEditField.Value) & (t_plot<=app.EndsEditField.Value);%Select a portion within the time bounds of the edit fields
            plot(app.UIAxes_WAVEFRM,t_plot(indselect),real(app.SDR.filt.IQ(indselect)),'Color',[1 0.4 0.4])
            app.UIAxes_WAVEFRM.YLim = 1.1*[min(real(app.SDR.filt.IQ)),max(real(app.SDR.filt.IQ))];
            app.UIAxes_WAVEFRM.XLim = [-inf inf]; %Set to auto
            hold(app.UIAxes_WAVEFRM,'on');
            
             h_wait_pulse.Message  = 'Detecting pulses...';
             h_wait_pulse.Value  = 0.25;
            
            %Find pulses within signal amplitude data
            pulse_data = pulsefind(1/pulse_dur,pulse_dur,pulse_rep,app.SDR.downmax,app.PulsedetectionthresholdSlider.Value);
            %Sometimes pulses are found when we don't have flight data (after
            %landing). Eliminate them with the mask that checks time stamps.
            valid_pulse_msk_1 = (pulse_data{1}>=app.UAV.time(1)&pulse_data{1}<=app.UAV.time(end));
            %Now eliminate pulses that are outside the times that the user
            %wanted to consider
            valid_pulse_msk_2 = (pulse_data{1}>=app.StartsEditField.Value)&(pulse_data{1}<=app.EndsEditField.Value);
            
            %Now AND them
            valid_pulse_msk = valid_pulse_msk_1 & valid_pulse_msk_2;
            
            app.pulse.time  = pulse_data{1}(valid_pulse_msk);
            app.pulse.amp   = pulse_data{2}(valid_pulse_msk);
            app.pulse.pow   = pulse_data{2}(valid_pulse_msk).^2;%power
            app.pulse.num   = length(app.pulse.time);%used to be num_of_pulses
            
            app.Lamppulsefindcomplete.Enable = 'on';
            
            h_wait_pulse.Message  = 'Writing filtered audio file...';
            h_wait_pulse.Value  = 0.5;
             
            %Write the audio file
            [status, msg, msgID] = mkdir(app.OutputTextArea.Value{1});
            audiowrite([app.OutputTextArea.Value{1},app.slash_char,app.OutputsavestringEditField.Value,'-AUDIO_FILT.wav'],real(app.SDR.filt.IQ)./max(abs(real(app.SDR.filt.IQ))),app.SDR.dec.Fs,'Title',[app.OutputsavestringEditField.Value,'-filtered']);
            
            if app.pulse.num==0
              uialert(app.UIFigure,'No pulses identified!','No pulses found warning')
            else
            
            h_wait_pulse.Message  = 'Correlating pulses to vehicle data...';
            h_wait_pulse.Value  = 0.75;
             
                
            %Correlate radio data to vehicle data
            [veh_out,pulse_waypt_num] = vehstates(app.pulse.time, app.UAV,[app.waypt.tin, app.waypt.tout]);%
            
            app.UAVPULS.x   = veh_out.x;
            app.UAVPULS.y   = veh_out.y;
            app.UAVPULS.yaw = veh_out.yaw;
            app.UAVPULS.alt = veh_out.alt;
            app.UAVPULS.waypt_num = pulse_waypt_num; %At which waypoint was this pulse received?
            pulse_latlon    = xy2latlon([app.home.lat,app.home.lon],[app.UAVPULS.y,app.UAVPULS.x]);
            app.UAVPULS.lat = pulse_latlon(:,1);
            app.UAVPULS.lon = pulse_latlon(:,2);
            %pulse_cyl       = [hypot(app.UAVPULS.y,app.UAVPULS.x),wrapTo360(180/pi*atan2(app.UAVPULS.y,app.UAVPULS.x))];
            app.UAVPULS.r   = hypot(app.UAVPULS.y,app.UAVPULS.x); %Home to UAV position (radial dist) at pulse reception time.
            app.UAVPULS.th  = wrapTo360(180/pi*atan2(app.UAVPULS.y,app.UAVPULS.x)); %Home to UAV position (theta angle from N) at pulse reception time.
            
            %app.UAVPULS.latstr = num2str(app.,'%3.9f')
            %for i = 1:length(app.UAVPULS.lat)
            %    app.UAVPULS.latstr = num2str(-35.123456789,'%3.9f')
            %end
            h_wait_pulse.Message  = 'Plotting results...';
            h_wait_pulse.Value  = 0.9;
            
            %Create the table data for the pulses so that we can use
            %sorting in  the UI
            %https://www.mathworks.com/help/matlab/ref/uitable.html
            Num = (1:app.pulse.num)';
            Time = app.pulse.time';
            Pow = app.pulse.pow';
            PowdB = 10*log10(app.pulse.pow'./max(app.pulse.pow));
            Lat = latlon2cell(app,app.UAVPULS.lat,'%3.6f');
            Lon = latlon2cell(app,app.UAVPULS.lon,'%3.6f');
            R  = app.UAVPULS.r;
            TH = app.UAVPULS.th;
            pulsedatatable = table(Num,Time,Pow,PowdB,Lat,Lon,R,TH);
            %pulsedatatable = table({})
                        
            app.UITable2.Data = pulsedatatable;%[(1:app.pulse.num)',app.pulse.time',app.pulse.pow'];
            %Make all the columns all sortable
            app.UITable2.ColumnSortable = true(1,size(app.UITable2.Data,2));
            
            %Sometimes there may be waypoints included in num_of_waypoints where no
            %pulses were recorded, or if there is an error with the radio, we could
            %be missing some radio data in some of the waypoints. So from here on,
            %we only want to consider the waypoints where we have valid radio data.
            %This is the list of waypoints that should be evaluted for DOA.
            %Eliminate the NaN values, and create a unique list
            waypts_with_pulses = unique(app.UAVPULS.waypt_num(~isnan(app.UAVPULS.waypt_num)));
            app.waypt.haspulses = ismember(1:app.waypt.num,waypts_with_pulses);
            %num_of_waypts_with_pulses = length(waypts_with_pulses);
            
            %This code is use to find the maximum and average pulse power and amp at
            %each waypoint.
            waypt_max_pulse_pow = zeros(app.waypt.num,1);   %Just zeros initialization
            waypt_avg_pulse_pow = waypt_max_pulse_pow;      %Just zeros initialization
            waypt_max_pulse_amp = waypt_max_pulse_pow;      %Just zeros initialization
            waypt_avg_pulse_amp = waypt_max_pulse_pow;      %Just zeros initialization
            
            for i = 1:app.waypt.num %This finds the maximum pulse signal amplitude at each waypoint
                if app.waypt.haspulses(i)
                    app.waypt.maxpulsepow(i) =   max(app.pulse.pow(app.UAVPULS.waypt_num == i));
                    app.waypt.avgpulsepow(i) =  mean(app.pulse.pow(app.UAVPULS.waypt_num == i));
                    app.waypt.maxpulseamp(i) =   max(app.pulse.amp(app.UAVPULS.waypt_num == i));
                    app.waypt.avgpulseamp(i) =  mean(app.pulse.amp(app.UAVPULS.waypt_num == i));
                else %If a waypoint didn't have pulses found, report NaN
                    app.waypt.maxpulsepow(i) =  NaN;
                    app.waypt.avgpulsepow(i) =  NaN;
                    app.waypt.maxpulseamp(i) =  NaN;
                    app.waypt.avgpulseamp(i) =  NaN;
                end
            end
            
            
            %Plot results
            %pulse_norm = app.pulse.pow/max(app.pulse.pow);
            pulse_norm = app.pulse.amp/max(app.pulse.amp);
            pulse_dia_plot = 30*pulse_norm;
            pulse_clr_plot = interp1(linspace(0,1,10),jet(10),pulse_norm);
            
            for i =1:length(app.UAVPULS.x)
                currtag = ['PULSE-',num2str(i)];
                plot3(app.UIAxes2, app.UAVPULS.x(i),app.UAVPULS.y(i), app.UAVPULS.alt(i),'o','Markersize',pulse_dia_plot(i),'Color',pulse_clr_plot(i,:),'Tag',currtag)
            end
            
            
            app.StartAudioButton.Enable         = 'on'; %We can now play audio
            app.BearingsButton.Enable   = 'on'; %We can now do localization estimates
            end
            close(h_wait_pulse);
        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            %flt_data_path = app.FlightFileTextArea.Value{1};
            [flt_data_path,flt_data_flnm,flt_data_ext]= fileparts(app.FlightFileTextArea.Value{1});
            if ~isempty(flt_data_path) %If they selected a flt_data_path default to there
                try
                    app.UIFigure.Visible = 'off';
                    [out_data_path] = uigetdir(flt_data_path,'SELECT OUTPUT FILE DIRECTORY');
                    app.UIFigure.Visible = 'on';
                catch %If the flt path isn't valid
                    app.UIFigure.Visible = 'off';
                    [out_data_path] = uigetdir([],'SELECT OUTPUT FILE DIRECTORY');
                    app.UIFigure.Visible = 'on';
                end
            else %If they haven't yet selected a flt_data_path start at home
                app.UIFigure.Visible = 'off';
                [out_data_path] = uigetdir([],'SELECT OUTPUT FILE DIRECTORY');
                app.UIFigure.Visible = 'on';
            end
            
            if out_data_path~=0 %Zero if user hit cancel
                app.OutputTextArea.Value  = out_data_path;
            end
            
        end

        % Button pushed function: StartAudioButton
        function StartAudioButtonPushed(app, event)
            %If we haven't opened it yet, or it already played to completion then recreate the audio object
            if ~isempty(app.player) %if it is running, stop the playback
                stop(app.player)
            end
            [y, fs] = audioread([app.OutputTextArea.Value{1},app.slash_char,app.OutputsavestringEditField.Value,'-AUDIO_FILT.wav']);
            app.player = audioplayer(y,fs);
            app.player.TimerFcn=@audiofunc;%Function executing during audio playback
            app.player.TimerPeriod = 0.3;%Can't do it much faster than this because the graphing can't keep up because matlab is so slow with plot updates: https://www.mathworks.com/matlabcentral/answers/382977-how-to-update-a-app-designer-uiaxes-plot-in-real-time
            play(app.player)
            
            function audiofunc(audioplayerobject,event)
                theylims = app.UIAxes_WAVEFRM.XLim;
                %app.UIAxes_WAVEFRM.XLim = [audioplayerobject.CurrentSample/audioplayerobject.SampleRate,audioplayerobject.CurrentSample/audioplayerobject.SampleRate+200] ;
                app.playbackcursors{1}.XData = audioplayerobject.CurrentSample/audioplayerobject.SampleRate*[1;1];
                app.playbackcursors{1}.YData = theylims;
                %refreshdata(app.UIAxes_WAVEFRM)
                %drawnow limitrate
                %disp(['time = ',num2str(app.player.CurrentSample/app.player.SampleRate),'s'])
            end
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
            %Only do something if the audio object already exists.
            if ~isempty(app.player)
                if strcmp(app.player.Running,'on')
                    pause(app.player)
                elseif strcmp(app.player.Running,'off')
                    resume(app.player)
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           if ~isempty(app.player)
            stop(app.player)
            app.player = [];%make empty so we can catch again on play
           end
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            skip=false;%Flag used to skip the rest of the function if something goes wrong
            
            try
                latlim = app.h_mapcache_ax.LatitudeAxis.Limits;
                lonlim = app.h_mapcache_ax.LongitudeAxis.Limits;
            catch ME
                warndlg('First select a region and zoom to the area of interest.')
                skip = true;
            end
            
            %%First calculate the number of maps that are going to need to be
            %%downloaded, and check with the user to make sure this is okay.
            
            zoomlogic =    [app.zoom_dl_sel_0.Value,...
                app.zoom_dl_sel_1.Value,...
                app.zoom_dl_sel_2.Value,...
                app.zoom_dl_sel_3.Value,...
                app.zoom_dl_sel_4.Value,...
                app.zoom_dl_sel_5.Value,...
                app.zoom_dl_sel_6.Value,...
                app.zoom_dl_sel_7.Value,...
                app.zoom_dl_sel_8.Value,...
                app.zoom_dl_sel_9.Value,...
                app.zoom_dl_sel_10.Value,...
                app.zoom_dl_sel_11.Value,...
                app.zoom_dl_sel_12.Value,...
                app.zoom_dl_sel_13.Value,...
                app.zoom_dl_sel_14.Value,...
                app.zoom_dl_sel_15.Value,...
                app.zoom_dl_sel_16.Value];
            
            
            if ~ismember(str2num(app.SelectmappreviewzoomlevelDropDown.Value),zoomlogic.*app.zoomvals)
                errordlg('Preview map zoom level must be on of those selected for download')
                skip = true;
            end
            
            mapselectlogic = [app.Toposelectcheckbox.Value,...
                app.ImageryToposelectcheckbox.Value,...
                app.Imageryonlyselectcheckbox.Value,...
                app.Shadedselectcheckbox.Value,...
                app.Hydroselectcheckbox.Value,...
                app.TNMBlankselectcheckbox.Value];
            
            if ~skip
                for i=1:length(mapselectlogic)
                    for j = 1:length(zoomlogic)
                        if mapselectlogic(i)==true && zoomlogic(j)==true
                            %Only do the following if we want to download that map
                            %Create the necessary directories.
                            %Don't care about displaying warning message about
                            %directory already existing, bu we do want to let the
                            %user know if the folder creation fails.
                            currsavedir = [app.mapcachedir,app.slash_char,app.mapselectvals{i},app.slash_char,num2str(app.zoomvals(j))];
                            [status, msg, ~] = mkdir(currsavedir);
                            if status
                                flnmlist_mat{i} = downloadmaps(latlim,lonlim,app.zoomvals(j),app.mapselectvals{i},currsavedir);
                            else
                                errordlg([app.mapselectvals{i}, ' map cache folder creation failed: ',msg])
                            end
                        end
                    end
                end
            end
            
            %Generate preview map
            map2preview = app.mapselectvals{find(mapselectlogic==true,1,'first')};
            cachedir = [app.mapcachedir,app.slash_char,map2preview];
            %Plot cached maps on the map preview figure with lat lons
            plotmap(app.UIAxes4,mean(latlim),mean(lonlim),latlim,lonlim,str2double(app.SelectmappreviewzoomlevelDropDown.Value),cachedir,'geo')
            app.UIAxes4.YLim = latlim;
            app.UIAxes4.XLim = lonlim;
            app.UIAxes4.Color = [1 1 1];
            
        end

        % Button pushed function: OpencachingmapButton
        function OpencachingmapButtonPushed(app, event)
            
            baseURL = "https://basemap.nationalmap.gov/ArcGIS/rest/services";
            usgsURL = baseURL + "/BASEMAP/MapServer/tile/${z}/${y}/${x}";
            
            basemaps = ["USGSImageryOnly" "USGSImageryTopo" ...
                "USGSTopo" "USGSShadedReliefOnly" "USGSHydroCached"];
            
            
            displayNames = ["USGS Imagery" "USGS Topographic Imagery" ...
                "USGS Shaded Topographic Map" "USGS Shaded Relief" ...
                "USGS Hydrography"];
            maxZoomLevel = 16;
            
            attribution = 'Credit: U.S. Geological Survey';
            
            basemap = basemaps(3);
            name = lower(basemap);
            url = replace(usgsURL,"BASEMAP",basemap);
            displayName = displayNames(3);
            addCustomBasemap(name,url,'Attribution',attribution, ...
                'DisplayName',displayName,'MaxZoomLevel',maxZoomLevel,'IsDeployable',true)
            
            %h_mapcache_fig = figure;
            app.h_mapcache_ax = geoaxes(app.MapCacheTab);
            geobasemap(app.h_mapcache_ax,name)
            %app.h_mapcache_ax = gca;
            set(app.h_mapcache_ax,'ZoomLevel',1.5);
            title(app.h_mapcache_ax,['Zoom to area to cache on ',displayName]);
        end

        % Value changed function: SelectmappreviewzoomlevelDropDown
        function SelectmappreviewzoomlevelDropDownValueChanged(app, event)
            
            value = app.SelectmappreviewzoomlevelDropDown.Value;
            %Check all the zoom check boxes and see which is listed
            zoomlogic =    [app.zoom_dl_sel_0.Value,...
                app.zoom_dl_sel_1.Value,...
                app.zoom_dl_sel_2.Value,...
                app.zoom_dl_sel_3.Value,...
                app.zoom_dl_sel_4.Value,...
                app.zoom_dl_sel_5.Value,...
                app.zoom_dl_sel_6.Value,...
                app.zoom_dl_sel_7.Value,...
                app.zoom_dl_sel_8.Value,...
                app.zoom_dl_sel_9.Value,...
                app.zoom_dl_sel_10.Value,...
                app.zoom_dl_sel_11.Value,...
                app.zoom_dl_sel_12.Value,...
                app.zoom_dl_sel_13.Value,...
                app.zoom_dl_sel_14.Value,...
                app.zoom_dl_sel_15.Value,...
                app.zoom_dl_sel_16.Value];
            %zoomvals = [1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16];
            
            if ~sum(str2num(value)==app.zoomvals.*zoomlogic)
                errordlg('Select a zoom level for preview that is selected for download')
                if sum(zoomlogic)~=0 %
                    defaultzoom = app.zoomvals(find(zoomlogic==1,1,'first'));%Default back to the highest zoom level selected
                else
                    defaultzoom = 14;
                end
                app.SelectmappreviewzoomlevelDropDown.Value = num2str(defaultzoom);
            end
            
        end

        % Button pushed function: ResetResultsMapButton
        function ResetResultsMapButtonPushed(app, event)
            
%Make column vectors of all the x and y data we care about being visible 
            xdatas = [app.LOC.CM.x;...
                     app.LOC.MLE.x;...
                     app.LOC.RMR.x;...
                     app.LOC.MEST.x;...
                     app.LOC.MED.x;...
                     app.UAV.x];
            ydatas = [app.LOC.CM.y;...
                     app.LOC.MLE.y;...
                     app.LOC.RMR.y;...
                     app.LOC.MEST.y;...
                     app.LOC.MED.y;...
                     app.UAV.y];    
            
            map_x_max = max(xdatas);
            map_x_min = min(xdatas);
            map_x_dist = map_x_max-map_x_min;
            map_y_max = max(ydatas);
            map_y_min = min(ydatas);
            map_y_dist = map_y_max-map_y_min;
            if map_x_dist~=0 && map_y_dist~=0
                %major_horiz_dist = max([map_x_dist,map_y_dist]);
                if map_x_dist>map_y_dist
                    app.UIAxes2.XLim = [map_x_min, map_x_max]+0.2*[-map_x_dist,map_x_dist];
                    app.UIAxes2.YLim = [map_y_min, map_y_max]+0.2*[-map_x_dist,map_x_dist];
                else 
                    app.UIAxes2.XLim = [map_x_min, map_x_max]+0.2*[-map_y_dist,map_y_dist];
                    app.UIAxes2.YLim = [map_y_min, map_y_max]+0.2*[-map_y_dist,map_y_dist];
                end
            else
                major_horiz_dist = 10;
            end
            %major_vert_dist  = [max(app.UAV.alt)-min(app.UAV.alt)];
            %This resets the results map with an orientation and scale
            %app.UIAxes2.XLim = [map_x_min, map_x_max]+[-major_horiz_dist,major_horiz_dist];
            %app.UIAxes2.YLim = [map_y_min, map_y_max]+[-major_horiz_dist,major_horiz_dist];
            app.UIAxes2.ZLim = 1.2*[-2 max(app.UAV.alt)];
            if strcmp(app.ViewDropDown.Value,'Top')
                app.UIAxes2.View = [90 90];
            elseif strcmp(app.ViewDropDown.Value,'Isometric')
                app.UIAxes2.View = [60 30];
            end
            
%             %This resets the results map with an orientation and scale
%             major_horiz_dist = max([max(app.UAV.x)-min(app.UAV.x),max(app.UAV.y)-min(app.UAV.y)]);
%             major_vert_dist  = [max(app.UAV.alt)-min(app.UAV.alt)];
%             app.UIAxes2.XLim = [min(app.UAV.x) max(app.UAV.x)]+[-major_horiz_dist,major_horiz_dist];
%             app.UIAxes2.YLim = [min(app.UAV.y) max(app.UAV.y)]+[-major_horiz_dist,major_horiz_dist];
%             app.UIAxes2.ZLim = 1.2*[-2 max(app.UAV.alt)];
%             app.UIAxes2.View = [90 90];
            %
            %disp('breakpoint!')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %All the maps should be surfaces, so delete them all.
            delete(findobj(app.UIAxes2,'Type','Surface'))
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            mapplotlatrng_UAV = [min(app.UAV.lat),max(app.UAV.lat)];
            mapplotlonrng_UAV = [min(app.UAV.lon),max(app.UAV.lon)];
            
            usr_map_dist_to_deg = km2deg(app.MapRad.Value);
            mapplotlatrng_usr = [app.home.lat-usr_map_dist_to_deg, app.home.lat+usr_map_dist_to_deg];
            mapplotlonrng_usr = [app.home.lon-usr_map_dist_to_deg, app.home.lon+usr_map_dist_to_deg];
            
            mapplotlat_min = min([mapplotlatrng_UAV(1),mapplotlatrng_usr(1)]);
            mapplotlat_max = max([mapplotlatrng_UAV(2),mapplotlatrng_usr(2)]);
            mapplotlon_min = min([mapplotlonrng_UAV(1),mapplotlonrng_usr(1)]);
            mapplotlon_max = max([mapplotlonrng_UAV(2),mapplotlonrng_usr(2)]);
            
            mapplotlatrng = [ mapplotlat_min  mapplotlat_max];
            mapplotlonrng = [ mapplotlon_min  mapplotlon_max];
            
            %cachedir = 'mapcache';
            cachedir = [app.mapcachedir,app.slash_char,app.MapDropDown.Value];
            %Plot cached maps on the results figure.  app.UIAxes2
            %plotmap(blah,latlonhome(1),latlonhome(2),mapplotlatrng,mapplotlonrng,15,cachedir,'meters')
            str2double(app.ZoomDropDown.Value);
            [numplot,numwant]=plotmap(app.UIAxes2,app.home.lat,app.home.lon,mapplotlatrng,mapplotlonrng,str2double(app.ZoomDropDown.Value),cachedir,'meters-NXEY');
            if numplot<numwant
                warndlg(['Of the ',num2str(numwant),' requested map tiles, ',num2str(numplot),' were available in your map cache.']);
            end
            
            
            ResetResultsMapButtonPushed(app);
        end

        % Button pushed function: ChangecachingdirectoryButton
        function ChangecachingdirectoryButtonPushed(app, event)
            %Ask user for the place where the maps should be saved or pulled from
            app.UIFigure.Visible = 'off';
            selecteddir = uigetdir(app.mapcachedir);
            app.UIFigure.Visible = 'on';
            if isstr(selecteddir) %uigetdir will return a zero if the user cancels. If that is the case, we don't do anything
                app.mapcachedir = selecteddir; %update the variable and...
                %Then updated the field showing it to the user
                app.MapcachedirEditField.Value = app.mapcachedir;
            end
        end

        % Button pushed function: BearingsButton
        function BearingsButtonPushed(app, event)
            
            h_wait_bear = uiprogressdlg(app.UIFigure,'Message','Calculating bearing estimates...','Title','Bearing progress');
            
            app.Lampbearingscomplete.Enable = 'off';       
            if app.PowButton.Value == true
                strengthtype = 'power';
            else
                strengthtype = 'amplitude';
            end
            if app.LinearButton.Value == true
                scale = 'linear';
            else
                scale = 'log';
            end
            
            
            %Setup location where bearings will be plotted
            app.BearingsTab.AutoResizeChildren = 'off';
            delete(app.BearingsTab.Children)%Get rid of any old plots
            %Do DOA estimates for the entire flight
            [doaout] = doapca(app.pulse.amp,app.UAVPULS.yaw,app.UAVPULS.waypt_num,app.waypt.num,strengthtype,scale,app.BearingsTab);%All inputs required
            close(gcf)%THere is a bug in the subplot function used in the doapca function that generates a figure window. This just closes that window. 
            
            h_wait_bear.Message  = 'Plotting results...';
            h_wait_bear.Value  = 0.5;
             
            %Give each waypoint its DOA
            for i = 1:app.waypt.num
                app.waypt.DOA_degN_CW(i) = doaout{1}(i);
                app.waypt.tau(i)         = doaout{2}(i);
            end
            
            plotswitch(app,app.UIAxes2.Children,'BEAR','delete');%Clear any old bearing estimates
            
            %Bearing estimates
            if any(app.waypt.DOA_degN_CW)%Do we have any DOAs to plot? OLD CODE:num_of_waypts_with_pulses ~=0
                %Range of flight to help with scaling of lines
                flight_delta_dist = sqrt(range(app.UAV.x).^2+range(app.UAV.y).^2);
                vect_lengths =0.25*flight_delta_dist*app.waypt.maxpulsepow/max(app.waypt.maxpulsepow);
                %vect_lengths =0.25*flight_delta_dist*app.waypt.maxpulseamp/max(app.waypt.maxpulseamp);
                for i = 1:length(app.waypt.DOA_degN_CW) %We will plot each individually so they each get a graphics handle we can give a different tag to. 
                %Now do the plotting
                %plot3(app.UIAxes2, app.waypt.x,app.waypt.y,app.waypt.alt,'o','MarkerEdgeColor',[1 0 0],'MarkerFaceColor',[1 0 0]);
                %Waypoint
                plot(app.UIAxes2,app.waypt.x(i),app.waypt.y(i),'o','MarkerEdgeColor',[1 0.9 0.9],'MarkerFaceColor',[1 0.9 0.9]);
                %Vector
                curr_tag = ['BEAR-',num2str(i)];
                quiver3(app.UIAxes2,app.waypt.x(i)',app.waypt.y(i)',app.waypt.alt(i)',vect_lengths(i).*cosd(app.waypt.DOA_degN_CW(i)),vect_lengths(i).*sind(app.waypt.DOA_degN_CW(i)),zeros(size(app.waypt.DOA_degN_CW(i))),'Color',[0.5 0.5 1],'MaxHeadSize',0.6,'AutoScale','off','Tag',curr_tag); %Transposes needed because quiver wants the same orientation of the vectors.
                
                %quiver(app.UIAxes2,app.waypt.x',app.waypt.y',vect_lengths.*cosd(app.waypt.DOA_degN_CW),vect_lengths.*sind(app.waypt.DOA_degN_CW),'Color',[0.9 0.9 1])
                %disp('Breakpoint!')
                end
                
            end
            
            app.PositionButton.Enable = 'on';
            app.Lampbearingscomplete.Enable = 'on';       
            
            close(h_wait_bear)
            
        end

        % Value changed function: CMButton
        function CMButtonValueChanged(app, event)
            value = app.CMButton.Value;
            app.doalogic(strcmp(app.doatypes,'CM')) = value; %Update the logic vector
        end

        % Value changed function: MLEButton
        function MLEButtonValueChanged(app, event)
            value = app.MLEButton.Value;
            app.doalogic(strcmp(app.doatypes,'MLE')) = value; %Update the logic vector
        end

        % Value changed function: RMRButton
        function RMRButtonValueChanged(app, event)
            value = app.RMRButton.Value;
            app.doalogic(strcmp(app.doatypes,'RMR')) = value; %Update the logic vector
        end

        % Value changed function: MESTButton
        function MESTButtonValueChanged(app, event)
            value = app.MESTButton.Value;
            app.doalogic(strcmp(app.doatypes,'MEST')) = value; %Update the logic vector
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.CMButton.Value   = 1; %Update the CM button values.
            app.doalogic(strcmp(app.doatypes,'CM')) = 1; %Update the logic vector
            app.MLEButton.Value  = 1; %Update the MLE button values.
            app.doalogic(strcmp(app.doatypes,'MLE')) = 1; %Update the logic vector
            app.RMRButton.Value  = 1; %Update the RMR button values.
            app.doalogic(strcmp(app.doatypes,'RMR')) = 1; %Update the logic vector
            app.MESTButton.Value = 1; %Update the MEST button values.
            app.doalogic(strcmp(app.doatypes,'MEST')) = 1; %Update the logic vector
        end

        % Button pushed function: ClearresultsButton
        function ClearresultsButtonPushed(app, event)
            
            answer = uiconfirm(app.UIFigure,{'Are you sure you want to clear the data and graphs?','This will only clear the application data. Data files will not be effected'},'Clear confirmation','Options',{'Yes','No'},'DefaultOption','No');
            if strcmp(answer,'No')
                %Do nothing
            elseif strcmp(answer,'Yes')
                %Clear all the data
                initialize_app_flight_props(app);
                
                %Reset lamps and button avaialability
                app.Lampvehicledataready.Enable     = 'off';
                app.Lamppreprocessingcomplete.Enable= 'off';
                app.Lamppulsefindcomplete.Enable    = 'off';
                app.Lampbearingscomplete.Enable     = 'off';
                app.Lamppositioncomplete.Enable     = 'off';
                app.TagcheckButton.Enable           = 'off';
                app.ExtractPulsesButton.Enable      = 'off';
                app.BearingsButton.Enable           = 'off';
                app.PositionButton.Enable           = 'off';
                
                
                %Find all the axes
                all_ax_list = findall(app.UIFigure,'Type','axes');
                for i = 1:length(all_ax_list)
                    all_ax_list(i).XLim = [-inf inf]; %Set limits back to auto
                    all_ax_list(i).YLim = [-inf inf]; %Set limits back to auto
                    all_ax_list(i).ZLim = [-inf inf]; %Set limits back to auto
                    delete(all_ax_list(i).Children)
                end
                %Clear the tables.
                app.UITable.Data  = [];
                app.UITable2.Data = [];
                app.UIWayptSelTable.Data = [];
                
            end
        end

        % Button pushed function: PositionButton
        function PositionButtonPushed(app, event)
            app.Lamppositioncomplete.Enable = 'off';      
                LOG = table2array(app.UIWayptSelTable.Data(:,2));
                %Create the localization inputs that downselect the
                %waypoint the users ask for to be selected. 
                y_loc_in      = app.waypt.y(LOG);
                x_loc_in      = app.waypt.x(LOG);
                doa_loc_in    = app.waypt.DOA_degN_CW(LOG);
                maxpow_loc_in = app.waypt.maxpulsepow(LOG);
                tau_loc_in    = app.waypt.tau(LOG);
                
            if sum(~isnan(doa_loc_in))<=1%Only proceed if we have more than one valid bearing. Can do position estimates with only one bearing
                warndlg(['More than one bearing estimate is needed for localization estimates. Of those waypoints selected, only ',num2str(sum(isnan(doa_loc_in))),' have valid bearings. Please change your selection.']);
            else            
                
                if app.CMButton.Value
                    [pos_yNxE_CM,intermap_yNxE] = localize_cm(y_loc_in,x_loc_in,doa_loc_in,maxpow_loc_in,tau_loc_in,0.35,30);
                else
                    pos_yNxE_CM = [NaN; NaN]; intermap_yNxE = [NaN, NaN, NaN, NaN];
                end
                if app.MLEButton.Value
                    [pos_yNxE_MLE]              = localize(y_loc_in,x_loc_in,doa_loc_in,'MLE');
                else
                    pos_yNxE_MLE = [NaN; NaN];
                end
                if app.RMRButton.Value
                    [pos_yNxE_RMR]              = localize(y_loc_in,x_loc_in,doa_loc_in,'RMR');
                else
                    pos_yNxE_RMR = [NaN; NaN];
                end
                if app.MESTButton.Value
                    [pos_yNxE_MEST]             = localize(y_loc_in,x_loc_in,doa_loc_in,'MEST');
                else
                    pos_yNxE_MEST = [NaN; NaN];
                end
                %Calculate the median regardless
                if app.MedianButton.Value
                    [pos_yNxE_MEDIAN]              = nanmedian([pos_yNxE_CM,pos_yNxE_MLE,pos_yNxE_RMR,pos_yNxE_MEST],2);%Median of all. Not mean in case there is an outlier.
                else
                    pos_yNxE_MEDIAN = [NaN; NaN];
                end
                
                if all(isnan([pos_yNxE_CM,pos_yNxE_MLE,pos_yNxE_RMR,pos_yNxE_MEST,pos_yNxE_MEDIAN]))
                   uialert(app.UIFigure,'No positions estimates were made. This may be because the selected method(s) could not converge based on the bearing estimates of the selected waypoints. Try including other waypoints or doing another flight.','No position estimates') 
                end
                
                latlonhome = [app.home.lat,app.home.lon];
                %Localization results in lat/lons
                latlon_CM     = xy2latlon(latlonhome,pos_yNxE_CM');
                latlon_MLE    = xy2latlon(latlonhome,pos_yNxE_MLE');
                latlon_RMR    = xy2latlon(latlonhome,pos_yNxE_RMR');
                latlon_MEST   = xy2latlon(latlonhome,pos_yNxE_MEST');
                latlon_MED    = xy2latlon(latlonhome,pos_yNxE_MEDIAN');
                %Localization results in cylindrical coordinates (radial distance
                %and bearing)
                cyl_CM     = [norm(pos_yNxE_CM)      ,wrapTo360(180/pi*atan2(pos_yNxE_CM(1),pos_yNxE_CM(2)))];
                cyl_MLE    = [norm(pos_yNxE_MLE)     ,wrapTo360(180/pi*atan2(pos_yNxE_MLE(1),pos_yNxE_MLE(2)))];
                cyl_RMR    = [norm(pos_yNxE_RMR)     ,wrapTo360(180/pi*atan2(pos_yNxE_RMR(1),pos_yNxE_RMR(2)))];
                cyl_MEST   = [norm(pos_yNxE_MEST)    ,wrapTo360(180/pi*atan2(pos_yNxE_MEST(1),pos_yNxE_MEST(2)))];
                cyl_MED   = [norm(pos_yNxE_MEDIAN),wrapTo360(180/pi*atan2(pos_yNxE_MEDIAN(1),pos_yNxE_MEDIAN(2)))];
                %Flip the x and y's to get back to X-North Y-East frame.
                pos_xNyE_CM   = flip(pos_yNxE_CM); 
                    intermap_xNyE = intermap_yNxE(:,[2,1,3:end]);
                pos_xNyE_MLE  = flip(pos_yNxE_MLE);
                pos_xNyE_RMR  = flip(pos_yNxE_RMR);
                pos_xNyE_MEST = flip(pos_yNxE_MEST);
                pos_xNyE_MED = flip(pos_yNxE_MEDIAN);
                
                
                %Package variables
                app.LOC.CM.x    = pos_xNyE_CM(1);
                app.LOC.CM.y    = pos_xNyE_CM(2);
                app.LOC.CM.lat  = latlon_CM(1);
                app.LOC.CM.lon  = latlon_CM(2);
                app.LOC.CM.r    = cyl_CM(1);
                app.LOC.CM.th   = cyl_CM(2);
                
                app.LOC.MLE.x    = pos_xNyE_MLE(1);
                app.LOC.MLE.y    = pos_xNyE_MLE(2);
                app.LOC.MLE.lat  = latlon_MLE(1);
                app.LOC.MLE.lon  = latlon_MLE(2);
                app.LOC.MLE.r    = cyl_MLE(1);
                app.LOC.MLE.th   = cyl_MLE(2);
                
                app.LOC.RMR.x    = pos_xNyE_RMR(1);
                app.LOC.RMR.y    = pos_xNyE_RMR(2);
                app.LOC.RMR.lat  = latlon_RMR(1);
                app.LOC.RMR.lon  = latlon_RMR(2);
                app.LOC.RMR.r    = cyl_RMR(1);
                app.LOC.RMR.th   = cyl_RMR(2);
                
                app.LOC.MEST.x    = pos_xNyE_MEST(1);
                app.LOC.MEST.y    = pos_xNyE_MEST(2);
                app.LOC.MEST.lat  = latlon_MEST(1);
                app.LOC.MEST.lon  = latlon_MEST(2);
                app.LOC.MEST.r    = cyl_MEST(1);
                app.LOC.MEST.th   = cyl_MEST(2);
                
                app.LOC.MED.x    = pos_xNyE_MED(1);
                app.LOC.MED.y    = pos_xNyE_MED(2);
                app.LOC.MED.lat  = latlon_MED(1);
                app.LOC.MED.lon  = latlon_MED(2);
                app.LOC.MED.r    = cyl_MED(1);
                app.LOC.MED.th   = cyl_MED(2);
                
                plotswitch(app,app.UIAxes2.Children,'LOC','delete');%Clear any old Location estimates
                
                plot(app.UIAxes2,app.LOC.CM.x  ,app.LOC.CM.y  ,'bs','Markersize',10,'Tag','LOC-CM');
                plot(app.UIAxes2,app.LOC.MLE.x ,app.LOC.MLE.y ,'b+','Markersize',10,'Tag','LOC-MLE');
                plot(app.UIAxes2,app.LOC.RMR.x ,app.LOC.RMR.y ,'bo','Markersize',10,'Tag','LOC-RMR');
                plot(app.UIAxes2,app.LOC.MEST.x,app.LOC.MEST.y,'b*','Markersize',10,'Tag','LOC-MEST');
                plot(app.UIAxes2,app.LOC.MED.x ,app.LOC.MED.y ,'bv','Markersize',10,'Tag','LOC-MED');
                
                app.LatEditField.ValueDisplayFormat = '%11.6f';
                app.LonEditField.ValueDisplayFormat = '%11.6f';
                app.XNEditField.ValueDisplayFormat  = '%11.1f';
                app.YEEditField.ValueDisplayFormat  = '%11.1f';
                app.REditField.ValueDisplayFormat   = '%11.1f';
                app.ThetafromTrueNEditField.ValueDisplayFormat = '%11.1f';
                
                if ~isnan(nanmedian([app.LOC.CM.x,app.LOC.MLE.x,app.LOC.RMR.x,app.LOC.MEST.x]))
                    app.LatEditField.Value   = nanmedian([app.LOC.CM.lat,app.LOC.MLE.lat,app.LOC.RMR.lat,app.LOC.MEST.lat]);
                    app.LonEditField.Value   = nanmedian([app.LOC.CM.lon,app.LOC.MLE.lon,app.LOC.RMR.lon,app.LOC.MEST.lon]);
                    app.XNEditField.Value    = nanmedian([app.LOC.CM.x,app.LOC.MLE.x,app.LOC.RMR.x,app.LOC.MEST.x]);
                    app.YEEditField.Value    = nanmedian([app.LOC.CM.y,app.LOC.MLE.y,app.LOC.RMR.y,app.LOC.MEST.y]);
                    app.REditField.Value     = nanmedian([app.LOC.CM.r,app.LOC.MLE.r,app.LOC.RMR.r,app.LOC.MEST.r]);
                    app.ThetafromTrueNEditField.Value = nanmedian([app.LOC.CM.th,app.LOC.MLE.th,app.LOC.RMR.th,app.LOC.MEST.th]);
                else
                    app.LatEditField.Value   = 0;
                    app.LonEditField.Value   = 0;
                    app.XNEditField.Value    = 0;
                    app.YEEditField.Value    = 0;
                    app.REditField.Value     = 0;
                    app.ThetafromTrueNEditField.Value = 0;
                end
                ResetResultsMapButtonPushed(app)
                
%                disp("breakpoint")
%                 plot(pos_xNyE_MLE(1),pos_xNyE_MLE(2),'b+','Markersize',10)
%                 plot(pos_xNyE_RMR(1),pos_xNyE_RMR(2),'bo','Markersize',10)
%                 plot(pos_xNyE_MEST(1),pos_xNyE_MEST(2),'b*','Markersize',10)
                
            end
            %Turn on the indicator
            app.Lamppositioncomplete.Enable = 'on';       
            
            
        end

        % Cell edit callback: UIWayptSelTable
        function UIWayptSelTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            plotswitch(app,app.UIAxes2.Children,'LOC-CM','delete');%Clear any old position estimates
            plotswitch(app,app.UIAxes2.Children,'LOC-MLE','delete');%Clear any old position estimates
            plotswitch(app,app.UIAxes2.Children,'LOC-RMR','delete');%Clear any old position estimates
            plotswitch(app,app.UIAxes2.Children,'LOC-MEST','delete');%Clear any old position estimates
            plotswitch(app,app.UIAxes2.Children,'LOC-MED','delete');%Clear any old position estimates
            
            if ~isempty(app.waypt.DOA_degN_CW)
            PositionButtonPushed(app,event)
            %Now turn on or off the bearing plot
            tag = ['BEAR-',num2str(indices(1))];
            if  newData==1
                pltcntrl = 'on';
            else
                pltcntrl = 'off';
            end
            plotswitch(app,app.UIAxes2.Children,tag,pltcntrl);%Turn the bearing on or off. 
            end
            %disp('BREAKPOINT!')
            
        end

        % Button pushed function: PlayRawAudioButton
        function PlayRawAudioButtonPushed(app, event)
        file_path = [app.OutputTextArea.Value{1},app.slash_char,app.OutputsavestringEditField.Value,'-AUDIO_RAW.wav'];
        openfile(app,file_path);
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
        file_path = [app.OutputTextArea.Value{1},app.slash_char,app.OutputsavestringEditField.Value,'-AUDIO_FILT.wav'];
        openfile(app,file_path); 
        end

        % Button pushed function: UAV_FTP_Pop_Dir
        function UAV_FTP_Pop_DirPushed(app, event)
        %This callback populates the tree with the first level of folders
        %and files in the FTP root directory. It also defines a function
        %'expandselecteddir' so that when a folder is selected, its
        %contents are set as children nodes and the folder is expanded in
        %the tree. Each tree node is given 'NodeData' that is equal to its
        %path on the FTP server. This way, when we go to download a
        %selected node in another callback funciton, we know where it is on
        %the FTP. Each node is also given 'UserData' equal to the file
        %size. This is used later to check with the user before starting a 
        %download,because files might be huge. 
        
            delete(app.Tree.Children)
            app.Tree.SelectionChangedFcn = @expandselecteddir;
                    
            allcont = sortstruct(app,dir(app.ftpobj));
                        
            for i = 1:length(allcont)
                curr_path = allcont(i).name;
                if allcont(i).isdir
                    fullfile(app.iconsdir,'foldericon.png');
                    uitreenode(app.Tree,'Text',allcont(i).name,'Icon',fullfile(app.iconsdir,'foldericon.png'),'NodeData',curr_path,'Tag','folder','UserData',allcont(i).bytes);
                else
                    uitreenode(app.Tree,'Text',allcont(i).name,'NodeData',curr_path,'Tag','file','UserData',allcont(i).bytes);
                end
            end
        function [] = expandselecteddir(src,event)
            %dir(ftpobj,event.SelectedNodes.Text)
            if isempty(event.SelectedNodes.Children) && strcmp(event.SelectedNodes.Tag,'folder') %Only expand if the item is a folder and doesn't have children - it would if we already expanded it and created children nodes
                allcont_curr = sortstruct(app,dir(app.ftpobj,event.SelectedNodes.NodeData));
                if ~isempty(allcont_curr)
                    for i = 1:length(allcont_curr)
                        %curr_path = [event.SelectedNodes.NodeData,app.slash_char,allcont_curr(i).name];
                        curr_path = [event.SelectedNodes.NodeData,'/',allcont_curr(i).name];%FTP Appears to only use the forward slash
                        if allcont_curr(i).isdir
                            uitreenode(event.SelectedNodes,'Text',allcont_curr(i).name,'Icon',fullfile(app.iconsdir,'foldericon.png'),'NodeData',curr_path,'Tag','folder','UserData',allcont_curr(i).bytes);
                        else
                            uitreenode(event.SelectedNodes,'Text',allcont_curr(i).name,'NodeData',curr_path,'Tag','file','UserData',allcont_curr(i).bytes);
                        end
                    end
                else
                    uitreenode(event.SelectedNodes,'Text','EMPTY','NodeData',curr_path,'Tag','Empty');
                end
                expand(event.SelectedNodes);%Expand the selected
            end
        end
                
        end

        % Button pushed function: DownloadDataButton
        function DownloadDataButtonPushed(app, event)
             %disp('breakpoint')    
             if ~isempty(app.Tree.SelectedNodes) %Only do this if the user selected a node
             %landingpath = pwd;
             app.UIFigure.Visible = 'off';
             local_backup_dir = uigetdir([],'SELECT LOCATION FOR LOCAL DATA BACKUP');
             app.UIFigure.Visible = 'on';
             app.LocalfolderEditField.Value = local_backup_dir;%Set field with the selected location
             
             h = msgbox('Checking download size...','Download Size','help');
             %First check the children file sizes and make sure the user
             %want to proceed. 
             child_list = allchild(app.Tree.SelectedNodes);
             totalsize = app.Tree.SelectedNodes.UserData;%Start with this node's size then add it's children
             for i = 1:length(child_list)
                totalsize = totalsize+child_list(i).UserData;
             end
             close(h)
             
             message = {['The files selected are ',num2str(totalsize/1e6),' Mb. This number may be wrong if you have not fully expanded the folders to the lowest level in the directory you are trying to download.'],'','Do you want to proceed with the download?'};
             answer = questdlg(message,'Download confirmation','No','Yes','Yes');
             
             %curr_dir = cd(app.ftpobj);
             if strcmp(answer,'Yes')
                 [filepath,name,ext] = fileparts(app.Tree.SelectedNodes.NodeData); %pull apart the selected path and filename
                %cd(app.ftpobj,[app.slash_char,app.Tree.SelectedNodes.Parent.NodeData]);%Change to the parent directory so we don't download the entire path structure. 
                cd(app.ftpobj,[app.connsettings.ftp.flightdir,'/',filepath]);%Change to the parent directory so we don't download the entire path structure. 
                h = msgbox({'Downloading data....','This will close automatically when download is complete.'},'Download Status','help');
                %mget(app.ftpobj,app.Tree.SelectedNodes.Text,local_backup_dir);
                
                %Now see if the local dir has the same file. If so, ask the
                %user to rename
                filesinlocalpath = dir(local_backup_dir);
                foundfile = false;
                for i = 1:length(filesinlocalpath)
                    foundfile = foundfile | strcmp(filesinlocalpath(i).name,[name,ext]); %will go true if one of them is found. 
                end
                if foundfile
                    prompt = ['A file in that directory already has the name of the file to are attempting to download. Do you want to overwrite that file? THIS WILL PERMANANTELY DELETE THE EXISTING FILE!'];
                    dlgtitle = 'File already exists';
                    answer = questdlg(prompt,dlgtitle,'Yes','No','No');
                    if strcmp(answer,'Yes')
                        thing2delete = [local_backup_dir,app.slash_char,name,ext];
                        prevrecstate = recycle('on');%Set delete functionality to move files to recycle bin rather than permanent deletion
                        if isfolder(thing2delete)
                            rmdir(thing2delete,'s') %Delete the directory
                        else
                            delete(thing2delete);%Deleted the file to make way for the new one
                        end
                        recycle(prevrecstate)%Set the recycle state back to what it was
                        mget(app.ftpobj,[name,ext],local_backup_dir);%Download the new one. 
                        close(h)
                        uialert(app.UIFigure,'Download Complete','Download Status','Icon','success')
                    else
                        close(h)
                    end
                else
                    mget(app.ftpobj,[name,ext],local_backup_dir);
                    close(h)
                    uialert(app.UIFigure,'Download Complete','Download Status','Icon','success')
                end
                
             end
                cd(app.ftpobj,app.connsettings.ftp.flightdir)
                app.LocalfolderEditField.Value = [local_backup_dir,app.slash_char,name,ext];%Set the field to the path so we can use it later as the starting point for the search of the files
             else
                 uialert(app.UIFigure,'Select a folder or file to download.','Select something please','icon','warn')
             end
             
        end

        % Button pushed function: SendsettingsButton
        function SendsettingsButtonPushed(app, event)
             INSTRUCT.TS = datestr(now);
            
             INSTRUCT.SDRSYS.FVEH  = app.FVEH_INPUT.Value;
             INSTRUCT.SDRSYS.RFG   = app.RFG_INPUT.Value;
             INSTRUCT.SDRSYS.IFG   = app.IFG_INPUT.Value;
             INSTRUCT.SDRSYS.BBG   = app.BBG_INPUT.Value;
             
             INSTRUCT.TAG(1).FT    = app.FT_INPUT.Value;
             INSTRUCT.TAG(1).FRS   = app.FRS_INPUT.Value;
             INSTRUCT.TAG(1).PUL_D = app.PUL_D_INPUT.Value;
             INSTRUCT.TAG(1).PUL_R = app.PUL_R_INPUT.Value;
            
            % INSTRUCT.TAG(2).FT = 149.356;
            % INSTRUCT.TAG(2).FRS = 52000;
            % INSTRUCT.TAG(2).PUL_D = 0.05;
            % INSTRUCT.TAG(2).PUL_R = 1.0;
            %
            INSTRUCT.NOTES = app.NOTES_INPUT.Value;
            
             %If not already connected to the FTP, start the connection. 
            if ~isempty(app.ftpobj)
                %Write file in current dir
                configwrite(app.connsettings.ftp.configflnm ,INSTRUCT,'overwrite');
                %This was just used for testing the function
                %configwrite('/Users/mws22/Google Drive/DASL Drive/RESEARCH_AREAS/UAV/Test Data Processing/Shafer_processing_software_development/UAV-RT Git/UAV-RT/POST_PROCESSING/curr_config.uavrt',INSTRUCT,'check');
                %curr_ftp_dir = app.ftpobj.dir;%grab current ftp dir to change back to it afte the upload
                %cd(app.ftpobj,app.Tree.SelectedNodes.NodeData);%Change ftp dir to node selected in tree
                cd(app.ftpobj,app.connsettings.ftp.flightdir);%Change ftp dir to node selected in tree
                mput(app.ftpobj,app.connsettings.ftp.configflnm )
                cd(app.ftpobj,app.connsettings.ftp.flightdir);%Reset the directory
                UAV_FTP_Pop_DirPushed(app);
            end


        end

        % Button pushed function: GetsettingsButton
        function GetsettingsButtonPushed(app, event)
            %If not already connected to the FTP, start the connection.
            if ~isempty(app.ftpobj)
                configfilename = app.connsettings.ftp.configflnm ;
                %Download the current config to current directory
                mget(app.ftpobj,configfilename)
                %Read the config file we just downloaded
                OUTSTRUCT = configread(configfilename);
                %Update the data fields
                app.FVEH_INPUT.Value  = str2double(OUTSTRUCT.SDRSYS.FVEH);
                app.RFG_INPUT.Value   = str2double(OUTSTRUCT.SDRSYS.RFG);
                app.IFG_INPUT.Value   = str2double(OUTSTRUCT.SDRSYS.IFG);
                app.BBG_INPUT.Value   = str2double(OUTSTRUCT.SDRSYS.BBG);
                app.FT_INPUT.Value    = str2double(OUTSTRUCT.TAG(1).FT);
                app.FRS_INPUT.Value   = str2double(OUTSTRUCT.TAG(1).FRS);
                app.PUL_D_INPUT.Value = str2double(OUTSTRUCT.TAG(1).PUL_D);
                app.PUL_R_INPUT.Value = str2double(OUTSTRUCT.TAG(1).PUL_R);
                app.NOTES_INPUT.Value = OUTSTRUCT.NOTES;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            %% First confirm IP addresses
            msg = {['Your current IP address is: ',app.connsettings.pc.ip];['UAV target IP address is: ',app.connsettings.uav.ip];'If you think these need to be updated, hit Cancel and check you connection settings. Otherwise click Continue.'};
            title = 'IP Confirm';
            selection = uiconfirm(app.UIFigure,msg,title,...
           'Options',{'Continue','Cancel'},...
           'DefaultOption',1,'CancelOption',2);    
           if strcmp(selection,'Continue')
            
            %% Check to see if UAV is online
            h_ipcheck = msgbox(['Checking if UAV is online at IP ',app.connsettings.uav.ip],'Network checkup','help');
            [lossprcnt,cmdout] = isipup(app.connsettings.uav.ip,4,0.5,false);
            close(h_ipcheck);
            if lossprcnt ~= 0
                answer = '';
                f_ping_report = [];
                %This brings up the qustion dialog again if they ask to see
                %the pin report
                while ~strcmp(answer,'Yes') && ~strcmp(answer,'No')
                    newmsg = {['Of the 4 pings to the UAV, ',num2str(lossprcnt),'% were lost.'];'Do you still want to try to connect?';'';'(Use "Connection Settings" to update the vehicle IP if necessary.)'};
                    answer = questdlg(newmsg,'Proceed with connection?','Yes','No','View ping report','No');
                    if strcmp(answer,'View ping report')
                        f_ping_report = msgbox(cmdout,'Ping terminal output');
                        waitfor(f_ping_report)
                    end
                end
            else
                answer = 'Yes';
            end
            %Only confirm if the use said so or if the connection was
            %perfect
            if strcmp(answer,'Yes')
                %First try the connection to the FTP server
                h = msgbox({'Attempting FTP Connection....','This will close automatically when connection is established.'},'FTP Connecting','help');
                try
                    %                app.ftpobj = ftp('ftp.ngdc.noaa.gov');%%TEST CONNECTIONEstablish the connections
                    app.ftpobj = ftp(app.connsettings.ftp.addr,app.connsettings.ftp.user,app.connsettings.ftp.pswd);%%Establish the connections
                    app.connsettings.ftp.origdir  =  cd(app.ftpobj); %UAV FTP initial connection directory.
                    app.connsettings.ftp.flightdir  = [app.connsettings.ftp.origdir,'/UAV-RT/VEHICLE/UAV-RT'];
                    cd(app.ftpobj,app.connsettings.ftp.flightdir); %Change to the flight data directory
                    %Now setup some buttons
                    app.GetsettingsButton.Enable  = 'on'; %Allow for checking settings
                    app.SendsettingsButton.Enable = 'on'; %Allow for sending settings
                    app.UAV_FTP_Pop_Dir.Enable    = 'on'; %Allow for directory expansion
                    app.DownloadDataButton.Enable = 'on'; %Allow for download data
                    app.DisconnectButton.Enable   = 'on'; %Allow for disconnect
                    app.FTPLamp.Color             = [0, 1, 0];%Update connection display
                    UAV_FTP_Pop_DirPushed(app);%Expand the tree
                    close(h)
                catch ME
                    close(h)
                    warnh = warndlg({['FTP Error: ',ME.identifier],...
                        ['Connection failed because: ',ME.message],...
                        ME.Correction});
                    waitfor(warnh);
                end
                
                %% Now attempt the UDP Connection
                h = msgbox({'Attempting UDP Connection....','This will close automatically when connection is established.'},'UDP Connecting','help');
                try
                    %app.udpobj    = udp(app.connsettings.uav.ip,...
                    %                 app.connsettings.uav.port_in,...
                    %                 'LocalPort', app.connsettings.pc.port_out);   % used to define the ports, where the IP address is the companion computer address
                    %app.udpobj = udp('0.0.0.0',60250,'LocalPort',4012);
                    %app.udpobj = udp('134.114.64.117',9090,'LocalPort',9091);
                    app.udpobj = udp(app.connsettings.uav.ip,app.connsettings.uav.port,'LocalPort',app.connsettings.pc.port);
                    %Using bytes function mode
                    %https://www.mathworks.com/help/instrument/writing-and-reading-data-2.html
                    %app.udpobj.BytesAvailableFcnCount = 12;
                    %app.udpobj.BytesAvailableFcnMode = 'byte';
                    app.udpobj.BytesAvailableFcnMode = 'terminator';
                    %app.udpobj.BytesAvailableFcn = {@printudpbytes,app.udpobj};%https://www.mathworks.com/help/matlab/matlab_external/events-and-callbacks.html#f75633
                    app.udpobj.BytesAvailableFcn = @parseudpbytes;%https://www.mathworks.com/help/matlab/matlab_external/events-and-callbacks.html#f75633
                          
                    
                    fopen(app.udpobj);  % used to start UDP connection
                    app.connsettings.upd.flg = 'IP';%Set the transmission flags. Always set the flag first.
                    fwrite(app.udpobj,['//IP:',getmyip(app),'\n'])
                    udpconnected(app); %Set some interface setting to show connection is active
                    %disp('Moving on....')
                    close(h)
                    %addlistener(app.udpobj,'Status','Postset',@(src,event)changeudplamp(app,app.udpobj));
                catch ME
                    close(h)
                    warnh = warndlg({['UDP Error: ',ME.identifier],...
                        ['Connection failed because: ',ME.message],...
                        ME.Correction});
                    waitfor(warnh);
                    
                end
                
            end
            
           end
           %disp('Breakpoint!')
           %fscanf(u)  % used to read the input on the incoming port
           %fclose(u);  % closes UDP connection
           
           
           %             function [] = printudpbytes(obj,event,u_in)
           %             %For printing the received bytes
           %                 resultstring = fscanf(u_in);
           %                 %disp(resultstring);
           %                 %Add the additional text
           %                 app.TextArea_VEHTRM.Value = [app.TextArea_VEHTRM.Value; {resultstring}];
           %             end
           
            function [] = parseudpbytes(event,obj)
                %app.udpobj.bytesavailable
                datain = fscanf(app.udpobj);
                %app.udpobj.bytesavailable
                %disp('Just checked bytes...')
                %regexp(datain(end),'\n')
                
                if regexp(datain(end),'\n')~=0% A line feed is at the end
                    datain = datain(1:end-1);
                end
                
                %app.TextArea_RAWCOMM.Value = [{datain};app.TextArea_RAWCOMM.Value];
                
                [~, i_e_INFO] = regexp(datain,'//INFO:'); %Don't need the start index( should always be 1
                [~, i_e_CMD]  = regexp(datain,'//CMD:');
                [~, i_e_TRM]  = regexp(datain,'//TRM:');
                [~, i_e_IP]   = regexp(datain,'//IP:');
                [~, i_e_HB]   = regexp(datain,'//HB:');
                [~, i_e_STATUS]   = regexp(datain,'//STATUS:');
                
                %%CHECK FOR TERMINAL MESSAGES TO PRINT
                if ~isempty(i_e_TRM)%Print terminal messages
                    %Print to terminal text area at the top
                    app.TextArea_VEHTRM.Value = [{datain(i_e_TRM+1:end)};app.TextArea_VEHTRM.Value];
                    %%CHECK FOR INFO MESSAGES TO PRINT
                elseif ~isempty(i_e_INFO)%Response from the host to one of our transmissions
                    %Now go through and see if the info matches the most recent
                    %transmission flag
                    %But only do this if we have a flag set. If we don't,
                    %the incomming data is just terminal data.
                    if ~isempty(app.connsettings.udp.flg)
                        if strcmp(datain(i_e_INFO+1:end),app.connsettings.udp.flg) && strcmp(app.connsettings.udp.flg,'IP')
                            %In this case we recently sent our IP address and the
                            %host send back IP as a confirmation that it was
                            %received.
                            udpconnected(app)%%Indicate that udp is connected
                            app.connsettings.udp.flg = '';%Clear the udp flag
                        elseif strcmp(datain(i_e_INFO+1:end),app.connsettings.udp.flg) && strcmp(app.connsettings.udp.flg,'start')
                            %In this case we recently sent a start command and the
                            %host send back 'start' as a confirmation that it was
                            %received.
                            %Update the button options
                            app.StartRadioButton.Enable = 'off';
                            app.StopRadioButton.Enable  = 'on';
                            
                        elseif strcmp(datain(i_e_INFO+1:end),app.connsettings.udp.flg) && strcmp(app.connsettings.udp.flg,'stop')
                            %In this case we recently sent a stop command and the
                            %host send back 'stop' as a confirmation that it was
                            %received.
                            %Update the button options
                            app.StartRadioButton.Enable = 'on';
                            app.StopRadioButton.Enable  = 'off';
                        else
                            %In this case we did not send our IP address but the
                            %host send back IP as a confirmation that it was
                            %received.
                            uialert(app.UIFigure,{['UDP Received a,',datain(i_e_INFO+1:end), ' confirmation but the most recent tranmission was ',app.connsettings.udp.flg];'Try disconnecting and then reconnecting'},'UDP Receipt Anomaly')
                            app.connsettings.udp.flg = '';%Clear the udp flag
                        end
                    end
                    %%CHECK FOR COMMAND MESSAGES
                elseif ~isempty(i_e_CMD)%
                    %Don't do anything because we don't expect the remote host
                    %to be sending commands. This code is just here in case we
                    %want to add functionality in the future.
                    %%CHECK FOR IP CONFIRMATION MESSAGES
                elseif ~isempty(i_e_IP)%Print terminal messages
                    %Don't do anything because we don't expect the remote host
                    %to be sending IP addresses. This code is just here in case we
                    %want to add functionality in the future.
                elseif ~isempty(i_e_HB)%Heartbeat received
                    %Update the last heart beat time. 
                    app.connsettings.udp.last_HB = 86400*now;%in seconds
                elseif ~isempty(i_e_STATUS)%Heartbeat received
                    %Update the last heart beat time. 
                    app.connsettings.udp.status = datain(i_e_STATUS+1:end);
                else %something happened and the data didn't have the proper message indicator
                    app.TextArea_VEHTRM.Value = [{datain};app.TextArea_VEHTRM.Value];
                end
                
            end

            
        end

        % Button pushed function: StartRadioButton
        function StartRadioButtonPushed(app, event)
            if ~isempty(app.udpobj) && strcmp(app.udpobj.Status,'open')
                app.connsettings.udp.flg = 'start';
                fwrite(app.udpobj,'//CMD:start\n')  % writes 'start' to the port which initializes GNU radio
                %disp('AA')
                app.StartRadioButton.Enable = 'off';
                %disp('BB')
                app.StopRadioButton.Enable  = 'on';
                %disp('CC')                
            else
                uialert(app.UIFigure,'Connect to vehicle first','Not connected')
            end
           
        end

        % Button pushed function: StopRadioButton
        function StopRadioButtonPushed(app, event)
            if ~isempty(app.udpobj) && strcmp(app.udpobj.Status,'open')
                app.connsettings.udp.flg = 'stop';
                fwrite(app.udpobj,'//CMD:stop\n')
                app.StartRadioButton.Enable = 'on';
                app.StopRadioButton.Enable = 'off';
            else
                uialert(app.UIFigure,'Connect to vehicle first','Not connected')
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if isempty(app.ftpobj) && ~((~isempty(app.udpobj) && strcmp(app.udpobj.Status,'open')))
                uialert(app.UIFigure,'No connection exists','Not connected')
            else
                if (~isempty(app.udpobj) && strcmp(app.udpobj.Status,'open'))
                    fwrite(app.udpobj,['//CMD:disconnect','\n']); %Tell the server we want to disconnect
                    fclose(app.udpobj);  % closes and cleans up UDP connection and object
                    delete(app.udpobj)
                    clear app.udpobj
                    app.udpobj = [];
                    app.connsettings.udp.flg = '';%Clear the flag. 
                    udpdisconnected(app);%Set a bunch of interface settings to indicate disconnect
                    
                else
                    uialert(app.UIFigure,'UDP already disconnected','No existing UDP connection')
                end
                if ~isempty(app.ftpobj)
                    %If ftp object was created and connection wasn't closed
                    close(app.ftpobj)%Properly close the connection
                    app.ftpobj = [];
                    delete(app.Tree.Children)%Clear the tree contents
                    app.FTPLamp.Color = [1, 0, 0];%Update connection display
                else
                    uialert(app.UIFigure,'FTP already disconnected','No existing FTP connection')
                end
                if isempty(app.ftpobj) && ~((~isempty(app.udpobj) && strcmp(app.udpobj.Status,'open')))
                    %If we close all the connections, diable the disconnect
                    %button
                    app.DisconnectButton.Enable = 'off';
                end
            end
        end

        % Button pushed function: ConnectionSettingsButton
        function ConnectionSettingsButtonPushed(app, event)
            prompt = {'PC IP Address','PC Port','UAV IP Address','UAV Port','UAV FTP Username','UAV FTP Password'};
            dlgtitle = 'Connection settings';
            dims = [1 35];
            definput = {app.connsettings.pc.ip,...
                        num2str(app.connsettings.pc.port),...
                        app.connsettings.uav.ip,...
                        num2str(app.connsettings.uav.port),...
                        app.connsettings.ftp.user,...
                        app.connsettings.ftp.pswd};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                %Update the ports and IPs as necessary
                app.connsettings.pc.ip       = answer{1};
                app.connsettings.pc.port     = str2num(answer{2});
                app.connsettings.uav.ip      = answer{3};
                app.connsettings.uav.port    = str2num(answer{4});
                app.connsettings.ftp.user    = answer{5};
                app.connsettings.ftp.pswd    = answer{6};
                
                app.connsettings.ftp.addr    = app.connsettings.uav.ip;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %This run when the app closes.
            if ~isempty(app.udpobj) && strcmp(app.udpobj.Status,'open')
               %If udp object was created and connection wasn't closed
                fclose(app.udpobj);%Properly close the connection to clear the port
            end
            if ~isempty(app.ftpobj)
               %If ftp object was created and connection wasn't closed
                close(app.ftpobj)%Properly close the connection
            end
            delete(app)
        end

        % Value changed function: MapcachedirEditField
        function MapcachedirEditFieldValueChanged(app, event)
        %If the user manually updated the field, updated the app.mapcachedir
        %property
            value = app.MapcachedirEditField.Value;
            app.mapcachedir = value; %Update property
        end

        % Button pushed function: ALLButton
        function ALLButtonPushed(app, event)
            
            if app.ProcessBearingCheckBox.Value == true && ...
               app.ProcessPositionCheckBox.Value  == true 
               steps = 5;
            elseif app.ProcessBearingCheckBox.Value == true && ...
                   app.ProcessPositionCheckBox.Value  == false 
               steps = 4;
            elseif app.ProcessBearingCheckBox.Value == false && ...
                   app.ProcessPositionCheckBox.Value  == false 
               steps = 3;
            end
            
            h_wait = uiprogressdlg(app.UIFigure,'Message','Processing Data','Title','Processing Progress','Cancelable','on');
            h_wait.Value = 0;
            
            
            h_wait.Message = 'Processing vehicle flight data...';
            VehicleDataButtonPushed(app,event);
            h_wait.Value = 1/steps;
            h_wait.Message = 'Processing radio data...';
            RadioDataButtonPushed(app,event);
            h_wait.Value = 2/steps;
            h_wait.Message = 'Extracting pulses...';
            ExtractPulsesButtonPushed(app,event);
            h_wait.Value = 3/steps;
            if app.ProcessBearingCheckBox.Value == true
            h_wait.Message = 'Estimating bearings...';
            BearingsButtonPushed(app,event);
            h_wait.Value = 4/steps;
            end
            if app.ProcessPositionCheckBox.Value == true
            h_wait.Message = 'Estimating positions...';
            PositionButtonPushed(app,event);
            h_wait.Value = 5/steps;
            end
            
            
            close(h_wait)
        end

        % Value changed function: ProcessBearingCheckBox
        function ProcessBearingCheckBoxValueChanged(app, event)
            value = app.ProcessBearingCheckBox.Value;
            if value == true
                app.ProcessPositionCheckBox.Enable = 'on';
            else
                app.ProcessPositionCheckBox.Enable = 'off';
                app.ProcessPositionCheckBox.Value  = false;
            end
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            %value = app.MapDropDown.Value;
            ClearButtonPushed(app);
            PlotButtonPushed(app);
        end

        % Button pushed function: CleanupconnectionButton
        function CleanupconnectionButtonPushed(app, event)
            %Does a hard disconnection and resets lamps etc. 
            delete(instrfindall);
            udpdisconnected(app);
            delete(app.udpobj)
            clear app.udpobj
            app.udpobj = [];
            app.TextArea_VEHTRM.Value = [app.TextArea_VEHTRM.Value;{['*** UDP Connection Closed: ',datestr(now)]}];%Timestamp connection start
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
            app.StartRadioButton.Enable = 'off';
            app.StopRadioButton.Enable = 'off';
            app.UDPLamp.Color          = [1, 0, 0];%Update connection display
            app.ftpobj = [];
            delete(app.Tree.Children)%Clear the tree contents
            app.FTPLamp.Color = [1, 0, 0];%Update connection display
            app.DisconnectButton.Enable = 'off';
            app.connsettings.udp.flg = '';%Clear the flag. 

        end

        % Callback function
        function SpecifytestlocButtonPushed(app, event)
%             prompt = {'Tag Latitude (deg-N):','Tag Longitude (deg-E):','Tag X Position (m-N)','Tag Y Position (m-E)','Tag radius from vehicle home (m)','Tag heading from vehicle home (deg from True North)','Tag altitude from ground (m)','Tag Antenna Orientation (deg from True North OR enter "V" if vertical OR NaN if unknown)'};
%             dlgtitle = 'Test Tag Inputs';
%             dims = [1 35];
%             definput = {'','','','','','','0','NaN'};
%             %Notify user to only enter one type of position
%             msg_f = msgbox({'Enter only tag lat/lon,  x/y, or R/Theta.';'';'Do not enter more than one type of position.'},'Position Spec. Requirements','help');
%             waitfor(msg_f);
%             %Query user about position
%             answer = inputdlg(prompt,dlgtitle,dims,definput);
%             
%             pos_logic_latlon = [~isempty(answer{1}),~isempty(answer{2})];
%             pos_logic_xy     = [~isempty(answer{3}),~isempty(answer{4})];
%             pos_logic_rth    = [~isempty(answer{5}),~isempty(answer{6})];
%             
%             %Check if more than one type of position was entered
%             pos_selected_logic = [any(pos_logic_latlon),any(pos_logic_xy),any(pos_logic_rth)]; %[1 0 0] if lat lat only, for example
%             morethanone = sum(pos_selected_logic);
%             while morethanone>1
%                 %Repeat this until the user enters just one type of position
%                 msg_f = msgbox({'Do not enter more than one type of position.'},'Position Spec. Requirements','help');
%                 waitfor(msg_f);
%                 %Query user about position
%                 answer = inputdlg(prompt,dlgtitle,dims,answer);%Default to user input
%                 pos_logic_latlon = [~isempty(answer{1}),~isempty(answer{2})];
%                 pos_logic_xy     = [~isempty(answer{3}),~isempty(answer{4})];
%                 pos_logic_rth    = [~isempty(answer{5}),~isempty(answer{6})];
%                 pos_selected_logic = [any(pos_logic_latlon),any(pos_logic_xy),any(pos_logic_rth)]; %[1 0 0] if lat lat only, for example
%                 morethanone = sum(pos_selected_logic);
%             end
%             
%             app.TEST.lat   = str2num(answer{1});
%             app.TEST.lon   = str2num(answer{2});
%             app.TEST.x     = str2num(answer{3});
%             app.TEST.y     = str2num(answer{4});
%             app.TEST.r     = str2num(answer{5});
%             app.TEST.th    = str2num(answer{6});
%             app.TEST.alt   = str2num(answer{7});
%             
%             if contains(answer{8},'v','IgnoreCase',true)%Check if any v in result
%                 app.TEST.orient = 'V';
%             elseif contains(answer{8},'NaN','IgnoreCase',true)
%                 app.TEST.orient = NaN;
%             elseif ~isempty(str2num(answer{8}))%Check if it is a number
%                 app.TEST.orient = str2num(answer{8});
%             else
%                 uialert(app.UIFigure,'Tag antenna orientation specification not valid. Please reenter.','Tag orientation error')
%             end
%             
%            disp('Breakpoint!')
        end

        % Callback function
        function TagpositionisButtonGroupSelectionChanged(app, event)
%             selectedButton = app.TagpositionisButtonGroup.SelectedObject;
%             if strcmp(selectedButton.Text,'Known')
%                 app.TAG_TEST_LAT.Enable = 'on';
%                 app.TAG_TEST_LON.Enable = 'on';
%                 app.TAG_TEST_ALT.Enable = 'on';
%                 app.TAG_TEST_ANT_STR.Enable = 'on';
%                 app.TEST.lat = str2double(app.TAG_TEST_LAT.Value);
%                 app.TEST.lon = str2double(app.TAG_TEST_LON.Value);
%                 app.TEST.ant = str2double(app.TAG_TEST_ANT_STR.Value);
%                 app.TEST.alt = str2double(app.TAG_TEST_ALT.Value);
%             else
%                 app.TAG_TEST_LAT.Enable = 'off';
%                 app.TAG_TEST_LON.Enable = 'off';
%                 app.TAG_TEST_ALT.Enable = 'off';
%                 app.TAG_TEST_ANT_STR.Enable = 'off';
%                 app.TEST.lat = [0];
%                 app.TEST.lon = [0];
%                 app.TEST.ant = [0];
%                 app.TEST.alt = 'Unknown';
%             end
        end

        % Value changed function: TAG_TEST_LAT
        function TAG_TEST_LATValueChanged(app, event)
            value = app.TAG_TEST_LAT.Value;
            app.TEST.lat = value;
        end

        % Value changed function: TAG_TEST_LON
        function TAG_TEST_LONValueChanged(app, event)
            value = app.TAG_TEST_LON.Value;
            app.TEST.lon = value;
        end

        % Value changed function: TAG_TEST_ALT
        function TAG_TEST_ALTValueChanged(app, event)
            value = app.TAG_TEST_ALT.Value;
            app.TEST.alt = value;
        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            value = app.EnableButton.Value;
             if value
                app.TAG_TEST_LAT.Enable = 'on';
                app.TAG_TEST_LON.Enable = 'on';
                app.TAG_TEST_ALT.Enable = 'on';
                app.TAG_TEST_ANT_STR.Enable = 'off';
                app.AntUnknownButton.Enable     = 'on';
                app.AntUnknownButton.Value      = true;
                app.AntVerticalButton.Enable    = 'on';
                app.AntHorizontalButton.Enable  = 'on';
                app.TEST.lat = app.TAG_TEST_LAT.Value;
                app.TEST.lon = app.TAG_TEST_LON.Value;
                app.TEST.ant = app.TAG_TEST_ANT_STR.Value;
                app.TEST.alt = app.TAG_TEST_ALT.Value;
            else
                app.TAG_TEST_LAT.Enable = 'off';
                app.TAG_TEST_LON.Enable = 'off';
                app.TAG_TEST_ALT.Enable = 'off';
                app.TAG_TEST_ANT_STR.Enable = 'off';
                app.AntUnknownButton.Enable     = 'off';
                app.AntUnknownButton.Value      = true;
                app.AntVerticalButton.Enable    = 'off';
                app.AntHorizontalButton.Enable  = 'off';
                app.TAG_TEST_ANT_STR.Value = 'Unknown';
                app.TEST.lat = [0];
                app.TEST.lon = [0];
                app.TEST.ant = 'Unknown';
                app.TEST.alt = [0];
            end
        end

        % Selection changed function: 
        % TagantennaorientationisButtonGroup
        function TagantennaorientationisButtonGroupSelectionChanged(app, event)
            selectedButton = app.TagantennaorientationisButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Unknown')
                app.TEST.ant = 'Unknown';
                app.TAG_TEST_ANT_STR.Value = 'Unknown';
                app.TAG_TEST_ANT_STR.Enable = 'off';
            elseif strcmp(selectedButton.Text,'Horizontal with heading')
                app.TEST.ant = 0;
                app.TAG_TEST_ANT_STR.Value = num2str(0);
                app.TAG_TEST_ANT_STR.Enable = 'on';
            elseif strcmp(selectedButton.Text,'Vertical')
                app.TEST.ant = 'Vertical';
                app.TAG_TEST_ANT_STR.Value = 'Vertical';
                app.TAG_TEST_ANT_STR.Enable = 'off';
            end
        end

        % Value changed function: TAG_TEST_ANT_STR
        function TAG_TEST_ANT_STRValueChanged(app, event)
            value = app.TAG_TEST_ANT_STR.Value;
            if ~isempty(str2num(value))%Check if it is a number
                valuenum = str2num(value);
                if (valuenum>=360) || (valuenum<0)
                    uialert(app.UIFigure,'Value must be between >=0 and <360 degrees.','Invalid orientation angle')
                    app.TAG_TEST_ANT_STR.Value = '0';
                else %Only update if valid. 
                    app.TAG_TEST_ANT_STR.Value = value;%Update field
                    app.TEST.ant = str2double(value);%Update property
                end
            else %If it isn't a number, it must be a string
                %Inform the user and reset to an unknown orientation
                uialert(app.UIFigure,'Value must be between >=0 and <360 degrees.','Invalid orientation angle')
                app.AntUnknownButton.Value = true;
                app.TEST.ant = 'Unknown';
                app.TAG_TEST_ANT_STR.Enable = 'off';
                app.TAG_TEST_ANT_STR.Value = 'Unknown';
            end
        end

        % Button pushed function: BreakpointButton
        function BreakpointButtonPushed(app, event)
            disp('Breakpoint!')
        end

        % Button pushed function: ClearterminalButton
        function ClearterminalButtonPushed(app, event)
        answer = uiconfirm(app.UIFigure,'Are you sure you want to delete the terminal display?','Clear confirmation','Options',{'Yes','No'},'DefaultOption','No','Icon','warning');
        if strcmp(answer,'Yes')
            app.TextArea_VEHTRM.Value = '';
            app.TextArea_RAWCOMM.Value = '';
        end
        
        end

        % Button pushed function: RefreshIPButton
        function RefreshIPButtonPushed(app, event)
            try
                app.connsettings.pc.ip = getmyip(app);
                uialert(app.UIFigure,['IP address reset to: ',app.connsettings.pc.ip],'Reset success','Icon','success')
            catch
                uialert(app.UIFigure,'Error getting the current IP address. You may need to enter it manually in the connection settings','IP Set Error','Icon','error')
            end
        end

        % Button pushed function: SnapwindowButton
        function SnapwindowButtonPushed(app, event)
            %Position the window in the screen
            %movegui(app.UIFigure,[1 1])
            screensize = get(0,'ScreenSize');
            pxwdth     = screensize(3);
            pxht       = screensize(4);
            app.UIFigure.Position = [1, 1, floor(pxwdth/4), pxht];       
            
        end

        % Callback function
        function SetworkingdirectoryButtonPushed(app, event)
            app.UIFigure.Visible = 'off'; 
            selpath = uigetdir([],'Set working file directory');
            %Based on https://www.mathworks.com/matlabcentral/answers/317676-uigetfile-will-make-app-be-behind-another-windows
            %To bring window back to front.
            app.UIFigure.Visible = 'on';
            
            stuffindir = dir(selpath);
            founddir = false;
            skip = false;
            for i = 1:length(stuffindir)
                if strcmp(stuffindir(i).name,'UAVRT') & stuffindir(i).isdir
                    founddir = true;
                end
            end
            if founddir
                    msg = 'That directory already has a folder called UAVRT. Do you want to use that directory or select a different folder?';
                    title = 'UAVRT Directory Already Exists';
                    selection = uiconfirm(app.UIFigure,msg,title,...
                                'Options',{'Use existing UAVRT folder','Close and select different folder','Cancel'},...
                                'DefaultOption',1,'CancelOption',3);
                            if strcmp(selection,'Use existing UAVRT folder')
                                selpath = fullfile(selpath,'UAVRT');
                            else
                                skip = true;
                            end
            else
                mkdir(fullfile(selpath,'UAVRT'));
                selpath = fullfile(selpath,'UAVRT');
            end
            if ~skip
            app.mapcachedir = fullfile(selpath,'UAV_Maps');
            app.iconsdir    = fullfile(selpath,'Icons');
            app.configfilesdir = fullfile(selpath);
            
            app.MapcachedirEditField.Value = app.mapcachedir;
            app.WorkingDirectoryEditField.Value = selpath;
            end
        end

        % Selection changed function: TimeselectionButtonGroup
        function TimeselectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeselectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'All data')
                app.StartsEditField.Enable = false;
                app.EndsEditField.Enable   = false;
                app.StartsEditField.Value = 0;
                app.EndsEditField.Value = Inf;
            elseif strcmp(selectedButton.Text,'At altitude')
                app.StartsEditField.Enable = false;
                app.EndsEditField.Enable   = false;
                if isempty(app.UAV.atalt)
                    app.StartsEditField.Value = 0;
                    app.EndsEditField.Value = Inf;  
                else
                    %Use the first and last point where the vehicle was
                    %within 90% of the max altitiude. See the comments in
                    %flightprep.m to see how this get set. 
                     app.StartsEditField.Value = app.UAV.time(find(app.UAV.atalt,1,'first'));
                     app.EndsEditField.Value   = app.UAV.time(find(app.UAV.atalt,1,'last'));
                end
            elseif strcmp(selectedButton.Text,'Manual times')
                app.StartsEditField.Enable = true;
                app.EndsEditField.Enable   = true;
            end
        end

        % Selection changed function: DwellSelectButtonGroup
        function DwellSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.DwellSelectButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Auto')
                app.MinDwellEditField.Enable  = 'off';
                app.MinDwellEditField.Editable = 'off';
                app.MinDwellEditField.Visible = 'off';
                app.MinDwellLabel.Visible = 'off';
                app.MaxDwellEditField.Enable  = 'off';
                app.MaxDwellEditField.Editable = 'off';
                app.MaxDwellEditField.Visible = 'off';
                app.MaxDwellLabel.Visible = 'off';
            else
                app.MinDwellEditField.Enable  = 'on';
                app.MinDwellEditField.Editable = 'on';
                app.MinDwellEditField.Visible = 'on';
                app.MinDwellLabel.Visible = 'on';
                app.MaxDwellEditField.Enable  = 'on';
                app.MaxDwellEditField.Editable = 'on';
                app.MaxDwellEditField.Visible = 'on';
                app.MaxDwellLabel.Visible = 'on';
            end
        end

        % Selection changed function: AltSelectButtonGroup
        function AltSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.AltSelectButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Auto')
                app.MinAltEditField.Enable  = 'off';
                app.MinAltEditField.Editable = 'off';
                app.MinAltEditField.Visible = 'off';
                app.MinAltLabel.Visible = 'off';
                app.MaxAltEditField.Enable  = 'off';
                app.MaxAltEditField.Editable = 'off';
                app.MaxAltEditField.Visible = 'off';
                app.MaxAltLabel.Visible = 'off';
                app.AltUnitsDropDown.Enable = 'off';
                app.AltUnitsDropDown.Editable = 'off';
                app.AltUnitsDropDown.Visible = 'off';
            else
                app.MinAltEditField.Enable  = 'on';
                app.MinAltEditField.Editable = 'on';
                app.MinAltEditField.Visible = 'on';
                app.MinAltLabel.Visible = 'on';
                app.MaxAltEditField.Enable  = 'on';
                app.MaxAltEditField.Editable = 'on';
                app.MaxAltEditField.Visible = 'on';
                app.MaxAltLabel.Visible = 'on';
                app.AltUnitsDropDown.Enable = 'on';
                app.AltUnitsDropDown.Editable = 'on';
                app.AltUnitsDropDown.Visible = 'on';
            end
        end

        % Value changed function: AltUnitsDropDown
        function AltUnitsDropDownValueChanged(app, event)
            value = app.AltUnitsDropDown.Value;
            if strcmp(value,'meters')%Changed to meters
                unitfactor = 1/3.28084; %m/ft
            else %Changes from m to ft
                unitfactor = 3.28084; %ft/m
            end
            %Now update the edit fields for the users
            app.MinAltEditField.Value = unitfactor*app.MinAltEditField.Value;
            app.MaxAltEditField.Value = unitfactor*app.MaxAltEditField.Value;
        end

        % Button pushed function: TagcheckButton
        function TagcheckButtonPushed(app, event)
        h_wait = uiprogressdlg(app.UIFigure,'Title','Checking tag data','Message','Extracting tag characteristics','Indeterminate','on')
        [f,PRI,PD,PRI_std,PD_std] = tagcheck(app.SDR.raw.data,app.FRS_PROCESS.Value);
        close(h_wait);
        themessage = sprintf('Frequency from center:\t%5.3f\t(Hz)\nPulse Rep. Interval:\t%3.3f\t(s)\nPulse Rep. Std. Dev.:\t%3.5f\t(s)\nPulse Duration:\t\t%3.3f\t(s)\nPulse Duration Std. Dev.:\t%3.5f\t(s)',f,PRI,PRI_std,PD,PD_std);
        uialert(app.UIFigure,themessage,'Tag Report','Icon','success')
%             app.SDR.dec.Fs
%             app.FRS_PROCESS.Value
        end

        % Button pushed function: PopfigureButton
        function PopfigureButtonPushed(app, event)
            f_map_pop = figure; %Create an indpendent figure;
            a2 = gca; %Add an axis
            %Now go through all the properties of the new figure and copy
            %over the equivalant properties from the app figure; Copypbj.m
            %doesn't work for UIAxes objects.
            a2_prop_list = properties(a2);
            app_prop_list = properties(app.UIAxes2);
            for i = 1:length(a2_prop_list)
                a2_prop_list{i}
                prop_ind_in_app_props = find(strcmp(app_prop_list,a2_prop_list{i}));
                if ~isempty(prop_ind_in_app_props) &&...
                        ~strcmp(a2_prop_list{i},'Parent') && ...
                        ~strcmp(a2_prop_list{i},'Position') && ...
                        ~strcmp(a2_prop_list{i},'OuterPosition')% && ...
                        %~strcmp(a2_prop_list{i},'ZAxis') 
%                     if i==81
%                         disp('Breakpoint')
%                     end
                    try %Skip parameters that won't copy 
                         if strcmp(a2_prop_list{i},'Children')
                             eval(['copyobj(app.UIAxes2.',app_prop_list{prop_ind_in_app_props},',a2);']);
%                             disp('Breakpoint')
%                             for i = 1:length(app.UIAxes2.Children)
%                                 copyobj(app.UIAxes2.Children(i),a2.Children(i));
%                             end
                         end
                        if isobject(a2_prop_list{i})
                            eval(['copyobj(app.UIAxes2.',app_prop_list{prop_ind_in_app_props},',a2);']);
                        else
                            eval(['a2.',a2_prop_list{i},'=','app.UIAxes2.',app_prop_list{prop_ind_in_app_props},';']);
                        end
                    catch
                        disp([a2_prop_list{i},' didn`t copy'])
                    end
                    %
                end
            end
            
        end

        % Button pushed function: PulseonlypositionButton
        function PulseonlypositionButtonPushed(app, event)
             
            [pos_yNxE_CM,intermap_yNxE] = localize_cm_pulses( app.UAVPULS.y, app.UAVPULS.x, app.pulse.pow);
                
            if sum(~isnan(app.pulse.pow))<=3%Only proceed if we have at least 3 valid pulses.
                warndlg(['More than three pulses are needed for localization estimates. ']);
            else            
                
                latlonhome = [app.home.lat,app.home.lon];
                %Localization results in lat/lons
                latlon_CM     = xy2latlon(latlonhome,pos_yNxE_CM');
                %Localization results in cylindrical coordinates (radial distance
                %and bearing)
                cyl_CM     = [norm(pos_yNxE_CM)      ,wrapTo360(180/pi*atan2(pos_yNxE_CM(1),pos_yNxE_CM(2)))];
                %Flip the x and y's to get back to X-North Y-East frame.
                pos_xNyE_CM   = flip(pos_yNxE_CM); 
                    intermap_xNyE = intermap_yNxE(:,[2,1,3:end]);
                
                %Package variables
                app.PULSELOC.CM.x    = pos_xNyE_CM(1);
                app.PULSELOC.CM.y    = pos_xNyE_CM(2);
                app.PULSELOC.CM.lat  = latlon_CM(1);
                app.PULSELOC.CM.lon  = latlon_CM(2);
                app.PULSELOC.CM.r    = cyl_CM(1);
                app.PULSELOC.CM.th   = cyl_CM(2);
                
                %%plotswitch(app,app.UIAxes2.Children,'LOC','delete');%Clear any old Location estimates
                
                plot(app.UIAxes2,app.PULSELOC.CM.x  ,app.PULSELOC.CM.y  ,'rd','MarkerFaceColor','r','Markersize',10,'Tag','PULSELOC-CM');
                
                app.LatEditField.ValueDisplayFormat = '%11.6f';
                app.LonEditField.ValueDisplayFormat = '%11.6f';
                app.XNEditField.ValueDisplayFormat  = '%11.1f';
                app.YEEditField.ValueDisplayFormat  = '%11.1f';
                app.REditField.ValueDisplayFormat   = '%11.1f';
                app.ThetafromTrueNEditField.ValueDisplayFormat = '%11.1f';
                
                if ~isnan([app.PULSELOC.CM.x])
                    app.LatEditField.Value   = app.PULSELOC.CM.lat;
                    app.LonEditField.Value   = app.PULSELOC.CM.lon;
                    app.XNEditField.Value    = app.PULSELOC.CM.x;
                    app.YEEditField.Value    = app.PULSELOC.CM.y;
                    app.REditField.Value     = app.PULSELOC.CM.r;
                    app.ThetafromTrueNEditField.Value = app.PULSELOC.CM.th;
                else
                    app.LatEditField.Value   = 0;
                    app.LonEditField.Value   = 0;
                    app.XNEditField.Value    = 0;
                    app.YEEditField.Value    = 0;
                    app.REditField.Value     = 0;
                    app.ThetafromTrueNEditField.Value = 0;
                end
                ResetResultsMapButtonPushed(app)
                
            end
            
        end

        % Value changed function: TesttagplotButton
        function TesttagplotButtonValueChanged(app, event)
            value = app.TesttagplotButton.Value;
            disp('BREAKPOINT')
%            %Plot the position of the beacon if testing
             if value 
                plotknowntagloc(app);
             else
                 handleswithLOCTRUE = findtagpart(app,app.UIAxes2.Children,'LOC-TRUE');
                 delete(app.UIAxes2.Children(handleswithLOCTRUE))
             end

        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            theexportloc = fullfile(app.OutputTextArea.Value{1},[app.OutputsavestringEditField.Value,'_PULSE_RECORD.csv']);
            selection = uiconfirm(app.UIFigure,...
                'This will overwrite the existing pulse data (if one exists already) in this export file location. Proceed?',...
                'Overwrite?','Icon','warning');
            if strcmp(selection,'OK')
                writetable(app.UITable2.Data,theexportloc)
            end
        end

        % Button pushed function: ExportCSVButton_2
        function ExportCSVButton_2Pushed(app, event)
            theexportloc = fullfile(app.OutputTextArea.Value{1},[app.OutputsavestringEditField.Value,'_WP_RECORD.csv']);
            selection = uiconfirm(app.UIFigure,...
                'This will overwrite the existing waypoint data (if one exists already) in this export file location. Proceed?',...
                'Overwrite?','Icon','warning');
            if strcmp(selection,'OK')
                writetable(app.UITable.Data,theexportloc)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VehicleConnectionTab = uitab(app.TabGroup);
            app.VehicleConnectionTab.Title = 'Vehicle Connection';
            app.FlightsettingsPanel = uipanel(app.VehicleConnectionTab);
            app.FlightsettingsPanel.Title = 'Flight settings';
            app.PulsedurationsEditField_2Label = uilabel(app.FlightsettingsPanel);
            app.PulsedurationsEditField_2Label.Text = 'Pulse duration (s)';
            app.PUL_D_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.PulserepetitionratesEditField_2Label = uilabel(app.FlightsettingsPanel);
            app.PulserepetitionratesEditField_2Label.Text = 'Pulse repetition rate (s)';
            app.PUL_R_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.RadioSamplingRateHzEditField_2Label = uilabel(app.FlightsettingsPanel);
            app.RadioSamplingRateHzEditField_2Label.Text = 'Radio Sampling Rate (Hz)';
            app.FRS_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.UAVTelemsamplerateHzLabel = uilabel(app.FlightsettingsPanel);
            app.UAVTelemsamplerateHzLabel.Text = 'UAV Telem. sample rate (Hz)';
            app.FVEH_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.TagFrequencyMHzEditField_2Label = uilabel(app.FlightsettingsPanel);
            app.TagFrequencyMHzEditField_2Label.Text = 'Tag Frequency (MHz)';
            app.FT_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.FT_INPUT.ValueDisplayFormat = '%11.6g';
            app.RFGaindBEditField_2Label = uilabel(app.FlightsettingsPanel);
            app.RFGaindBEditField_2Label.Text = 'RF Gain (dB)';
            app.RFG_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.IFGaindBEditField_2Label = uilabel(app.FlightsettingsPanel);
            app.IFGaindBEditField_2Label.Text = 'IF Gain (dB)';
            app.IFG_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.FlightnotesTextArea_2Label = uilabel(app.FlightsettingsPanel);
            app.FlightnotesTextArea_2Label.Text = 'Flight notes';
            app.NOTES_INPUT = uitextarea(app.FlightsettingsPanel);
            app.BBGaindBLabel = uilabel(app.FlightsettingsPanel);
            app.BBGaindBLabel.Text = 'BB Gain (dB)';
            app.BBG_INPUT = uieditfield(app.FlightsettingsPanel, 'numeric');
            app.SendsettingsButton = uibutton(app.FlightsettingsPanel, 'push');
            app.SendsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SendsettingsButtonPushed, true);
            app.SendsettingsButton.Text = 'Send settings';
            app.GetsettingsButton = uibutton(app.FlightsettingsPanel, 'push');
            app.GetsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @GetsettingsButtonPushed, true);
            app.GetsettingsButton.Text = 'Get settings';
            app.UsepastconfigButton = uibutton(app.FlightsettingsPanel, 'push');
            app.UsepastconfigButton.Enable = 'off';
            app.UsepastconfigButton.Text = 'Use past config';
            app.SystemControlPanel = uipanel(app.VehicleConnectionTab);
            app.SystemControlPanel.Title = 'System Control';
            app.ConnectButton = uibutton(app.SystemControlPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StartRadioButton = uibutton(app.SystemControlPanel, 'push');
            app.StartRadioButton.ButtonPushedFcn = createCallbackFcn(app, @StartRadioButtonPushed, true);
            app.StartRadioButton.Text = 'Start Radio';
            app.StopRadioButton = uibutton(app.SystemControlPanel, 'push');
            app.StopRadioButton.ButtonPushedFcn = createCallbackFcn(app, @StopRadioButtonPushed, true);
            app.StopRadioButton.Text = 'Stop Radio';
            app.TextArea_VEHTRM = uitextarea(app.SystemControlPanel);
            app.TextArea_VEHTRM.Editable = 'off';
            app.DisconnectButton = uibutton(app.SystemControlPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectionSettingsButton = uibutton(app.SystemControlPanel, 'push');
            app.ConnectionSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectionSettingsButtonPushed, true);
            app.ConnectionSettingsButton.Text = 'Connection Settings';
            app.FTPLampLabel = uilabel(app.SystemControlPanel);
            app.FTPLampLabel.Text = 'FTP';
            app.FTPLamp = uilamp(app.SystemControlPanel);
            app.UDPLampLabel = uilabel(app.SystemControlPanel);
            app.UDPLampLabel.Text = 'UDP';
            app.UDPLamp = uilamp(app.SystemControlPanel);
            app.VehicleTerminalOutputLabel = uilabel(app.SystemControlPanel);
            app.VehicleTerminalOutputLabel.Text = 'Vehicle Terminal Output';
            app.ConnectionStatusLabel = uilabel(app.SystemControlPanel);
            app.ConnectionStatusLabel.Text = 'Connection Status';
            app.CleanupconnectionButton = uibutton(app.SystemControlPanel, 'push');
            app.CleanupconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CleanupconnectionButtonPushed, true);
            app.CleanupconnectionButton.Text = 'Clean up connection';
            app.ClearterminalButton = uibutton(app.SystemControlPanel, 'push');
            app.ClearterminalButton.ButtonPushedFcn = createCallbackFcn(app, @ClearterminalButtonPushed, true);
            app.ClearterminalButton.Text = 'Clear terminal';
            app.RefreshIPButton = uibutton(app.SystemControlPanel, 'push');
            app.RefreshIPButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshIPButtonPushed, true);
            app.RefreshIPButton.Text = 'Refresh IP';
            app.SnapwindowButton = uibutton(app.SystemControlPanel, 'push');
            app.SnapwindowButton.ButtonPushedFcn = createCallbackFcn(app, @SnapwindowButtonPushed, true);
            app.SnapwindowButton.Text = 'Snap window';
            app.LastHearbeatEditFieldLabel = uilabel(app.SystemControlPanel);
            app.LastHearbeatEditFieldLabel.Text = 'Last Hearbeat';
            app.LastHearbeatEditField = uieditfield(app.SystemControlPanel, 'numeric');
            app.LastHearbeatEditField.ValueDisplayFormat = '%11.2g';
            app.LastHearbeatEditField.Editable = 'off';
            app.StatusEditFieldLabel = uilabel(app.SystemControlPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.SystemControlPanel, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'unknown';
            app.FileoffloadPanel = uipanel(app.VehicleConnectionTab);
            app.FileoffloadPanel.Title = 'File offload';
            app.Tree = uitree(app.FileoffloadPanel);
            app.DownloadDataButton = uibutton(app.FileoffloadPanel, 'push');
            app.DownloadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDataButtonPushed, true);
            app.DownloadDataButton.Text = 'Download ';
            app.UAV_FTP_Pop_Dir = uibutton(app.FileoffloadPanel, 'push');
            app.UAV_FTP_Pop_Dir.ButtonPushedFcn = createCallbackFcn(app, @UAV_FTP_Pop_DirPushed, true);
            app.UAV_FTP_Pop_Dir.Text = 'Refresh';
            app.LocalfolderEditField = uieditfield(app.FileoffloadPanel, 'text');
            app.SaveLocationLabel = uilabel(app.FileoffloadPanel);
            app.SaveLocationLabel.Text = 'Save Location';
            app.ProcessingPrepTab = uitab(app.TabGroup);
            app.ProcessingPrepTab.Title = 'Processing Prep';
            app.DataprocessinginputPanel = uipanel(app.ProcessingPrepTab);
            app.DataprocessinginputPanel.Title = 'Data processing input';
            app.LoadDataButton = uibutton(app.DataprocessinginputPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.FlightFileTextAreaLabel = uilabel(app.DataprocessinginputPanel);
            app.FlightFileTextAreaLabel.Text = 'Flight File';
            app.FlightFileTextArea = uitextarea(app.DataprocessinginputPanel);
            app.RadioFileTextAreaLabel = uilabel(app.DataprocessinginputPanel);
            app.RadioFileTextAreaLabel.Text = 'Radio File';
            app.RadioFileTextArea = uitextarea(app.DataprocessinginputPanel);
            app.OutputsavestringEditFieldLabel = uilabel(app.DataprocessinginputPanel);
            app.OutputsavestringEditFieldLabel.Text = 'Output savestring';
            app.OutputsavestringEditField = uieditfield(app.DataprocessinginputPanel, 'text');
            app.OutputTextAreaLabel = uilabel(app.DataprocessinginputPanel);
            app.OutputTextAreaLabel.Text = 'Output ';
            app.OutputTextArea = uitextarea(app.DataprocessinginputPanel);
            app.SelectOutputFolderButton = uibutton(app.DataprocessinginputPanel, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.ConfigurationFileTextAreaLabel = uilabel(app.DataprocessinginputPanel);
            app.ConfigurationFileTextAreaLabel.Text = 'Configuration File';
            app.ConfigurationFileTextArea = uitextarea(app.DataprocessinginputPanel);
            app.ProcessingcontrolsPanel = uipanel(app.ProcessingPrepTab);
            app.ProcessingcontrolsPanel.Title = 'Processing controls';
            app.RadioProcessingOptionsButtonGroup = uibuttongroup(app.ProcessingcontrolsPanel);
            app.RadioProcessingOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioProcessingOptionsButtonGroupSelectionChanged, true);
            app.RadioProcessingOptionsButtonGroup.Title = 'Radio Processing Options';
            app.AutodetectpulsefrequencyButton = uiradiobutton(app.RadioProcessingOptionsButtonGroup);
            app.AutodetectpulsefrequencyButton.Text = 'Auto detect pulse frequency';
            app.AutodetectpulsefrequencyButton.Value = true;
            app.ManuallyenterpulsefrequencyButton = uiradiobutton(app.RadioProcessingOptionsButtonGroup);
            app.ManuallyenterpulsefrequencyButton.Text = 'Manually enter pulse frequency:';
            app.FilterbandwidthHzEditFieldLabel = uilabel(app.RadioProcessingOptionsButtonGroup);
            app.FilterbandwidthHzEditFieldLabel.Text = 'Filter bandwidth (Hz)';
            app.FilterbandwidthHzEditField = uieditfield(app.RadioProcessingOptionsButtonGroup, 'numeric');
            app.PulsefrequencyHzEditField = uieditfield(app.RadioProcessingOptionsButtonGroup, 'numeric');
            app.PlotspectumofradiodataCheckBox = uicheckbox(app.RadioProcessingOptionsButtonGroup);
            app.PlotspectumofradiodataCheckBox.Text = 'Plot spectum of radio data';
            app.PlotspectrogramthismighttakeacoupleofminutesCheckBox = uicheckbox(app.RadioProcessingOptionsButtonGroup);
            app.PlotspectrogramthismighttakeacoupleofminutesCheckBox.Text = 'Plot spectrogram (this might take a couple of minutes)';
            app.UpperlimitfromcenterfrequencyHzEditFieldLabel = uilabel(app.RadioProcessingOptionsButtonGroup);
            app.UpperlimitfromcenterfrequencyHzEditFieldLabel.Text = 'Upper limit from center frequency (Hz)';
            app.UpperlimitfromcenterfrequencyHzEditField = uieditfield(app.RadioProcessingOptionsButtonGroup, 'numeric');
            app.TimeselectionButtonGroup = uibuttongroup(app.RadioProcessingOptionsButtonGroup);
            app.TimeselectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeselectionButtonGroupSelectionChanged, true);
            app.TimeselectionButtonGroup.Title = 'Time selection';
            app.AlldataButton = uitogglebutton(app.TimeselectionButtonGroup);
            app.AlldataButton.Text = 'All data';
            app.AtaltitudeButton = uitogglebutton(app.TimeselectionButtonGroup);
            app.AtaltitudeButton.Tooltip = {'Selects time between the first and last instants when vehicle is within 90% of its average alt. for the flight.'};
            app.AtaltitudeButton.Text = 'At altitude';
            app.AtaltitudeButton.Value = true;
            app.ManualtimesButton = uitogglebutton(app.TimeselectionButtonGroup);
            app.ManualtimesButton.Text = 'Manual times';
            app.StartsEditFieldLabel = uilabel(app.TimeselectionButtonGroup);
            app.StartsEditFieldLabel.Enable = 'off';
            app.StartsEditFieldLabel.Text = 'Start (s)';
            app.StartsEditField = uieditfield(app.TimeselectionButtonGroup, 'numeric');
            app.StartsEditField.Limits = [0 Inf];
            app.StartsEditField.Enable = 'off';
            app.EndsEditFieldLabel = uilabel(app.TimeselectionButtonGroup);
            app.EndsEditFieldLabel.Enable = 'off';
            app.EndsEditFieldLabel.Text = 'End (s)';
            app.EndsEditField = uieditfield(app.TimeselectionButtonGroup, 'numeric');
            app.EndsEditField.Limits = [1 Inf];
            app.EndsEditField.Enable = 'off';
            app.EndsEditField.Value = Inf;
            app.PulsedetectionthresholdSliderLabel = uilabel(app.RadioProcessingOptionsButtonGroup);
            app.PulsedetectionthresholdSliderLabel.Text = 'Pulse detection threshold';
            app.PulsedetectionthresholdSlider = uislider(app.RadioProcessingOptionsButtonGroup);
            app.PulsedetectionthresholdSlider.Limits = [0 1];
            app.PulsedetectionthresholdSlider.Tooltip = {'This is the fraction above the moving average of the signal (power or amplitude depending on selection) at which the signal could be considered a pulse.'};
            app.PulsedetectionthresholdSlider.Value = 0.5;
            app.WaypointdetectionButtonGroup = uibuttongroup(app.ProcessingcontrolsPanel);
            app.WaypointdetectionButtonGroup.Title = 'Waypoint detection';
            app.DwellSelectButtonGroup = uibuttongroup(app.WaypointdetectionButtonGroup);
            app.DwellSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DwellSelectButtonGroupSelectionChanged, true);
            app.DwellSelectButtonGroup.Title = 'Dwell time';
            app.AutoDwellButton = uitogglebutton(app.DwellSelectButtonGroup);
            app.AutoDwellButton.Text = 'Auto';
            app.AutoDwellButton.Value = true;
            app.ManualDwellButton = uitogglebutton(app.DwellSelectButtonGroup);
            app.ManualDwellButton.Text = 'Manual';
            app.MinsLabel = uilabel(app.DwellSelectButtonGroup);
            app.MinsLabel.Enable = 'off';
            app.MinsLabel.Visible = 'off';
            app.MinsLabel.Text = 'Min (s)';
            app.MinDwellEditField = uieditfield(app.DwellSelectButtonGroup, 'numeric');
            app.MinDwellEditField.Limits = [0 Inf];
            app.MinDwellEditField.ValueDisplayFormat = '%11.1f';
            app.MinDwellEditField.Enable = 'off';
            app.MinDwellEditField.Visible = 'off';
            app.MinDwellEditField.Tooltip = {'Minimum time UAV might have been at each waypoint'};
            app.MinDwellEditField.Value = 55;
            app.MaxsEditFieldLabel = uilabel(app.DwellSelectButtonGroup);
            app.MaxsEditFieldLabel.Visible = 'off';
            app.MaxsEditFieldLabel.Text = 'Max (s)';
            app.MaxDwellEditField = uieditfield(app.DwellSelectButtonGroup, 'numeric');
            app.MaxDwellEditField.Limits = [1 Inf];
            app.MaxDwellEditField.ValueDisplayFormat = '%11.1f';
            app.MaxDwellEditField.Visible = 'off';
            app.MaxDwellEditField.Tooltip = {'Maximum time UAV might have been at each waypoint'};
            app.MaxDwellEditField.Value = 65;
            app.MaxDwellLabel = uilabel(app.DwellSelectButtonGroup);
            app.MaxDwellLabel.Visible = 'off';
            app.MaxDwellLabel.Text = 'Max (s)';
            app.MinDwellLabel = uilabel(app.DwellSelectButtonGroup);
            app.MinDwellLabel.Visible = 'off';
            app.MinDwellLabel.Text = 'Min (s)';
            app.AltSelectButtonGroup = uibuttongroup(app.WaypointdetectionButtonGroup);
            app.AltSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AltSelectButtonGroupSelectionChanged, true);
            app.AltSelectButtonGroup.Title = 'Altitude';
            app.AutoAltButton = uitogglebutton(app.AltSelectButtonGroup);
            app.AutoAltButton.Text = 'Auto';
            app.AutoAltButton.Value = true;
            app.ManualAltButton = uitogglebutton(app.AltSelectButtonGroup);
            app.ManualAltButton.Text = 'Manual';
            app.MinEditFieldLabel = uilabel(app.AltSelectButtonGroup);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Visible = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinAltEditField = uieditfield(app.AltSelectButtonGroup, 'numeric');
            app.MinAltEditField.ValueDisplayFormat = '%11.1f';
            app.MinAltEditField.Enable = 'off';
            app.MinAltEditField.Visible = 'off';
            app.MinAltEditField.Tooltip = {'Minimum altitude UAV might have been at each waypoint'};
            app.MinAltEditField.Value = 48;
            app.MaxEditFieldLabel = uilabel(app.AltSelectButtonGroup);
            app.MaxEditFieldLabel.Enable = 'off';
            app.MaxEditFieldLabel.Visible = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxAltEditField = uieditfield(app.AltSelectButtonGroup, 'numeric');
            app.MaxAltEditField.ValueDisplayFormat = '%11.1f';
            app.MaxAltEditField.Enable = 'off';
            app.MaxAltEditField.Visible = 'off';
            app.MaxAltEditField.Tooltip = {'Maximum altitude UAV might have been at each waypoint'};
            app.MaxAltEditField.Value = 52;
            app.AltUnitsDropDown = uidropdown(app.AltSelectButtonGroup);
            app.AltUnitsDropDown.Items = {'meters', 'feet'};
            app.AltUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @AltUnitsDropDownValueChanged, true);
            app.AltUnitsDropDown.Enable = 'off';
            app.AltUnitsDropDown.Visible = 'off';
            app.AltUnitsDropDown.Value = 'meters';
            app.MaxAltLabel = uilabel(app.AltSelectButtonGroup);
            app.MaxAltLabel.Visible = 'off';
            app.MaxAltLabel.Text = 'Max';
            app.MinAltLabel = uilabel(app.AltSelectButtonGroup);
            app.MinAltLabel.Visible = 'off';
            app.MinAltLabel.Text = 'Min';
            app.FlightsettingsPanel_2 = uipanel(app.ProcessingPrepTab);
            app.FlightsettingsPanel_2.Title = 'Flight settings';
            app.PulsedurationsEditFieldLabel = uilabel(app.FlightsettingsPanel_2);
            app.PulsedurationsEditFieldLabel.Text = 'Pulse duration (s)';
            app.PUL_D_PROCESS = uieditfield(app.FlightsettingsPanel_2, 'numeric');
            app.PulserepetitionratesEditFieldLabel = uilabel(app.FlightsettingsPanel_2);
            app.PulserepetitionratesEditFieldLabel.Text = 'Pulse repetition rate (s)';
            app.PUL_R_PROCESS = uieditfield(app.FlightsettingsPanel_2, 'numeric');
            app.RadioSamplingRateHzEditFieldLabel = uilabel(app.FlightsettingsPanel_2);
            app.RadioSamplingRateHzEditFieldLabel.Text = 'Radio Sampling Rate (Hz)';
            app.FRS_PROCESS = uieditfield(app.FlightsettingsPanel_2, 'numeric');
            app.VehiclestatesamplerateHzLabel = uilabel(app.FlightsettingsPanel_2);
            app.VehiclestatesamplerateHzLabel.Text = 'Vehicle state sample rate (Hz)';
            app.FVEH_PROCESS = uieditfield(app.FlightsettingsPanel_2, 'numeric');
            app.TagFrequencyMHzEditFieldLabel = uilabel(app.FlightsettingsPanel_2);
            app.TagFrequencyMHzEditFieldLabel.Text = 'Tag Frequency (MHz)';
            app.FT_PROCESS = uieditfield(app.FlightsettingsPanel_2, 'numeric');
            app.FT_PROCESS.ValueDisplayFormat = '%11.6g';
            app.FlightnotesTextAreaLabel = uilabel(app.FlightsettingsPanel_2);
            app.FlightnotesTextAreaLabel.Text = 'Flight notes';
            app.NOTES_PROCESS = uitextarea(app.FlightsettingsPanel_2);
            app.NOTES_PROCESS.Editable = 'off';
            app.TestinginputsPanel = uipanel(app.ProcessingPrepTab);
            app.TestinginputsPanel.Title = 'Testing inputs';
            app.AltmLabel = uilabel(app.TestinginputsPanel);
            app.AltmLabel.Text = 'Alt. (m)';
            app.TAG_TEST_ALT = uieditfield(app.TestinginputsPanel, 'numeric');
            app.TAG_TEST_ALT.ValueChangedFcn = createCallbackFcn(app, @TAG_TEST_ALTValueChanged, true);
            app.TAG_TEST_ALT.Enable = 'off';
            app.LondegELabel = uilabel(app.TestinginputsPanel);
            app.LondegELabel.Text = 'Lon. (deg-E)';
            app.TAG_TEST_LON = uieditfield(app.TestinginputsPanel, 'numeric');
            app.TAG_TEST_LON.Limits = [-180 180];
            app.TAG_TEST_LON.ValueDisplayFormat = '%3.7g';
            app.TAG_TEST_LON.ValueChangedFcn = createCallbackFcn(app, @TAG_TEST_LONValueChanged, true);
            app.TAG_TEST_LON.Enable = 'off';
            app.LatdegNLabel = uilabel(app.TestinginputsPanel);
            app.LatdegNLabel.Text = 'Lat. (deg-N)';
            app.TAG_TEST_LAT = uieditfield(app.TestinginputsPanel, 'numeric');
            app.TAG_TEST_LAT.Limits = [-90 90];
            app.TAG_TEST_LAT.ValueDisplayFormat = '%3.7g';
            app.TAG_TEST_LAT.ValueChangedFcn = createCallbackFcn(app, @TAG_TEST_LATValueChanged, true);
            app.TAG_TEST_LAT.Enable = 'off';
            app.TagantennaorientationisButtonGroup = uibuttongroup(app.TestinginputsPanel);
            app.TagantennaorientationisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TagantennaorientationisButtonGroupSelectionChanged, true);
            app.TagantennaorientationisButtonGroup.Title = 'Tag antenna orientation is:';
            app.AntUnknownButton = uiradiobutton(app.TagantennaorientationisButtonGroup);
            app.AntUnknownButton.Enable = 'off';
            app.AntUnknownButton.Text = 'Unknown';
            app.AntUnknownButton.Value = true;
            app.AntHorizontalButton = uiradiobutton(app.TagantennaorientationisButtonGroup);
            app.AntHorizontalButton.Enable = 'off';
            app.AntHorizontalButton.Text = 'Horizontal with heading';
            app.AntVerticalButton = uiradiobutton(app.TagantennaorientationisButtonGroup);
            app.AntVerticalButton.Enable = 'off';
            app.AntVerticalButton.Text = 'Vertical';
            app.EnableButton = uibutton(app.TestinginputsPanel, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            app.TAG_TEST_ANT_STR = uieditfield(app.TestinginputsPanel, 'text');
            app.TAG_TEST_ANT_STR.ValueChangedFcn = createCallbackFcn(app, @TAG_TEST_ANT_STRValueChanged, true);
            app.TAG_TEST_ANT_STR.Enable = 'off';
            app.TAG_TEST_ANT_STR.Value = 'Unknown';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ProcessingPanel = uipanel(app.ResultsTab);
            app.ProcessingPanel.Title = 'Processing';
            app.RadioDataButton = uibutton(app.ProcessingPanel, 'push');
            app.RadioDataButton.ButtonPushedFcn = createCallbackFcn(app, @RadioDataButtonPushed, true);
            app.RadioDataButton.Text = 'Radio Data';
            app.VehicleDataButton = uibutton(app.ProcessingPanel, 'push');
            app.VehicleDataButton.ButtonPushedFcn = createCallbackFcn(app, @VehicleDataButtonPushed, true);
            app.VehicleDataButton.Text = 'Vehicle Data';
            app.ExtractPulsesButton = uibutton(app.ProcessingPanel, 'push');
            app.ExtractPulsesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractPulsesButtonPushed, true);
            app.ExtractPulsesButton.Text = 'Extract Pulses';
            app.BearingsButton = uibutton(app.ProcessingPanel, 'push');
            app.BearingsButton.ButtonPushedFcn = createCallbackFcn(app, @BearingsButtonPushed, true);
            app.BearingsButton.Text = 'Bearings';
            app.PositionButton = uibutton(app.ProcessingPanel, 'push');
            app.PositionButton.ButtonPushedFcn = createCallbackFcn(app, @PositionButtonPushed, true);
            app.PositionButton.Text = 'Position';
            app.Lampvehicledataready = uilamp(app.ProcessingPanel);
            app.Lamppreprocessingcomplete = uilamp(app.ProcessingPanel);
            app.Lamppulsefindcomplete = uilamp(app.ProcessingPanel);
            app.Lampbearingscomplete = uilamp(app.ProcessingPanel);
            app.Lamppositioncomplete = uilamp(app.ProcessingPanel);
            app.ProcessBearingCheckBox = uicheckbox(app.ProcessingPanel);
            app.ProcessBearingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessBearingCheckBoxValueChanged, true);
            app.ProcessBearingCheckBox.Text = '';
            app.ProcessBearingCheckBox.Value = true;
            app.ProcessPositionCheckBox = uicheckbox(app.ProcessingPanel);
            app.ProcessPositionCheckBox.Text = '';
            app.ProcessPositionCheckBox.Value = true;
            app.ALLButton = uibutton(app.ProcessingPanel, 'push');
            app.ALLButton.ButtonPushedFcn = createCallbackFcn(app, @ALLButtonPushed, true);
            app.ALLButton.Text = 'ALL';
            app.LocalizationControlPanel = uipanel(app.ResultsTab);
            app.LocalizationControlPanel.Title = 'Localization Control';
            app.SignalScaleButtonGroup = uibuttongroup(app.LocalizationControlPanel);
            app.SignalScaleButtonGroup.Title = 'Signal Scale';
            app.AmpButton = uitogglebutton(app.SignalScaleButtonGroup);
            app.AmpButton.Tooltip = {'Uses pulse amplitude when using PCA analysis used for bearing estimates.'};
            app.AmpButton.Text = 'Amp';
            app.PowButton = uitogglebutton(app.SignalScaleButtonGroup);
            app.PowButton.Tooltip = {'Uses pulse power (amp^2) when using PCA analysis used for bearing estimates.'};
            app.PowButton.Text = 'Pow';
            app.PowButton.Value = true;
            app.PCAScaleButtonGroup = uibuttongroup(app.LocalizationControlPanel);
            app.PCAScaleButtonGroup.Title = 'PCA Scale';
            app.LinearButton = uitogglebutton(app.PCAScaleButtonGroup);
            app.LinearButton.Tooltip = {'Linear scaling on the pulse signals in the PCA analysis used for bearing estimates.'};
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogButton = uitogglebutton(app.PCAScaleButtonGroup);
            app.LogButton.Tooltip = {'Log10 scaling on the pulse signals in the PCA analysis used for bearing estimates.'};
            app.LogButton.Text = 'Log';
            app.MethodPanel = uipanel(app.LocalizationControlPanel);
            app.MethodPanel.Title = 'Method';
            app.CMButton = uibutton(app.MethodPanel, 'state');
            app.CMButton.ValueChangedFcn = createCallbackFcn(app, @CMButtonValueChanged, true);
            app.CMButton.Tooltip = {'Center of mass method'};
            app.CMButton.Text = 'CM';
            app.MLEButton = uibutton(app.MethodPanel, 'state');
            app.MLEButton.ValueChangedFcn = createCallbackFcn(app, @MLEButtonValueChanged, true);
            app.MLEButton.Tooltip = {'Maximum likelihood estimate'};
            app.MLEButton.Text = 'MLE';
            app.MLEButton.Value = true;
            app.RMRButton = uibutton(app.MethodPanel, 'state');
            app.RMRButton.ValueChangedFcn = createCallbackFcn(app, @RMRButtonValueChanged, true);
            app.RMRButton.Tooltip = {'Repeated median regression'};
            app.RMRButton.Text = 'RMR';
            app.MESTButton = uibutton(app.MethodPanel, 'state');
            app.MESTButton.ValueChangedFcn = createCallbackFcn(app, @MESTButtonValueChanged, true);
            app.MESTButton.Tooltip = {'M-estimation'};
            app.MESTButton.Text = 'MEST';
            app.MedianButton = uibutton(app.MethodPanel, 'state');
            app.MedianButton.Tooltip = {'Also include an estimated position based on the median of the results select methods. '};
            app.MedianButton.Text = 'Median';
            app.AllButton = uibutton(app.MethodPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Tooltip = {'Execute all localization methods.'};
            app.AllButton.Text = 'All';
            app.UIWayptSelTable = uitable(app.LocalizationControlPanel);
            app.UIWayptSelTable.ColumnName = {'W.P.'; 'Select'};
            app.UIWayptSelTable.RowName = {};
            app.UIWayptSelTable.CellEditCallback = createCallbackFcn(app, @UIWayptSelTableCellEdit, true);
            app.ClearresultsButton = uibutton(app.LocalizationControlPanel, 'push');
            app.ClearresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearresultsButtonPushed, true);
            app.ClearresultsButton.Tooltip = {'Clear data and plots.'};
            app.ClearresultsButton.Text = {'Clear'; 'results'};
            app.TagcheckButton = uibutton(app.LocalizationControlPanel, 'push');
            app.TagcheckButton.ButtonPushedFcn = createCallbackFcn(app, @TagcheckButtonPushed, true);
            app.TagcheckButton.Enable = 'off';
            app.TagcheckButton.Tooltip = {'This will use the data to determine a tag''s center frequency (from the tuned frequency of the radio) and pulse statistics like pulse interval and duration.'};
            app.TagcheckButton.Text = {'Tag'; 'check'};
            app.PulseonlypositionButton = uibutton(app.LocalizationControlPanel, 'push');
            app.PulseonlypositionButton.ButtonPushedFcn = createCallbackFcn(app, @PulseonlypositionButtonPushed, true);
            app.PulseonlypositionButton.Tooltip = {'Calculate position using only the pulse results based on signal strength and no bearing estimates. Useful for "lawn mower" flight patterns.'};
            app.PulseonlypositionButton.Text = 'Pulse only position';
            app.TabGroup2 = uitabgroup(app.ResultsTab);
            app.MapTab = uitab(app.TabGroup2);
            app.MapTab.Title = 'Map';
            app.Panel_2 = uipanel(app.MapTab);
            app.ResetResultsMapButton = uibutton(app.Panel_2, 'push');
            app.ResetResultsMapButton.ButtonPushedFcn = createCallbackFcn(app, @ResetResultsMapButtonPushed, true);
            app.ResetResultsMapButton.Text = 'Reset';
            app.ClearButton = uibutton(app.Panel_2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PlotButton = uibutton(app.Panel_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PositionEstimateMedianofcurrentlyplottedLabel = uilabel(app.Panel_2);
            app.PositionEstimateMedianofcurrentlyplottedLabel.Text = 'Position Estimate (Median of currently plotted)';
            app.ZoomDropDownLabel = uilabel(app.Panel_2);
            app.ZoomDropDownLabel.Text = 'Zoom';
            app.ZoomDropDown = uidropdown(app.Panel_2);
            app.ZoomDropDown.Items = {'No Map', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.ZoomDropDown.Value = '16';
            app.MapradiuskmLabel = uilabel(app.Panel_2);
            app.MapradiuskmLabel.Text = 'Map radius (km)';
            app.MapRad = uieditfield(app.Panel_2, 'numeric');
            app.MapRad.Limits = [0 Inf];
            app.MapRad.ValueDisplayFormat = '%2.1g';
            app.MapRad.Value = 0.5;
            app.MapDropDownLabel = uilabel(app.Panel_2);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.Panel_2);
            app.MapDropDown.Items = {'USGS Topo', 'USGS ImageryTopo', 'USGS ImageryOnly', 'USGS ShadedReliefOnly'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'USGS Topo';
            app.LatEditFieldLabel = uilabel(app.Panel_2);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.Panel_2, 'numeric');
            app.LonEditFieldLabel = uilabel(app.Panel_2);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.Panel_2, 'numeric');
            app.XNEditFieldLabel = uilabel(app.Panel_2);
            app.XNEditFieldLabel.Text = 'X(N)';
            app.XNEditField = uieditfield(app.Panel_2, 'numeric');
            app.YEEditFieldLabel = uilabel(app.Panel_2);
            app.YEEditFieldLabel.Text = 'Y(E)';
            app.YEEditField = uieditfield(app.Panel_2, 'numeric');
            app.REditFieldLabel = uilabel(app.Panel_2);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.Panel_2, 'numeric');
            app.ThetafromTrueNEditFieldLabel = uilabel(app.Panel_2);
            app.ThetafromTrueNEditFieldLabel.Text = 'Theta (from True N)';
            app.ThetafromTrueNEditField = uieditfield(app.Panel_2, 'numeric');
            app.ViewDropDownLabel = uilabel(app.Panel_2);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.Panel_2);
            app.ViewDropDown.Items = {'Top', 'Isometric'};
            app.ViewDropDown.Value = 'Top';
            app.PopfigureButton = uibutton(app.Panel_2, 'push');
            app.PopfigureButton.ButtonPushedFcn = createCallbackFcn(app, @PopfigureButtonPushed, true);
            app.PopfigureButton.Text = 'Pop figure';
            app.TesttagplotButton = uibutton(app.Panel_2, 'state');
            app.TesttagplotButton.ValueChangedFcn = createCallbackFcn(app, @TesttagplotButtonValueChanged, true);
            app.TesttagplotButton.Text = {'Test tag'; 'plot'};
            app.UIAxes2 = uiaxes(app.MapTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.BearingsTab = uitab(app.TabGroup2);
            app.BearingsTab.Title = 'Bearings';
            app.AudioTab = uitab(app.TabGroup2);
            app.AudioTab.Title = 'Audio';
            app.StartAudioButton = uibutton(app.AudioTab, 'push');
            app.StartAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StartAudioButtonPushed, true);
            app.StartAudioButton.Text = 'Start';
            app.StopButton = uibutton(app.AudioTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayPauseButton = uibutton(app.AudioTab, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.WaypointTab = uitab(app.TabGroup2);
            app.WaypointTab.Title = 'Waypoint';
            app.UITable = uitable(app.WaypointTab);
            app.UITable.ColumnName = {'Waypt #'; 'Time @ (s)'; 'x (m-N)'; 'y (m-E)'; 'Alt. (m)'; 'Lat.'; 'Lon.'};
            app.UITable.RowName = {};
            app.ExportCSVButton_2 = uibutton(app.WaypointTab, 'push');
            app.ExportCSVButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButton_2Pushed, true);
            app.ExportCSVButton_2.Text = 'Export CSV';
            app.UIAxes = uiaxes(app.WaypointTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PulsesTab = uitab(app.TabGroup2);
            app.PulsesTab.Title = 'Pulses';
            app.UITable2 = uitable(app.PulsesTab);
            app.UITable2.ColumnName = {'#'; 'Time (s)'; 'Power (mW)'; 'Power (dB)'; 'Lat.'; 'Lon.'; 'R (m)'; 'Hdng (deg-N)'};
            app.UITable2.RowName = {};
            app.ExportCSVButton = uibutton(app.PulsesTab, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.SpectrumTab = uitab(app.TabGroup2);
            app.SpectrumTab.Title = 'Spectrum';
            app.PlayRawAudioButton = uibutton(app.SpectrumTab, 'push');
            app.PlayRawAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRawAudioButtonPushed, true);
            app.PlayRawAudioButton.Text = 'Play Raw Audio';
            app.PlayFilteredAudioButton = uibutton(app.SpectrumTab, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.UIAxes_PSD = uiaxes(app.SpectrumTab);
            xlabel(app.UIAxes_PSD, 'Frequency (Hz)')
            ylabel(app.UIAxes_PSD, 'Double sided amp.')
            app.UIAxes_WAVEFRM = uiaxes(app.SpectrumTab);
            xlabel(app.UIAxes_WAVEFRM, 'Time (s)')
            ylabel(app.UIAxes_WAVEFRM, 'Amplitude')
            app.MapcachingTab = uitab(app.TabGroup);
            app.MapcachingTab.Title = 'Map caching';
            app.DownloadButton = uibutton(app.MapcachingTab, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Text = 'Download';
            app.OpencachingmapButton = uibutton(app.MapcachingTab, 'push');
            app.OpencachingmapButton.ButtonPushedFcn = createCallbackFcn(app, @OpencachingmapButtonPushed, true);
            app.OpencachingmapButton.Text = 'Open caching map';
            app.SelectmappreviewzoomlevelDropDownLabel = uilabel(app.MapcachingTab);
            app.SelectmappreviewzoomlevelDropDownLabel.Text = '2. Select map preview zoom level';
            app.SelectmappreviewzoomlevelDropDown = uidropdown(app.MapcachingTab);
            app.SelectmappreviewzoomlevelDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.SelectmappreviewzoomlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmappreviewzoomlevelDropDownValueChanged, true);
            app.SelectmappreviewzoomlevelDropDown.Value = '16';
            app.ChangecachingdirectoryButton = uibutton(app.MapcachingTab, 'push');
            app.ChangecachingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecachingdirectoryButtonPushed, true);
            app.ChangecachingdirectoryButton.Text = 'Change caching directory ';
            app.MapcachedirEditField = uieditfield(app.MapcachingTab, 'text');
            app.MapcachedirEditField.ValueChangedFcn = createCallbackFcn(app, @MapcachedirEditFieldValueChanged, true);
            app.MapcachedirEditField.Editable = 'off';
            app.Toposelectcheckbox = uicheckbox(app.MapcachingTab);
            app.Toposelectcheckbox.Text = 'USGS Topo';
            app.ImageryToposelectcheckbox = uicheckbox(app.MapcachingTab);
            app.ImageryToposelectcheckbox.Text = 'USGS ImageryTopo';
            app.Imageryonlyselectcheckbox = uicheckbox(app.MapcachingTab);
            app.Imageryonlyselectcheckbox.Text = 'USGS ImageryOnly';
            app.Shadedselectcheckbox = uicheckbox(app.MapcachingTab);
            app.Shadedselectcheckbox.Text = 'USGS ShadedReliefOnly';
            app.SelectmappreviewlevelDropDownLabel_2 = uilabel(app.MapcachingTab);
            app.SelectmappreviewlevelDropDownLabel_2.Text = '1. Select zoom levels to download';
            app.zoom_dl_sel_6 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_6.Text = '6';
            app.zoom_dl_sel_8 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_8.Text = '8';
            app.zoom_dl_sel_7 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_7.Text = '7';
            app.zoom_dl_sel_5 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_5.Text = '5';
            app.zoom_dl_sel_4 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_4.Text = '4';
            app.zoom_dl_sel_3 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_3.Text = '3';
            app.zoom_dl_sel_2 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_2.Text = '2';
            app.zoom_dl_sel_1 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_1.Text = '1';
            app.zoom_dl_sel_9 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_9.Text = '9';
            app.zoom_dl_sel_10 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_10.Text = '10';
            app.zoom_dl_sel_11 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_11.Text = '11';
            app.zoom_dl_sel_12 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_12.Text = '12';
            app.zoom_dl_sel_13 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_13.Text = '13';
            app.zoom_dl_sel_14 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_14.Text = '14';
            app.zoom_dl_sel_15 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_15.Text = '15';
            app.zoom_dl_sel_16 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_16.Text = '16';
            app.zoom_dl_sel_16.Value = true;
            app.SelectmappreviewlevelDropDownLabel_3 = uilabel(app.MapcachingTab);
            app.SelectmappreviewlevelDropDownLabel_3.Text = '3. Select maps to download';
            app.SelectmappreviewlevelDropDownLabel_4 = uilabel(app.MapcachingTab);
            app.SelectmappreviewlevelDropDownLabel_4.Text = '4. If desired, select alternate cache directory';
            app.SelectmappreviewlevelDropDownLabel_5 = uilabel(app.MapcachingTab);
            app.SelectmappreviewlevelDropDownLabel_5.Text = '5. Open caching map and zoom/pan to area of interest. (Requires network connection)';
            app.SelectmappreviewlevelDropDownLabel_6 = uilabel(app.MapcachingTab);
            app.SelectmappreviewlevelDropDownLabel_6.Text = '6. Download maps.';
            app.Hydroselectcheckbox = uicheckbox(app.MapcachingTab);
            app.Hydroselectcheckbox.Text = 'USGS Hydrocache';
            app.TNMBlankselectcheckbox = uicheckbox(app.MapcachingTab);
            app.TNMBlankselectcheckbox.Text = 'USGS TNMBlank';
            app.zoom_dl_sel_0 = uicheckbox(app.MapcachingTab);
            app.zoom_dl_sel_0.Text = '0';
            app.TabGroup3 = uitabgroup(app.MapcachingTab);
            app.MapCacheTab = uitab(app.TabGroup3);
            app.MapCacheTab.Title = 'Map Cache';
            app.MapPreviewTab = uitab(app.TabGroup3);
            app.MapPreviewTab.Title = 'Map Preview';
            app.UIAxes4 = uiaxes(app.MapPreviewTab);
            title(app.UIAxes4, 'Map Preview')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DebuggingTab = uitab(app.TabGroup);
            app.DebuggingTab.Title = 'Debugging';
            app.BreakpointButton = uibutton(app.DebuggingTab, 'push');
            app.BreakpointButton.ButtonPushedFcn = createCallbackFcn(app, @BreakpointButtonPushed, true);
            app.BreakpointButton.Text = 'Breakpoint Button';
            app.RawCommsLabel = uilabel(app.DebuggingTab);
            app.RawCommsLabel.Text = 'Raw Comms';
            app.TextArea_RAWCOMM = uitextarea(app.DebuggingTab);
            app.TextArea_RAWCOMM.Editable = 'off';
            app.ctfrootEditFieldLabel = uilabel(app.DebuggingTab);
            app.ctfrootEditFieldLabel.Text = 'ctfroot';
            app.ctfrootEditField = uieditfield(app.DebuggingTab, 'text');
            app.matlabrootEditFieldLabel = uilabel(app.DebuggingTab);
            app.matlabrootEditFieldLabel.Text = 'matlab root';
            app.matlabrootEditField = uieditfield(app.DebuggingTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uavrt_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end